classdef brainSurfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        brainSurferUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadsurfaceMenu                 matlab.ui.container.Menu
        NewfigureMenu                   matlab.ui.container.Menu
        FsaveragetemplateMenu_2         matlab.ui.container.Menu
        LHRHMenu                        matlab.ui.container.Menu
        LHMenu                          matlab.ui.container.Menu
        RHMenu                          matlab.ui.container.Menu
        Carettemplates900Menu_2         matlab.ui.container.Menu
        FlatMenu_3                      matlab.ui.container.Menu
        LHRHMenu_2                      matlab.ui.container.Menu
        LHMenu_12                       matlab.ui.container.Menu
        RHMenu_12                       matlab.ui.container.Menu
        VeryinflatedMenu_3              matlab.ui.container.Menu
        LHRHMenu_3                      matlab.ui.container.Menu
        LHMenu_13                       matlab.ui.container.Menu
        RHMenu_13                       matlab.ui.container.Menu
        InflatedMenu_3                  matlab.ui.container.Menu
        LHRHMenu_4                      matlab.ui.container.Menu
        LHMenu_14                       matlab.ui.container.Menu
        RHMenu_14                       matlab.ui.container.Menu
        MidthicknessMenu_3              matlab.ui.container.Menu
        LHRHMenu_15                     matlab.ui.container.Menu
        LHMenu_15                       matlab.ui.container.Menu
        RHMenu_15                       matlab.ui.container.Menu
        LoadfilesMenu                   matlab.ui.container.Menu
        ExistingfigureMenu              matlab.ui.container.Menu
        FsaveragetemplateMenu_3         matlab.ui.container.Menu
        LHMenu_7                        matlab.ui.container.Menu
        RHMenu_7                        matlab.ui.container.Menu
        Carettemplates900Menu_3         matlab.ui.container.Menu
        FlatMenu_2                      matlab.ui.container.Menu
        LHMenu_8                        matlab.ui.container.Menu
        RHMenu_8                        matlab.ui.container.Menu
        VeryinflatedMenu_2              matlab.ui.container.Menu
        LHMenu_9                        matlab.ui.container.Menu
        RHMenu_9                        matlab.ui.container.Menu
        InflatedMenu_2                  matlab.ui.container.Menu
        LHMenu_10                       matlab.ui.container.Menu
        RHMenu_10                       matlab.ui.container.Menu
        MidthicknessMenu_2              matlab.ui.container.Menu
        LHMenu_11                       matlab.ui.container.Menu
        RHMenu_11                       matlab.ui.container.Menu
        AddfilesMenu_2                  matlab.ui.container.Menu
        LoadsulcigyriMenu               matlab.ui.container.Menu
        LoadoverlaysMenu                matlab.ui.container.Menu
        ImportoverlayfromvolumespaceMenu  matlab.ui.container.Menu
        ImportoverlayonfsaverageMenu_2  matlab.ui.container.Menu
        Importoverlayoncarets900Menu    matlab.ui.container.Menu
        ImportanatlasMenu               matlab.ui.container.Menu
        SavethresholdedoverlaysMenu     matlab.ui.container.Menu
        CombinedsavedCIFTIoverlaysMenu  matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        HemisphereMenu_2                matlab.ui.container.Menu
        LeftMenu_2                      matlab.ui.container.Menu
        RightMenu_2                     matlab.ui.container.Menu
        SpecificoverlayMenu_2           matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        LeftlateralMenu                 matlab.ui.container.Menu
        RightlateralMenu                matlab.ui.container.Menu
        InferiorMenu_3                  matlab.ui.container.Menu
        SuperiorMenu_3                  matlab.ui.container.Menu
        SpecificlocationMenu            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Panel_8                         matlab.ui.container.Panel
        AppearanceLabel_2               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        UnderlayTab                     matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        ValuesPanel                     matlab.ui.container.Panel
        valueSelectionSurf              matlab.ui.control.DropDown
        posSwitchSurf                   matlab.ui.control.Switch
        PositiveLabel                   matlab.ui.control.Label
        negSwitchSurf                   matlab.ui.control.Switch
        NegativeLabel                   matlab.ui.control.Label
        ZerosLabel                      matlab.ui.control.Label
        remZeroSwitchSurf               matlab.ui.control.Switch
        reloadButton2                   matlab.ui.control.Button
        BinarizevaluessulciandgyriPanel  matlab.ui.container.Panel
        binSwitchSurf                   matlab.ui.control.Switch
        sgThresh                        matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        DefinecolorforsulciandgyriindividuallyLabel  matlab.ui.control.Label
        sulcButton                      matlab.ui.control.Button
        gyrButton                       matlab.ui.control.Button
        SurfacePanel                    matlab.ui.container.Panel
        opacityBoxSurf                  matlab.ui.control.Spinner
        opacityLabelSurf_2              matlab.ui.control.Label
        leftHemiToggle                  matlab.ui.control.StateButton
        rightHemiToggle                 matlab.ui.control.StateButton
        opacityLabelSurf_3              matlab.ui.control.Label
        RangeofvaluestoexcludePanel     matlab.ui.container.Panel
        lowerThreshBoxSurf              matlab.ui.control.Spinner
        upperThreshBoxSurf              matlab.ui.control.Spinner
        LowerlimitLabel                 matlab.ui.control.Label
        UpperlimitLabel                 matlab.ui.control.Label
        OverlayTab                      matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        ValuesPanel_2                   matlab.ui.container.Panel
        valueSelection                  matlab.ui.control.DropDown
        posSwitch                       matlab.ui.control.Switch
        PositiveLabel_2                 matlab.ui.control.Label
        negSwitch                       matlab.ui.control.Switch
        NegativeLabel_2                 matlab.ui.control.Label
        ZerosLabel_2                    matlab.ui.control.Label
        remZeroSwitch                   matlab.ui.control.Switch
        reloadOver                      matlab.ui.control.Button
        pvaluesPanel_2                  matlab.ui.container.Panel
        addpButton                      matlab.ui.control.StateButton
        fdrButton                       matlab.ui.control.StateButton
        bonfButton                      matlab.ui.control.StateButton
        pthresholdLabel_2               matlab.ui.control.Label
        pThreshBox                      matlab.ui.control.Spinner
        RangeofvaluestoexcludePanel_2   matlab.ui.container.Panel
        LowerlimitLabel_2               matlab.ui.control.Label
        UpperlimitLabel_2               matlab.ui.control.Label
        lowerThreshBox                  matlab.ui.control.Spinner
        upperThreshBox                  matlab.ui.control.Spinner
        ClusterthresholdPanel           matlab.ui.container.Panel
        clusterThreshBox                matlab.ui.control.Spinner
        SizethreshLabel                 matlab.ui.control.Label
        clusterSlider                   matlab.ui.control.Slider
        ClustcorrButton                 matlab.ui.control.Button
        LimitsTab                       matlab.ui.container.Tab
        TabGroup_4                      matlab.ui.container.TabGroup
        UnderlayTab_2                   matlab.ui.container.Tab
        histSurf                        matlab.ui.control.UIAxes
        IntensityhistogramLabel         matlab.ui.control.Label
        UpperlimitofcolormapLabel       matlab.ui.control.Label
        LowerlimitofcolormapLabel       matlab.ui.control.Label
        limMaxSurf                      matlab.ui.control.Spinner
        limMinSurf                      matlab.ui.control.Spinner
        OverlayTab_3                    matlab.ui.container.Tab
        IntensityhistogramLabel_2       matlab.ui.control.Label
        UpperlimitofcolormapLabel_2     matlab.ui.control.Label
        LowerlimitofcolormapLabel_2     matlab.ui.control.Label
        limMinOver                      matlab.ui.control.Spinner
        limMaxOver                      matlab.ui.control.Spinner
        histOver                        matlab.ui.control.UIAxes
        ColormapTab                     matlab.ui.container.Tab
        TabGroup_3                      matlab.ui.container.TabGroup
        UnderlayTab_3                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        colormapSelectorSurf            matlab.ui.control.DropDown
        binsLabelSurf                   matlab.ui.control.Label
        opacityLabelSurf                matlab.ui.control.Label
        selectSpacingSurf               matlab.ui.control.DropDown
        binBoxSurf                      matlab.ui.control.Spinner
        InvertcolorsLabel               matlab.ui.control.Label
        invertSwitchSurf                matlab.ui.control.Switch
        opacityBoxSG                    matlab.ui.control.Spinner
        OverlayTab_2                    matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        colormapSelector                matlab.ui.control.DropDown
        BinsLabel_2                     matlab.ui.control.Label
        OpacityLabel_2                  matlab.ui.control.Label
        selectSpacing                   matlab.ui.control.DropDown
        binBox                          matlab.ui.control.Spinner
        opacityBox                      matlab.ui.control.Spinner
        InvertcolorsLabel_2             matlab.ui.control.Label
        invertSwitch                    matlab.ui.control.Switch
        OutlineDropDownLabel            matlab.ui.control.Label
        outlineDropDown                 matlab.ui.control.DropDown
        GrowShrinkSlider_2Label         matlab.ui.control.Label
        growShrinkSlider                matlab.ui.control.Slider
        growShrinkBox                   matlab.ui.control.Spinner
        BinarizeDropDownLabel           matlab.ui.control.Label
        binSwitch                       matlab.ui.control.DropDown
        SmoothingTab                    matlab.ui.container.Tab
        TabGroup_5                      matlab.ui.container.TabGroup
        SmoothTab                       matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        AreaLabel_2                     matlab.ui.control.Label
        StepsLabel_2                    matlab.ui.control.Label
        assignSwitchUnder               matlab.ui.control.Switch
        smoothAreaUnder                 matlab.ui.control.Spinner
        smoothStepsUnder                matlab.ui.control.Spinner
        smoothButtonSurf                matlab.ui.control.StateButton
        VerticesbeingsmoothedLabel_3    matlab.ui.control.Label
        SmoothsulcigyriLabel            matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        AreaLabel_3                     matlab.ui.control.Label
        StepsLabel_3                    matlab.ui.control.Label
        smoothArea                      matlab.ui.control.Spinner
        smoothSteps                     matlab.ui.control.Spinner
        smoothButton                    matlab.ui.control.StateButton
        assignSwitch                    matlab.ui.control.Switch
        VerticesbeingsmoothedLabel_2    matlab.ui.control.Label
        SmoothoverlayLabel_2            matlab.ui.control.Label
        ClusterTab                      matlab.ui.container.Tab
        addpButton_4                    matlab.ui.control.Button
        addpButton_5                    matlab.ui.control.Button
        addpButton_6                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        reloadButton                    matlab.ui.control.Button
        copyButton                      matlab.ui.control.Button
        deleteButton                    matlab.ui.control.Button
        OverlayselectionLabel           matlab.ui.control.Label
        upButton                        matlab.ui.control.Button
        downButton                      matlab.ui.control.Button
        lightsButton                    matlab.ui.control.Button
        screenshotButton                matlab.ui.control.Button
        dupeButton                      matlab.ui.control.Button
        maskButton                      matlab.ui.control.Button
        LHButton                        matlab.ui.control.Button
        RHButton                        matlab.ui.control.Button
        overlayListBox                  matlab.ui.control.ListBox
        Panel_11                        matlab.ui.container.Panel
        colormapSelectAtlas             matlab.ui.control.DropDown
        loadAtlas                       matlab.ui.control.Button
        LoadedatlasLabel                matlab.ui.control.Label
        loadLabels                      matlab.ui.control.Button
        AreaonlasttooltipLabel          matlab.ui.control.Label
        selectSpacingAtlas              matlab.ui.control.DropDown
        BordersLabel                    matlab.ui.control.Label
        borderToggleAtlas               matlab.ui.control.Switch
        upperLimAtlas                   matlab.ui.control.Spinner
        lowerLimAtlas                   matlab.ui.control.Spinner
        binAtlas                        matlab.ui.control.Spinner
        BinsLabel_3                     matlab.ui.control.Label
        UpperlimitLabel_3               matlab.ui.control.Label
        LowerlimitLabel_3               matlab.ui.control.Label
        opacityAtlas                    matlab.ui.control.Spinner
        OpacityLabel_3                  matlab.ui.control.Label
        ShrinkGrowLabel                 matlab.ui.control.Label
        shrinkGrowAtlas                 matlab.ui.control.Spinner
        toggleColorbarAtlas             matlab.ui.control.StateButton
        InvertcolorsLabel_4             matlab.ui.control.Label
        invertToggleAtlas               matlab.ui.control.Switch
        reloadAtlas                     matlab.ui.control.Button
        AtlasLabel                      matlab.ui.control.Label
        SettingsMenu                    matlab.ui.container.Menu
        ColorsMenu                      matlab.ui.container.Menu
        ColorbarMenu                    matlab.ui.container.Menu
        VisibilityMenu                  matlab.ui.container.Menu
        ShowMenu_4                      matlab.ui.container.Menu
        HideMenu_4                      matlab.ui.container.Menu
        OrientationMenu                 matlab.ui.container.Menu
        VerticalMenu                    matlab.ui.container.Menu
        HorizontalMenu                  matlab.ui.container.Menu
        TitlesonlyformultipleselectionsMenu  matlab.ui.container.Menu
        ShowMenu_5                      matlab.ui.container.Menu
        HideMenu_5                      matlab.ui.container.Menu
        ChangespacingbetweencolorbarsMenu  matlab.ui.container.Menu
        CreatecolormapMenu              matlab.ui.container.Menu
        UsecolorsfromannotationCIFTIheaderforatlasMenu  matlab.ui.container.Menu
        ChangedefaultsurfacecolorMenu   matlab.ui.container.Menu
        ChangebackgroundofbrainfigureMenu  matlab.ui.container.Menu
        GUIMenu                         matlab.ui.container.Menu
        DarkModeMenu_3                  matlab.ui.container.Menu
        LightModeMenu_3                 matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        ContextMenu2                    matlab.ui.container.ContextMenu
        EditlightlocationsMenu          matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        ContextMenu4                    matlab.ui.container.ContextMenu
        TakemultiplestandardizedscreenshotsofselectedoverlaysMenu  matlab.ui.container.Menu
        TakemultiplestandardizedscreenshotsofsomeoverlaysMenu  matlab.ui.container.Menu
        TakemultiplestandardizedscreenshotsofalloverlaysMenu  matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        DeleteselectoverlayMenu         matlab.ui.container.Menu
        DeletealloverlaysMenu           matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        ContextMenu9                    matlab.ui.container.ContextMenu
        ViewleftlateralMenu             matlab.ui.container.Menu
        ViewrightlateralMenu            matlab.ui.container.Menu
        ViewinferiorMenu                matlab.ui.container.Menu
        ViewSuperiorMenu                matlab.ui.container.Menu
        ContextMenu10                   matlab.ui.container.ContextMenu
        ContextMenu11                   matlab.ui.container.ContextMenu
        MakedefaultsulcalandgyralcolorsMenu_2  matlab.ui.container.Menu
        InvertsulcalandgyralcolorsMenu_2  matlab.ui.container.Menu
        ContextMenu12                   matlab.ui.container.ContextMenu
        ContextMenu13                   matlab.ui.container.ContextMenu
        ChangeFontSizeMenu              matlab.ui.container.Menu
        TrimoverlaynamelengthsMenu      matlab.ui.container.Menu
        ContextMenu15                   matlab.ui.container.ContextMenu
        ReloadsomeoverlaysMenu          matlab.ui.container.Menu
        ContextMenu16                   matlab.ui.container.ContextMenu
        RandomizecolorsMenu             matlab.ui.container.Menu
        UsedefaultcolorsfromatlasifavailableMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        
    end
    
    properties (Access = public)
        pths = struct('sep','','brnPth','','scrptPth','','clrmpPth','','gPth','','atlasPth',''); % this variable will hold all of our paths
        brainFig = ''; % this variable will hold the brain figure
        sg = struct('left','','right',''); % this variable stores the loaded in sulcal and gyral information
        sgB = struct('left','','right',''); % this variable will store a backup of sulcal/gyral info so that it can be reloaded if necessary
        
        %lastAct = '';
        underlay = struct('left','','right','','leftCurv','','rightCurv','');
        overlay = struct('names','','paths','','bm','','bmB','','settings','');
        ticksSG = struct('left', struct('ticks', '', 'labels', ''), 'right',struct('ticks', '', 'labels', '')); % this variable will hold ticks for the sulcal/gyral information
        ticks = struct('ticks','', 'labels','');
        colormapSG = struct('left', struct('map', '', 'dataMap', ''), 'right',struct('map', '', 'dataMap', ''));
        colormap = struct('map','', 'dataMap','');
        colormapSG_tmp = [];
        sgTh = [];
        
        ticksSG_tmp = [];
        %ln = {'left', 'lh', '.l.'};
        %rn = {'right', 'rh','.r.'};
        ln = {'left', 'lh', '.l.','_l.','_l_'};
        rn = {'right', 'rh','.r.','_r.','_r_'};
        
        dlights = [];
        pMap = [];
        sg_tmp = [];
        
        curs = [];
        dcm = [];
        info = [];
        surfC = [0.5 0.5 0.5];
        
        sulcColorDisp = [];
        gyrColorDisp = [];
        
        userColor = [];
        
        limMaxLineSurf = [];
        limMinLineSurf = [];
        lowerThreshLineSurf = [];
        upperThreshLineSurf = [];
        
        limMaxLineOver = [];
        limMinLineOver = [];
        lowerThreshLineOver = [];
        upperThreshLineOver = [];
        
        axSurf;    % for the axes
        axOver;
        lSurf;     % for the line
        lOver;
        
        currOver = [];
        cBar = [];
        cBarDir = 'vert';
        cBarAx = [];
        cBarUnder = [];
        cBarUnderShow = 'no';
        cBarShow = 'yes';
        cBarUnderDir = 'vert';
        cBarUnderAx = [];
        clusters = [];
        cBarText = [];
        cBarSpacing = 0.07;
        ci = 'true';
        nmL = 115;
        atlas = struct('bm',struct('left','','right',''),'overlay',struct('left','','right',''),'cBar','','cBarText','','cBarAx','','regions',struct('left','','right',''),'clusters',struct('left','','right',''),'borders',struct('left','','right',''),'name',struct('left','','right',''),'path',struct('left','','right',''),'defaultColors',struct('left','','right',''),'defaultCbar',struct('left','','right',''));
        
        overlaySelectionApp
        
        defaultSetOver = struct('colormapSelector','jet','selectSpacing','even','valueSelection','Raw','posSwitch','On','negSwitch','On','remZeroSwitch','Off','smoothButton',0,'smoothArea',0,'smoothSteps',0,'borderSwitch','Thresholded','assignSwitch','thresholded','lowerThreshBox',0,'upperThreshBox',0,'binSwitch','Off','binBox',1000,'opacityBox',1,'invertSwitch','Off','clusterThreshBox',0,'clusterSlider',0,'outlineDropDown','None','growShrinkBox',0,'colorSpecial','None','pMap',[],'pMapB',[],'pThresh',1,'pFDR',0,'pBonf',0,'clusters',[],'threshVals',[]);
    end
    
    methods (Access = private)
        
        function txt = dcDisplay(app,src,evnt)
            sg = NaN;
            ib = [];
            reg = NaN;
            v = [];
            
            lf = 0;
            if isfield(app.underlay,'left')
                [~,~,ib] = intersect(evnt.Position,app.underlay.left.Vertices,'rows');
                %[~,~,ib] = intersect(evnt.Position,app.underlay.left.Vertices,'rows');
                %[D,id] = pdist2(app.underlay.left.Vertices,evnt.Position,'euclidean','Smallest',1)
                if ~isempty(ib)
                    v = ib;
                    if ~isempty(app.atlas.regions.left)
                        reg = [app.atlas.regions.left{v}];
                    else
                        reg = ['No atlas labels loaded'];
                    end
                    if ~isempty(app.atlas.bm.left)
                        reg = [reg ', value:' num2str(app.atlas.bm.left(v))];
                    end
                    lf = 1;
                    if strcmpi(reg,'NaN')
                        reg = num2str(app.atlas.bm.left(v));
                    end
                    if ~isempty(app.sg.left)
                        sg = num2str(app.sg.left(v));
                    end
                end
            end
            
            rg = 0;
            if isfield(app.underlay,'right')
                [~,~,ib] = intersect(evnt.Position,app.underlay.right.Vertices,'rows');
                if ~isempty(ib)
                    v = ib;
                    if ~isempty(app.atlas.regions.right)
                        reg = [app.atlas.regions.right{v}];
                    else
                        reg = ['No atlas labels loaded'];
                    end
                    if ~isempty(app.atlas.bm.right)
                        reg = [reg ', value:' num2str(app.atlas.bm.right(v))];
                    end
                    rg = 1;
                    if strcmpi(reg,'NaN')
                        reg = num2str(app.atlas.bm.right(v));
                    end
                    if ~isempty(app.sg.right)
                        sg = num2str(app.sg.right(v));
                    end
                end
            end
            
            if ~isnan(reg)
                id = strfind(reg,'_');
                reg(id) = ' ';
            end
            
            si = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                si = [si; sit];
                s = [s; st];
            end
            id = find(si == 1);
            si(id) = [];
            s(id) = [];
            
            if ~isempty(si) & ~isempty(v)
                for fi = 1:length(si)
                    
                    if fi == 1
                        ov = num2str(app.overlay.bm{si(fi)}(v));
                    else
                        ov = [ov ', ' num2str(app.bm{si(fi)}(v))];
                    end
                end
            else
                ov = NaN;
            end
            
            if ~isempty(v)
                txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')' newline 'vertex: ' num2str(v)];
            else
                txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')'];
            end
            
            if (rg+lf) == 1
                txt = [txt newline 'region: ' reg newline 'sulci/gyri: ' sg newline 'overlays: ' ov];
            else
                txt = [txt newline 'Coordinate in current datatip matches both hemispheres'];
            end
            
            if isfield(app.underlay,'left') & isfield(app.underlay,'right')
                if ~isempty(app.underlay.left) & ~isempty(app.underlay.right)
                    if size(app.underlay.left.Vertices,1) ~= size(app.underlay.right.Vertices,1)
                        txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')' newline 'Atlas functionality does not support multiple different spaces'];
                        reg = 'Atlas functionality does not support multiple different spaces';
                    end
                end
            end
            
            id = strfind(reg,',');
            reg = reg(1:id-1);
            app.AreaonlasttooltipLabel.Text = ['Area on last tooltip: ' reg];
        end
        
        % Function that responds to the movement of the sliders
        function lMoving(app, source)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
                
                delete(app.lowerThreshLineSurf)
                delete(app.upperThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
        
        function lMoving_2(app, source)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.lowerThreshBox = app.lowerThreshBox.Value;
                app.overlay.settings{si}.upperThreshBox = app.upperThreshBox.Value;
                
                [app] = overPatch(app,'false','false','false');
                
                delete(app.lowerThreshLineOver)
                delete(app.upperThreshLineOver)
                hold(app.histOver,'on');
                l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),5);
                app.lowerThreshLineOver = plot(ones(size(l))*app.lowerThreshBox.Value,l,'k','Parent',app.histOver,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),5);
                app.upperThreshLineOver = plot(ones(size(l))*app.upperThreshBox.Value,l,'k','Parent',app.histOver,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histOver,'off');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in an overlay','Action unavailable');
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
        function lMoving2(app, source)
            if isgraphics(app.brainFig,'figure')
                app.lowerThreshBoxSurf.Value = min(source.Position(:,1));
                app.upperThreshBoxSurf.Value = max(source.Position(:,1));
            else
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
        function lMoving2_2(app, source)
            if isgraphics(app.brainFig,'figure')
                app.lowerThreshBox.Value = min(source.Position(:,1));
                app.upperThreshBox.Value = max(source.Position(:,1));
            else
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
    end
    
    methods (Access = public)
        
        function [app] = sgPatch(app,smth)
            cV = find(ismember(app.colormapSelectorSurf.Items,app.colormapSelectorSurf.Value));
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                
                % set up any variables that can't be passed in as they
                % exist in the GUI
                if app.userColor{cV} == 1
                    cm = app.colormapSelectorSurf.Value;
                else
                    cm = app.userColor{cV};
                end
                
                if strcmp(app.binSwitchSurf.Value,'On')
                    b = app.sgThresh.Value;
                else
                    b = [];
                end
                
                switch smth
                    case 'true'
                        if app.smoothButtonSurf.Value == 1
                            a = app.smoothAreaUnder.Value;
                            s = app.smoothStepsUnder.Value;
                            
                            sT = app.assignSwitchUnder.Value;
                            if (isfield(app.sg,'right') & ~isempty(app.sg.right)) & (isfield(app.sg,'left') & ~isempty(app.sg.left))
                                tog = [app.sg.left; app.sg.right];
                            elseif isfield(app.sg,'left') & ~isempty(app.sg.left)
                                tog = [app.sg.left]';
                            elseif isfield(app.sg,'right') & ~isempty(app.sg.right)
                                tog = [app.sg.right]';
                            end
                            
                        else
                            a = 0;
                            s = 0;
                            sT = app.assignSwitchUnder.Value;
                            if ~isempty(app.sgB.left)
                                app.sg.left = app.sgB.left;
                            end
                            if ~isempty(app.sgB.right)
                                app.sg.right = app.sgB.right;
                            end
                        end
                    case 'false'
                        a = 0;
                        s = 0;
                        sT = app.assignSwitchUnder.Value;
                end
                
                if ~isempty(app.sgB.left) & ~isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg, app.sgTh] = patchUnderlaySG(app.underlay, 'lh', app.sg.left,'rh', app.sg.right,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    
                    view(tmp,[-90 0])
                    
                elseif ~isempty(app.sgB.left) & isempty(app.sgB.right)
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp, sg_tmp, sgTh_tmp] = patchUnderlaySG(app.underlay, 'lh', app.sg.left,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    app.colormapSG.left = app.colormapSG_tmp.left;
                    app.ticksSG.left = app.ticksSG_tmp.left;
                    app.sg.left = sg_tmp.left;
                    app.sgTh.left = sgTh_tmp.left;
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,[-90 0])
                    
                elseif ~isempty(app.sg.right) & isempty(app.sgB.left)
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp, sg_tmp, sgTh_tmp] = patchUnderlaySG(app.underlay,'rh', app.sg.right,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    app.colormapSG.right = app.colormapSG_tmp.right;
                    app.ticksSG.right = app.ticksSG_tmp.right;
                    app.sg.right = sg_tmp.right;
                    app.sgTh.right = sgTh_tmp.right;
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,[90 0])
                end
                
                % if you performed smoothing just now, overwrite data and
                % update histogram; if you didn't, still update histogram
                switch smth
                    case 'true'
                        if app.smoothButtonSurf.Value == 1
                            if ~isempty(app.sgB.left)
                                app.sg.left =  app.sgTh.left;
                            end
                            if ~isempty(app.sgB.right)
                                app.sg.right =  app.sgTh.right;
                            end
                        end
                end
                
                tmp = fieldnames(app.underlay);
                u = unique(app.underlay.(tmp{1}).FaceVertexCData,'rows');
                m = mean(u,2);
                [~,mIdx] = min(m);
                [~,mmIdx] = max(m);
                app.sulcButton.BackgroundColor = u(mIdx,:);
                app.gyrButton.BackgroundColor = u(mmIdx,:);
            end
        end
        
        function [app] = overPatch(app,smth,vls,clstr)
            if isgraphics(app.brainFig,'figure')
                
                % remove previous selection
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                            delete(app.cBar{i})
                            delete(app.cBarAx{i})
                            delete(app.cBarText{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.cBar = [];
                    app.cBarAx = [];
                end
                
                % get selections
                si = [];
                s = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    si = [si; sit];
                    s = [s; st];
                end
                
                if si ~= 1
                    % now loop over all items so long as you haven't only
                    % selected 'no overlay'
                    for fi = 1:length(si)
                        
                        % get colormap for the selection
                        cV = find(ismember(app.colormapSelectorSurf.Items,app.overlay.settings{si(fi)}.colormapSelector));
                        % set up any variables that can't be passed in as they
                        % exist in the GUI
                        if app.userColor{cV} == 1
                            cm = app.overlay.settings{si(fi)}.colormapSelector;
                        else
                            cm = app.userColor{cV};
                        end
                        
                        % get smoothing parameters
                        switch smth
                            case 'true'
                                if app.overlay.settings{si(fi)}.smoothButton == 1
                                    a = app.overlay.settings{si(fi)}.smoothArea;
                                    s = app.overlay.settings{si(fi)}.smoothSteps;
                                    sT = app.overlay.settings{si(fi)}.assignSwitch;
                                else % resets overlay completely if you are unclicking the smooth button
                                    a = 0;
                                    s = 0;
                                    sT = app.overlay.settings{si(fi)}.assignSwitch;
                                    app.overlay.bm{si(fi)} = app.overlay.bmB{si(fi)};
                                end
                            case 'false'
                                a = 0;
                                s = 0;
                                sT = app.overlay.settings{si(fi)}.assignSwitch;
                        end
                        
                        if strcmpi(vls,'true')
                            app.overlay.settings{si}.clusterThreshBox = 0;
                            app.clusterSlider.Value = 0;
                            app.clusterThreshBox.Value = 0;
                            
                            app.overlay.settings{si}.posSwitch = 'On';
                            app.posSwitch.Value = 'On';
                            
                            app.overlay.settings{si}.negSwitch = 'On';
                            app.negSwitch.Value = 'On';
                            
                            app.overlay.settings{si}.growShrinkBox = 0;
                            app.growShrinkSlider.Value = 0;
                            app.growShrinkBox.Value = 0;
                            
                            app.overlay.settings{si}.outlineDropDown = 'None';
                            app.outlineDropDown.Value = 'None';
                            
                            app.overlay.settings{si}.smoothButton = 0;
                            app.smoothButton.Value = 0;
                            
                            app.overlay.settings{si}.smoothArea = 0;
                            app.smoothArea.Value = 0;
                            
                            app.overlay.settings{si}.smoothSteps = 0;
                            app.smoothSteps.Value = 0;
                            
                            app.overlay.settings{si}.asignSwitch = 'thresholded';
                            app.assignSwitch.Value = 'thresholded';
                            
                            app.overlay.settings{si}.binSwitch = 'Off';
                            app.binSwitch.Value = 'Off';
                            
                            lm = [];
                        else
                            lm = [app.overlay.settings{si(fi)}.limMinOver app.overlay.settings{si(fi)}.limMaxOver];
                        end
                        
                        % now patch...but first check if there is an
                        % underlay for the hemisphere that you are trying
                        % to patch
                        if length(app.overlay.bm{si(fi)}) == size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1)
                            if isfield(app.underlay,app.overlay.settings{si(fi)}.hemi)
                                if strcmp(clstr,'true') && ~isempty(app.overlay.settings{si(fi)}.clusters)
                                    [app.currOver{fi},cmap,ticks,values,smoothed,clusters,transp] = patchOverlay(app.brainFig,app.overlay.bm{si(fi)},app.underlay,app.overlay.settings{si(fi)}.hemi,'vls',lower(app.overlay.settings{si(fi)}.valueSelection),'thresh',[app.overlay.settings{si(fi)}.lowerThreshBox app.overlay.settings{si(fi)}.upperThreshBox],...
                                        'pos',lower(app.overlay.settings{si(fi)}.posSwitch),'neg',lower(app.overlay.settings{si(fi)}.negSwitch),'colormap',cm,'colorSpacing',lower(app.overlay.settings{si(fi)}.selectSpacing),'colorBins',app.overlay.settings{si(fi)}.binBox, ...
                                        'colorSpecial',app.overlay.settings{si(fi)}.colorSpecial,'invertColors',lower(app.overlay.settings{si(fi)}.invertSwitch),'limits',lm,'binarize',lower(app.overlay.settings{si(fi)}.binSwitch),...
                                        'inclZero',lower(app.overlay.settings{si(fi)}.remZeroSwitch),'opacity',app.overlay.settings{si(fi)}.opacityBox,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT,...
                                        'clusterThresh',app.overlay.settings{si(fi)}.clusterThreshBox,'pMap',app.overlay.settings{si(fi)}.pMap,'pThresh',app.overlay.settings{si(fi)}.pThresh,'outline',lower(app.overlay.settings{si(fi)}.outlineDropDown),'grow',app.overlay.settings{si(fi)}.growShrinkBox,'priorClusters',app.overlay.settings{si(fi)}.clusters);
                                else
                                    [app.currOver{fi},cmap,ticks,values,smoothed,clusters,transp] = patchOverlay(app.brainFig,app.overlay.bm{si(fi)},app.underlay,app.overlay.settings{si(fi)}.hemi,'vls',lower(app.overlay.settings{si(fi)}.valueSelection),'thresh',[app.overlay.settings{si(fi)}.lowerThreshBox app.overlay.settings{si(fi)}.upperThreshBox],...
                                        'pos',lower(app.overlay.settings{si(fi)}.posSwitch),'neg',lower(app.overlay.settings{si(fi)}.negSwitch),'colormap',cm,'colorSpacing',lower(app.overlay.settings{si(fi)}.selectSpacing),'colorBins',app.overlay.settings{si(fi)}.binBox, ...
                                        'colorSpecial',app.overlay.settings{si(fi)}.colorSpecial,'invertColors',lower(app.overlay.settings{si(fi)}.invertSwitch),'limits',lm,'binarize',lower(app.overlay.settings{si(fi)}.binSwitch),...
                                        'inclZero',lower(app.overlay.settings{si(fi)}.remZeroSwitch),'opacity',app.overlay.settings{si(fi)}.opacityBox,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT,...
                                        'clusterThresh',app.overlay.settings{si(fi)}.clusterThreshBox,'pMap',app.overlay.settings{si(fi)}.pMap,'pThresh',app.overlay.settings{si(fi)}.pThresh,'outline',lower(app.overlay.settings{si(fi)}.outlineDropDown),'grow',app.overlay.settings{si(fi)}.growShrinkBox);
                                end
                                
                                % now setup the colorbar
                                cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));
                                if length(app.brainFig.Children) > 1
                                    pos = get(app.brainFig.Children(end),'Position');
                                else
                                    pos = get(app.brainFig.Children,'Position');
                                end
                                
                                if strcmpi(app.cBarDir,'vert')
                                    stripe = 0.04;
                                    edge = 0.01;
                                    space = 0.03;
                                    p1 = (pos(1)+pos(3)+space);
                                    rect = [(p1 - app.cBarSpacing*(length(si)-1)) pos(2) stripe*pos(3) pos(4)];
                                    rect(1) = rect(1) + app.cBarSpacing*(fi-1);
                                    transp.map = transp.map';
                                    tlab = 'YTickLabels';
                                    tlab2 = 'YTick';
                                    tr = 'xtick';
                                elseif strcmpi(app.cBarDir,'horiz')
                                    stripe = (0.075)/1.7;
                                    space = .1;
                                    offs = 0.07;
                                    %rect = [pos(1) (pos(2)+(pos(2) - app.cBarSpacing*(length(si)-1))) pos(3) stripe*pos(4)];
                                    p2 = (pos(2) - offs);
                                    rect = [pos(1) (p2 + app.cBarSpacing*(length(si)-1)) pos(3) stripe*pos(4)];
                                    rect(2) = rect(2) - app.cBarSpacing*(fi-1);
                                    cb = permute(cb,[2 1 3]);
                                    tlab = 'XTickLabels';
                                    tlab2 = 'XTick';
                                    tr = 'ytick';
                                end
                                app.cBarAx{fi} = axes('Position', rect);
                                app.cBar{fi} = image(app.cBarAx{fi},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                                
                                set(app.cBarAx{fi},[tr 'labelmode'],'manual')
                                set(app.cBarAx{fi},[tr 'label'],[])
                                set(app.cBarAx{fi},'Ydir','normal','YAxisLocation','right')
                                app.cBar{fi}.Parent.TickLength = [0 0];
                                app.cBar{fi}.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                                app.cBar{fi}.Parent.(tlab) = num2str(ticks.labels(:));
                                
                                if length(si) > 1
                                    tmpNm = app.overlayListBox.Items{si(fi)};
                                    id = strfind(tmpNm,'_');
                                    tmpNm(id) = ' ';
                                    if length(tmpNm) > 50
                                        tmpNm = tmpNm(1:50);
                                    end
                                    if strcmpi(app.cBarDir,'vert')
                                        %app.cBarText{fi} = text(app.cBarAx{fi},app.cBarAx{fi}.Position(1)/2,app.cBarAx{fi}.Position(2)-0.01,tmpNm,'Rotation',90);
                                        app.cBarText{fi} = text(app.cBarAx{fi},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                                    else
                                        %app.cBarText{fi} = text(app.cBarAx{fi}.Position(1)+0.2,app.cBarAx{fi}.Position(2)+0.3,tmpNm);
                                        app.cBarText{fi} = text(app.cBarAx{fi},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                                    end
                                end
                                
                                if strcmpi(app.cBarShow,'no')
                                    app.cBar{fi}.Visible = 'off';
                                    app.cBarAx{fi}.Visible = 'off';
                                    app.cBarText{fi}.Visible = 'off';
                                end
                                
                                % save out clusters
                                app.clusters = clusters;
                                app.overlay.settings{si(fi)}.clusters = clusters;
                                
                                % save out values
                                app.overlay.settings{si(fi)}.threshVals = values;
                                
                                % if you performed smoothing just now, overwrite data and
                                % update histogram; if you didn't, still update histogram
                                if length(si) == 1
                                    switch vls
                                        case 'true'
                                            app.overlay.bm{si} = values;
                                            app.overlay.settings{si}.limMinOver = min(app.overlay.bm{si});
                                            app.overlay.settings{si}.limMaxOver = max(app.overlay.bm{si});
                                    end
                                    switch smth
                                        case 'true'
                                            if app.overlay.settings{si}.smoothButton == 1
                                                app.overlay.bm{si} = smoothed;
                                            end
                                    end
                                end
                                
                                
                                [tog] = updateGUI(app,'overlay','false','true');
                                tmp = findall(app.brainFig.Children,'Type','Axes');
                                
                                if length(tmp) > 1
                                    tmp=tmp(end);
                                end
                                
                                if strcmpi(app.overlay.settings{si(fi)}.hemi,'right')
                                    view(tmp(end),[90 0])
                                end
                                if strcmpi(app.overlay.settings{si(fi)}.hemi,'left')
                                    view(tmp,[-90 0])
                                end
                            else
                                uialert(app.brainSurferUIFigure,['There is no surface to patch this file on :' app.overlay.names{si(fi)} ''],'Invalid action');
                            end
                        else
                            uialert(app.brainSurferUIFigure,['Surface shape does not match the shape of the brainmap that you are trying to patch :' app.overlay.names{si(fi)} ''],'Invalid action');
                        end
                        
                    end
                else
                    [tog] = updateGUI(app,'overlay','true','true');
                end
            end
        end
        
        function importAtlas(app,f,lg)
            % f is a cell array of file paths to atlas(es).
            % l is a file path to file with labels for atlas
            ign = {'pths';'brainFig';'sg';'sgB';'underlay';'overlay';'ticksSG';'ticks';'colormapSG';'colormap';'colormapSG_tmp';'sgTh';'ticksSG_tmp';'ln';'rn';'dlights';'pMap';'sg_tmp';'curs';'dcm';'info';'surfC';'sulcColorDisp';'gyrColorDisp';'userColor';'limMaxLineSurf';'limMinLineSurf';'lowerThreshLineSurf';'upperThreshLineSurf';'limMaxLineOver';'limMinLineOver';'lowerThreshLineOver';'upperThreshLineOver';'axSurf';'axOver';'lSurf';'lOver';'currOver';'cBar';'cBarDir';'cBarAx';'cBarUnder';'cBarUnderShow';'cBarShow';'cBarUnderDir';'cBarUnderAx';'clusters';'cBarText';'cBarSpacing';'ci';'nmL';'atlas';'overlaySelectionApp';'defaultSetOver'};
            props = properties(app);
            for i = 1:length(props)
                if isempty(intersect(props{i},ign))
                    try
                        app.(props{i}).Enable = 'off';
                    catch
                    end
                end
            end
            app.lOver.Visible = 'off';
            app.lSurf.Visible = 'off';
            
            datacursormode(app.brainFig,'on');
            dc = datacursormode(app.brainFig);
            dc.Enable = 'on';
            dc.UpdateFcn = @(src,evnt)dcDisplay(app,src,evnt);
            
            if ~isempty(f)
                if ~isempty(app.atlas.overlay.left)
                    delete(app.atlas.overlay.left)
                    app.atlas.overlay.left = [];
                end
                
                if ~isempty(app.atlas.overlay.right)
                    delete(app.atlas.overlay.right)
                    app.atlas.overlay.right = [];
                end
                                
                if ~isempty(app.atlas.cBar)
                    delete(app.atlas.cBar)
                    app.atlas.cBar = [];
                end
                
                if ~isempty(app.atlas.cBarText)
                    delete(app.atlas.cBarText)
                    app.atlas.cBarText = [];
                end
                
                if ~isempty(app.atlas.cBarAx)
                    delete(app.atlas.cBarAx)
                    app.atlas.cBarAx = [];
                end
                
                app.atlas.defaultColors.left = [];
                app.atlas.bm.left = [];
                app.atlas.regions.left = [];
                app.atlas.clusters.left = [];
                app.atlas.borders.left = [];
                app.atlas.name.left = [];
                app.atlas.path.left = [];
                app.atlas.defaultColors.right = [];
                app.atlas.bm.right = [];
                app.atlas.regions.right = [];
                app.atlas.clusters.right = [];
                app.atlas.borders.right = [];
                app.atlas.name.right = [];
                app.atlas.path.right = [];
                
                [app,hemi] = idHemi(app,f);
                
                for i = 1:length(f)
%                     if ~isempty(app.atlas.overlay.(hemi{i}))
%                         delete(app.atlas.overlay.(hemi{i}))
%                         app.atlas.overlay.(hemi{i}) = [];
%                     end
%                     
%                     if ~isempty(app.atlas.cBar)
%                         delete(app.atlas.cBar)
%                         app.atlas.cBar = [];
%                     end
%                     
%                     if ~isempty(app.atlas.cBarText)
%                         delete(app.atlas.cBarText)
%                         app.atlas.cBarText = [];
%                     end
%                     
%                     if ~isempty(app.atlas.cBarAx)
%                         delete(app.atlas.cBarAx)
%                         app.atlas.cBarAx = [];
%                     end
%                     
%                     app.atlas.defaultColors.(hemi{i}) = [];
%                     app.atlas.bm.(hemi{i}) = [];
%                     app.atlas.regions.(hemi{i}) = [];
%                     app.atlas.clusters.(hemi{i}) = [];
%                     app.atlas.borders.(hemi{i}) = [];
%                     app.atlas.name.(hemi{i}) = [];
%                     app.atlas.path.(hemi{i}) = [];
                    
                    [p,n,e] = fileparts(f{i});
                    app.atlas.path.(hemi{i}) = [p app.pths.sep];
                    app.atlas.name.(hemi{i}) = [n e];
                    
                    disp(['Trying to load atlas: ' f{i}])
                    
                    % now load
                    try % try to load a regular nifti file
                        disp('Attempting to load as conventional nifti file...')
                        ld = load_nifti([p app.pths.sep n e]);
                        app.atlas.bm.(hemi{i}) = ld.vol;
                        sz = size(app.atlas.bm.(hemi{i}));
                    catch
                        disp('NIFTI LOAD FAILED!')
                        try % if that did not work, load a gifti file
                            disp('Attempting to load as gifti file...')
                            ld = gifti([p app.pths.sep n e]);
                            if isfield(ld,'cdata')
                                app.atlas.bm.(hemi{i}) = ld.cdata;
                                %app.atlas.regions.(hemi{i}) = nan(size(app.underlay.(hemi{i}).Vertices,1),1);
                                app.atlas.regions.(hemi{i}) = cell([size(app.underlay.(hemi{i}).Vertices,1),1]);
                                app.atlas.regions.(hemi{i})(:) = {'No region information'};
                                sz = size(app.atlas.bm.(hemi{i}));
                            else
                                uialert(app.brainSurferUIFigure,['Could not find cdata structure (' f{i} ')'],'Unable to load File');
                                sz = [];
                            end
                        catch
                            disp('GIFTI LOAD FAILED!')
                            try % if gifti did not work, try cifti
                                disp('Attempting to load as cifti file...')
                                ld = cifti_read([p app.pths.sep n e]);
                                
                                c = cell2mat(ld.diminfo{1}.models);
                                c = {c.struct};
                                
                                l = find(contains(c,'CORTEX_LEFT'));
                                r = find(contains(c,'CORTEX_RIGHT'));
                                
                                key = [ld.diminfo{2}.maps.table.key]';
                                name = {ld.diminfo{2}.maps.table.name};
                                
                                if isempty(hemi{i})
                                    app.atlas.bm.left = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.right = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                                    app.atlas.defaultColors.left = zeros([ld.diminfo{1}.models{1}.numvert,3]);
                                    app.atlas.defaultColors.right = zeros([ld.diminfo{1}.models{2}.numvert,3]);
                                    
                                    app.atlas.bm.left(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                    app.atlas.bm.right(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                    
                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';
                                    
                                    [~, is2] = ismember(app.atlas.bm.left,key);
                                    app.atlas.regions.left = name(is2);
                                    app.atlas.defaultColors.left = cmap(is2,1:3);
                                    app.atlas.defaultCbar.left = cmap(:,1:3);
                                    
                                    [~, is2] = ismember(app.atlas.bm.right,key);
                                    app.atlas.regions.right = name(is2);
                                    app.atlas.defaultColors.right = cmap(is2,1:3);
                                    app.atlas.defaultCbar.right = cmap(:,1:3);
                                    
                                    sz = size(app.atlas.bm.left);
                                    
                                    if length(f) > 2
                                        uialert(app.brainSurferUIFigure,'You have a CIFTI file with no reference to a hemisphere, meaning it is bilateral. Make sure you are loading in only one atlas file in this case.','Action unavailable');
                                    end
                                    
                                elseif strcmpi(hemi{i},'left')
                                    app.atlas.bm.left = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.left(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                    app.atlas.defaultColors.left = zeros([ld.diminfo{1}.models{1}.numvert,3]);
                                    sz = size(app.atlas.bm.left);
                                    [~, is2] = ismember(app.atlas.bm.left,key);
                                    app.atlas.regions.left = name(is2);
                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';
                                    app.atlas.defaultColors.left = cmap(is2,1:3);
                                    app.atlas.defaultCbar.left = cmap(:,1:3);
                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.left = app.atlas.bm.left + (max(app.atlas.bm.right) + 1);
                                        end
                                    end
                                    
                                elseif strcmpi(hemi{i},'right')
                                    app.atlas.bm.right = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.right(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                    sz = size(app.atlas.bm.right);
                                    [~, is2] = ismember(app.atlas.bm.right,key);
                                    app.atlas.regions.right = name(is2);
                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';
                                    app.atlas.defaultColors.right = cmap(is2,1:3);
                                    app.atlas.defaultCbar.right = cmap(:,1:3);
                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.right = app.atlas.bm.right + (max(app.atlas.bm.left) + 1);
                                        end
                                    end
                                end
                            catch
                                disp('CIFTI LOAD FAILED!')
                                try
                                    disp('Attempting to load as annotation file...')
                                    [vertices, label, ctab] = read_annotation([p app.pths.sep n e]);
                                    app.atlas.bm.(hemi{i}) = zeros(size(app.underlay.(hemi{i}).Vertices,1),1);
                                    [~, is2] = ismember(label,ctab.table(:,end));
                                    %app.atlas.bm.(hemi{i})(vertices+1) = label;
                                    %app.atlas.bm.(hemi{i})(vertices+1) = is2;
                                    app.atlas.bm.(hemi{i}) = is2;
                                    % use the commented out line to trim the
                                    % 'values' for each roi (because these do
                                    % get displayed)
                                    cmap = ctab.table(:,1:3);
                                    app.atlas.defaultColors.(hemi{i}) = cmap(is2,1:3);
                                    app.atlas.regions.(hemi{i}) = ctab.struct_names(is2);
                                    app.atlas.defaultCbar.(hemi{i}) = ctab.table(:,1:3);
                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.right = app.atlas.bm.right + (max(app.atlas.bm.left) + 1);
                                        end
                                    end
                                    
%                                     if i == 2
%                                         id = find(app.atlas.bm.(hemi{i}) ~= 0);
%                                         if strcmpi(hemi{i},'left')
%                                             app.atlas.bm.(hemi{i})(id) = app.atlas.bm.(hemi{i})(id) + max(app.atlas.bm.right);
%                                         elseif strcmpi(hemi{i},'right')
%                                             app.atlas.bm.(hemi{i})(id) = app.atlas.bm.(hemi{i})(id) + max(app.atlas.bm.left);
%                                         end
%                                     end
                                    
                                    sz = size(app.atlas.bm.(hemi{i}));
                                catch
                                    disp('ANNOTATION LOAD FAILED!')
                                    try
                                        disp('Attempting to load as label...')
                                        [l_template, coordsTemplate] = read_label([], [p app.pths.sep n e]);
                                        app.atlas.regions.(hemi{i}) = cell(size(app.underlay.(hemi{i}).Vertices,1),1);
                                        app.atlas.regions.(hemi{i})(:) =  {'No region information'};
                                        app.atlas.regions.(hemi{i})(l_template(:,1)+1) = {'ROI'};
                                        app.atlas.bm.(hemi{i})(l_template(:,1)+1) = 1;
                                        sz = size(app.atlas.bm.(hemi{i}));
                                    catch
                                        disp('Uh-oh...your file could not be loaded as nifti, gifti, cifti, annotation or label')
                                    end
                                end
                                
                            end
                        end
                    end
                    
                    % check if data size is 3D...if it is, ask if user
                    % wants to import volume to surface
                    if sz(2) > 1
                        uialert(app.brainSurferUIFigure,'You cannot import a volume space atlas at the moment','Action unavailable');

                    end
                    
                    % get atlas names
                    if ~isempty(lg)
                        [~,~,e] = fileparts(lg);
                        switch e
                            case '.xml'
                                if i == 1
                                    xml = xml2struct([p f]);
                                    name = [];
                                    key = [];
                                    for j = 1:length(xml.Children(4).Children)
                                        if ~isempty(xml.Children(4).Children(j).Children)
                                            name = [name; {xml.Children(4).Children(j).Children.Data}];
                                            key = [key; str2double(xml.Children(4).Children(j).Attributes(1).Value)];
                                        end
                                    end
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                [~, is2] = ismember(app.atlas.bm.(hemi{i}),key);
                                
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = name(is2(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};
                            case '.txt'
                                if i == 1
                                    fid = fopen(lg);
                                    tline = fgetl(fid);
                                    tlines = cell(0,1);
                                    while ischar(tline)
                                        tlines{end+1,1} = tline;
                                        tline = fgetl(fid);
                                    end
                                    fclose(fid);
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = tlines(app.atlas.bm.(hemi{i})(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};;
                        end
                    end
                    
                    % get clusters and borders
                    atv = find(app.atlas.bm.(hemi{i}) ~= 0);
                    inUn = unique(app.atlas.bm.(hemi{i})(atv));
                    for roii = 1:length(inUn)
                        dataClust{roii} = find(app.atlas.bm.(hemi{i}) == inUn(roii));
                    end
                    app.atlas.clusters.(hemi{i}) = dataClust;
                    dataClust = getClusterBoundary(app.atlas.clusters.(hemi{i}), app.underlay.(hemi{i}).Faces);
                    app.atlas.borders.(hemi{i}) = dataClust;
                end
                
                if ~isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left; app.atlas.bm.right];
                elseif isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.right];
                elseif ~isempty(app.atlas.bm.left) & isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left];
                end
                
                % update GUI
                app.upperLimAtlas.Value = max(tog);
                app.lowerLimAtlas.Value = min(tog);
                app.colormapSelectAtlas.Value = 'jet';
                app.selectSpacingAtlas.Value = 'even';
                app.binAtlas.Value = (max(tog) - min(tog) +1);
                app.shrinkGrowAtlas.Value = 0;
                app.toggleColorbarAtlas.Value = 0;
                app.invertToggleAtlas.Value = 'Off';
                app.opacityAtlas.Value = 0;
                app.borderToggleAtlas.Value = 'Off';
                
                for i = 1:length(f)
                    % now patch
                    [app.atlas.overlay.(hemi{i}),cmap,ticks,~,~,~,transp] = patchOverlay(app.brainFig,app.atlas.bm.(hemi{i}),app.underlay,hemi{i},...
                        'opacity',app.opacityAtlas.Value,'UI',app.brainSurferUIFigure,'colormap',app.colormapSelectAtlas.Value,'colorSpacing',app.selectSpacingAtlas.Value,...
                        'colorBins',app.binAtlas.Value,'limits',[app.lowerLimAtlas.Value app.upperLimAtlas.Value],'priorClusters',app.atlas.clusters.(hemi{i}));
                end
                
                % now setup the colorbar
                cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));
                
                if length(app.brainFig.Children) > 1
                    pos = get(app.brainFig.Children(end),'Position');
                else
                    pos = get(app.brainFig.Children,'Position');
                end
                
                if strcmpi(app.cBarDir,'vert')
                    stripe = (0.075)/1.7;
                    space = .1;
                    offs = 0.07;
                    %rect = [pos(1) (pos(2)+(pos(2) - app.cBarSpacing*(length(si)-1))) pos(3) stripe*pos(4)];
                    p2 = (pos(2) - offs);
                    rect = [pos(1) (p2 + app.cBarSpacing*(length(f)-1)) pos(3) stripe*pos(4)];
                    rect(2) = rect(2) - app.cBarSpacing*(i-1);
                    cb = permute(cb,[2 1 3]);
                    tlab = 'XTickLabels';
                    tlab2 = 'XTick';
                    tr = 'ytick';
                elseif strcmpi(app.cBarDir,'horiz')
                    stripe = 0.04;
                    edge = 0.01;
                    space = 0.03;
                    p1 = (pos(1)+pos(3)+space);
                    rect = [(p1 - app.cBarSpacing*(length(f)-1)) pos(2) stripe*pos(3) pos(4)];
                    rect(1) = rect(1) + app.cBarSpacing*(i-1);
                    transp.map = transp.map';
                    tlab = 'YTickLabels';
                    tlab2 = 'YTick';
                    tr = 'xtick';
                end
                app.atlas.cBarAx = axes('Position', rect);
                app.atlas.cBar = image(app.atlas.cBarAx,cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                
                set(app.atlas.cBarAx,[tr 'labelmode'],'manual')
                set(app.atlas.cBarAx,[tr 'label'],[])
                set(app.atlas.cBarAx,'Ydir','normal','YAxisLocation','right')
                app.atlas.cBar.Parent.TickLength = [0 0];
                app.atlas.cBar.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                app.atlas.cBar.Parent.(tlab) = num2str(ticks.labels(:));
                
                if length(f) == 1
                    atNm = app.atlas.name.(hemi{1});
                elseif length(f) == 2
                    [s,id] = setdiff(app.atlas.name.left,app.atlas.name.right,'stable');
                    tmp = app.atlas.name.left;
                    tmp(id) = [];
                    atNm = tmp;
                end
                
                id = strfind(atNm,'_');
                atNm(id) = ' ';
                if length(atNm) > 50
                    atNm = atNm(1:50);
                end
                if strcmpi(app.cBarDir,'vert')
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5-(0.003*length(atNm)),0.5,atNm);
                else
                    app.atlas.cBarText= text(app.atlas.cBarAx,0.5,0.5-(0.005*length(atNm)),atNm,'Rotation',90);
                end
                
%                 if strcmpi(app.cBarDir,'vert')
%                     app.atlas.cBarText = text(app.atlas.cBarAx.Position(1)+(0.01*length(atNm)),app.atlas.cBarAx.Position(2)+0.3,atNm);
%                 else
%                     app.atlas.cBarText = text(app.atlas.cBarAx,app.atlas.cBarAx.Position(1)-0.3,app.atlas.cBarAx.Position(2)-0.01,atNm);
%                     set(app.atlas.cBarText,'Rotation',90);
%                 end
                
                app.atlas.cBar.Visible = 'off';
                app.atlas.cBarAx.Visible = 'off';
                app.atlas.cBarText.Visible = 'off';
                
                app.LoadedatlasLabel.Text  = ['Loaded atlas: '  atNm];
                
                for i = 1:length(props)
                    if isempty(intersect(props{i},ign))
                        try
                            app.(props{i}).Enable = 'on';
                        catch
                        end
                    end
                end
                app.lOver.Visible = 'on';
                app.lSurf.Visible = 'on';
            end
        end
        
        function updateAtlas(app)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    f = 2;
                    hemi{1} = 'left'; hemi{2} = 'right';
                elseif isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    f = 1;
                    hemi{1} = 'right';
                elseif ~isempty(app.atlas.overlay.left) & isempty(app.atlas.overlay.right)
                    f = 1;
                    hemi{1} = 'left';
                end
                
                for i = 1:f
                    % remove the old stuff
                    if ~isempty(app.atlas.overlay.(hemi{i}))
                        delete(app.atlas.overlay.(hemi{i}))
                        app.atlas.overlay.(hemi{i}) = [];
                    end
                    
                    bm = app.atlas.bm.(hemi{i});
                    ic = 'on';
                    if strcmpi(app.borderToggleAtlas.Value,'On')
                        atv = unique(vertcat(app.atlas.borders.(hemi{i}){:}));
                        av = [1:length(bm)]';
                        t = setdiff(av,atv);
                        bm(t) = 0;
                        ic = 'off';
                         if app.shrinkGrowAtlas.Value ~= 0
                             bm = zeros(size(app.atlas.bm.(hemi{i})));
                             pc = app.atlas.borders.(hemi{i});
                             atv = vertcat(app.atlas.borders.(hemi{i}){:});
                             d = uiprogressdlg(app.brainSurferUIFigure,'Title','Growing map',...
                                 'Message','Growing/shrinking map...');
                             d.Value = 0.5;
            
                             [grownVert,atv2,growVertTracker2] = growMap(app.atlas.borders.(hemi{i}),app.shrinkGrowAtlas.Value,atv,app.underlay.(hemi{i}).Faces);
                             
                             for clusteri = 1:length(app.atlas.borders.(hemi{i}))
                                 v = unique(app.atlas.bm.(hemi{i})(app.atlas.clusters.(hemi{i}){clusteri}));
                                 vert = unique(growVertTracker2{clusteri});
                                 bm(vert) = v;
                             end
                             d.Value = 1;
                         end
                    end
                    
                    % now patch
                    [app.atlas.overlay.(hemi{i}),cmap,ticks,~,~,~,transp] = patchOverlay(app.brainFig,bm,app.underlay,hemi{i},...
                        'opacity',app.opacityAtlas.Value,'UI',app.brainSurferUIFigure,'colormap',app.colormapSelectAtlas.Value,'colorSpacing',app.selectSpacingAtlas.Value,'inclZero',ic,...
                        'invertColors',lower(app.invertToggleAtlas.Value),'colorBins',app.binAtlas.Value,'limits',[app.lowerLimAtlas.Value app.upperLimAtlas.Value],...
                        'priorClusters',app.atlas.clusters.(hemi{i}));
                end
                
                if ~isempty(app.atlas.cBar)
                    delete(app.atlas.cBar)
                    app.atlas.cBar = [];
                end
                
                if ~isempty(app.atlas.cBarText)
                    delete(app.atlas.cBarText)
                    app.atlas.cBarText = [];
                end
                
                if ~isempty(app.atlas.cBarAx)
                    delete(app.atlas.cBarAx)
                    app.atlas.cBarAx = [];
                end
                
                % now setup the colorbar
                cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));
                
                if length(app.brainFig.Children) > 1
                    pos = get(app.brainFig.Children(end),'Position');
                else
                    pos = get(app.brainFig.Children,'Position');
                end
                
                if strcmpi(app.cBarDir,'vert')
                    stripe = (0.075)/1.7;
                    space = .1;
                    offs = 0.07;
                    %rect = [pos(1) (pos(2)+(pos(2) - app.cBarSpacing*(length(si)-1))) pos(3) stripe*pos(4)];
                    p2 = (pos(2) - offs);
                    rect = [pos(1) (p2 + app.cBarSpacing*(f-1)) pos(3) stripe*pos(4)];
                    rect(2) = rect(2) - app.cBarSpacing*(1-1);
                    cb = permute(cb,[2 1 3]);
                    tlab = 'XTickLabels';
                    tlab2 = 'XTick';
                    tr = 'ytick';
                elseif strcmpi(app.cBarDir,'horiz')
                    stripe = 0.04;
                    edge = 0.01;
                    space = 0.03;
                    p1 = (pos(1)+pos(3)+space);
                    rect = [(p1 - app.cBarSpacing*(f-1)) pos(2) stripe*pos(3) pos(4)];
                    rect(1) = rect(1) + app.cBarSpacing*(1-1);
                    transp.map = transp.map';
                    tlab = 'YTickLabels';
                    tlab2 = 'YTick';
                    tr = 'xtick';
                end
                app.atlas.cBarAx = axes('Position', rect);
                app.atlas.cBar = image(app.atlas.cBarAx,cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                
                set(app.atlas.cBarAx,[tr 'labelmode'],'manual')
                set(app.atlas.cBarAx,[tr 'label'],[])
                set(app.atlas.cBarAx,'Ydir','normal','YAxisLocation','right')
                app.atlas.cBar.Parent.TickLength = [0 0];
                app.atlas.cBar.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                app.atlas.cBar.Parent.(tlab) = num2str(ticks.labels(:));
                
                if strcmpi(app.cBarDir,'vert')
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5-(0.003*length(app.LoadedatlasLabel.Text(15:end))),0.5,app.LoadedatlasLabel.Text(15:end));
                else
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5,0.5-(0.005*length(app.LoadedatlasLabel.Text(15:end))),app.LoadedatlasLabel.Text(15:end),'Rotation',90);
                end
                
                if app.toggleColorbarAtlas.Value == 0
                    app.atlas.cBar.Visible = 'off';
                    app.atlas.cBarAx.Visible = 'off';
                    app.atlas.cBarText.Visible = 'off';
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end
        

        function [tog] = updateGUI(app,fT,def,smth)
            %Function for updating GUI based on some selection. fT determines whether update will involve underlay or overlay data in GUI. Def will determine if you would like the update to set Tdefault  values. Note, default means different things for underlay and overlay because of how these types of data are treated. There is no expectation for how many overlays you will be loading, but we do expect you to only ever have two surface files loaded. As a result, underlay sulcal/gyral information is organized in a simple left/right variable (app.sg.left and app.sg.right with backups in app.sgB.left/right) that gets overwritten but overlays are stored in a cell array (app.overlay). Default in the context of underlays refers to GUI information updated based on loaded sulcal/gyral information. Default in the context of overlays refers to the out-of-the-box GUI state and is only used if the user selects “no overlay” patch. Otherwise, the kind of information treated as “default” for underlays is extracted at the time of overlay loading and stored in a settings structure (app.overlay.settings). The final smth argument refers to whether smoothing was applied to the data. This only matters for underlay data because only for this data do we sometimes need to update one portion of the GUI but not another (e.g., to compute ‘defaults’ for a map over again to reset things, or to update one portion of the GUI but not another).
            
            switch fT
                case 'underlay'
                    % FIRST: update GUI
                    % combine left and right hemisphere data (if it exists) to
                    % update GUI
                    
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    
                    if (isfield(app.sg,'right') & ~isempty(app.sg.right)) & (isfield(app.sg,'left') & ~isempty(app.sg.left))
                        tog = [app.sg.left; app.sg.right];
                        if sum(app.underlay.left.Vertices(:,3)) ~= 0 || sum(app.underlay.right.Vertices(:,3)) ~= 0
                            view(tmp,[-90 0])
                        end
                    elseif isfield(app.sg,'left') & ~isempty(app.sg.left)
                        tog = [app.sg.left]';
                        if sum(app.underlay.left.Vertices(:,3)) ~= 0
                            view(tmp,[-90 0])
                        end
                    elseif isfield(app.sg,'right') & ~isempty(app.sg.right)
                        tog = [app.sg.right]';
                        if sum(app.underlay.right.Vertices(:,3)) ~= 0
                            view(tmp,[90 0])
                        end
                    end
                    app.limMinSurf.Value = min(tog);
                    app.limMaxSurf.Value = max(tog);
                    
                    if strcmp(smth,'true') & app.smoothButtonSurf.Value == 0
                        
                    else
                        %% GUI update sequence for underlays
                        app.axSurf.XLim = [min(tog) max(tog)];
                        app.lowerThreshBoxSurf.Limits = app.axSurf.XLim;
                        app.upperThreshBoxSurf.Limits = app.axSurf.XLim;
                        app.sgThresh.Limits = app.axSurf.XLim;
                        app.limMaxSurf.Value = app.axSurf.XLim(2);
                        app.limMinSurf.Value = app.axSurf.XLim(1);
                        
                        ns = 5;
                        tmp = min(tog):(max(tog)-min(tog))/(ns-1):max(tog);
                        if length(tmp) < ns
                            tmp = [tmp max(tog)];
                        end
                        app.axSurf.XTick = tmp;
                    end
                    
                    % update steps
                    app.lowerThreshBoxSurf.Step = range(tog)/20;
                    app.upperThreshBoxSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.limMaxSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.limMinSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.sgThresh.Step = app.lowerThreshBoxSurf.Step;
                    
                    % update intensity histogram
                    f = figure('visible','off');
                    h = histfit(tog,100,'kernel');
                    bar(h(1).XData,h(1).YData,'k','Parent',app.histSurf);
                    hold(app.histSurf,'on')
                    plot(h(2).XData,h(2).YData,'k','Parent',app.histSurf,'Color',[0.941 0.46 0.262],'LineWidth',2);
                    l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                    app.limMinLineSurf = plot(ones(size(l))*app.limMinSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle','--');
                    l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                    app.limMaxLineSurf = plot(ones(size(l))*app.limMaxSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle','--');
                    hold(app.histSurf,'off');
                    delete(f)
                    
                    tmp = fieldnames(app.underlay);
                    u = unique(app.underlay.(tmp{1}).FaceVertexCData,'rows');
                    m = mean(u,2);
                    [~,mIdx] = min(m);
                    [~,mmIdx] = max(m);
                    app.sulcButton.BackgroundColor = u(mIdx,:);
                    app.gyrButton.BackgroundColor = u(mmIdx,:);
                    
                    switch def
                        case 'true'
                            app.colormapSelectorSurf.Value = 'gray';
                            app.selectSpacingSurf.Value = 'even';
                            app.valueSelectionSurf.Value = 'Raw';
                            app.posSwitchSurf.Value = 'On';
                            app.negSwitchSurf.Value = 'On';
                            app.remZeroSwitchSurf.Value = 'Off';
                            m = mean([min(tog) max(tog)]);
                            app.lSurf.Position = [m 0; m 0];
                            app.smoothButtonSurf.Value = 0;
                            app.smoothAreaUnder.Value = 0;
                            app.smoothStepsUnder.Value = 0;
                            app.assignSwitchUnder.Value = 'thresholded';
                            
                            app.lowerThreshBoxSurf.Value = m;
                            app.upperThreshBoxSurf.Value = m;
                            
                            hold(app.histSurf,'on')
                            l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                            app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                            l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                            app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                            hold(app.histSurf,'off')
                            
                            app.binSwitchSurf.Value = 'Off';
                            try
                                app.sgThresh.Value = 0;
                            catch
                                app.sgThresh.Value = min(tog);
                            end
                            app.binBoxSurf.Value = 1000;
                            app.opacityBoxSurf.Value = 1;
                            app.opacityBoxSG.Value = 1;
                            app.invertSwitchSurf.Value = 'Off';
                            app.smoothAreaUnder.Value = 0;
                            app.smoothStepsUnder.Value = 0;
                    end
                    
                case 'overlay'
                    
                    % here you will need to combine all selected overlays
                    % into one
                    % get selections
                    [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                    tog = cat(1, app.overlay.bm{si}); % we are getting this now in case you want to make a histogram of all the different maps at some point...
                    
                    % check if selections are just 'no overlay' in which
                    % case make everything in GUI default and don't let the data update the GUI
                    if si == 1
                        def = 'true';
                    else
                        % update histograms first because for this it does
                        % not matter whether 1 item was selected or
                        % multiple items...
                        
                        if length(si) == 1
                            c1 = [0 0 0 1]; % bar color
                            c2 = [0.941 0.46 0.262 1]; % line color
                            c4 = [1 0 0 1];% threshold color
                            c3 = [0.635 0.078 0.184 1]; % limits colors
                        else
                            c = distinguishable_colors(length(si));
                            c1 = c;
                            c1(:,4) = 0.3;
                            c2 = c;
                            c2(:,4) = 0.6;
                            c4 = c;
                            c4(:,4) = 0.2;
                            c3 = c;
                            c3(:,4) = 0.2;
                        end
                        
                        % clear legend if last slection was multi-overlay
                        leg = legend(app.histOver);
                        delete(leg)
                        
                        mins = [];
                        maxs = [];
                        for fi = 1:length(si)
                            % update intensity histogram
                            f = figure('visible','off');
                            h = histfit(app.overlay.bm{si(fi)},100,'kernel');
                            if length(si) > 1
                                p = bar(h(1).XData,h(1).YData,'FaceColor',c1(fi,1:3),'EdgeColor','none','FaceAlpha',0.3,'Parent',app.histOver); % have to ignore manually specified opacity because it doesn't work for "bar"
                                p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            else
                                p = bar(h(1).XData,h(1).YData,'FaceColor',c1(fi,1:3),'EdgeColor','none','FaceAlpha',1,'Parent',app.histOver);
                                p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            end
                            hold(app.histOver,'on');
                            p = plot(h(2).XData,h(2).YData,'Color',[c2(fi,:)],'Parent',app.histOver,'LineWidth',2);
                            
                            l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),10000);
                            app.limMinLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.limMinOver,l,'k','Parent',app.histOver,'Color',[c3(fi,:)],'LineWidth',2,'LineStyle','--');
                            app.limMinLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            app.limMaxLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.limMaxOver,l,'k','Parent',app.histOver,'Color',[c3(fi,:)],'LineWidth',2,'LineStyle','--');
                            app.limMaxLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            
                            app.lowerThreshLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.lowerThreshBox,l,'k','Parent',app.histOver,'Color',[c4(fi,:)],'LineWidth',2,'LineStyle',':');
                            app.lowerThreshLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            app.upperThreshLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.upperThreshBox,l,'k','Parent',app.histOver,'Color',[c4(fi,:)],'LineWidth',2,'LineStyle',':');
                            app.upperThreshLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            
                            mins = [mins app.overlay.settings{si(fi)}.limMinOver];
                            maxs = [maxs app.overlay.settings{si(fi)}.limMaxOver];
                            
                            delete(f)
                            if fi == length(si)
                                hold(app.histOver,'off');
                            end
                        end
                        
                        if min(mins) == max(maxs)
                            app.axOver.XLim = [min(mins)-0.00001 max(maxs)];
                        else
                            app.axOver.XLim = [min(mins) max(maxs)];
                        end
                        
                        % if multi-overlay setup legend
                        if length(si) > 1
                            leg = legend(app.histOver);
                            tmp = {app.overlay.names{si}};
                            leg.String = cellfun(@(x) x(1:25), tmp, 'un', 0);
                            
                            % else update GUI parameters
                        elseif length(si) == 1
                            app.limMaxOver.Value = double(app.axOver.XLim(2));
                            app.limMinOver.Value = double(app.axOver.XLim(1));
                            app.colormapSelector.Value = app.overlay.settings{si}.colormapSelector;
                            app.selectSpacing.Value = app.overlay.settings{si}.selectSpacing;
                            app.valueSelection.Value = app.overlay.settings{si}.valueSelection;
                            
                            app.smoothButton.Value = app.overlay.settings{si}.smoothButton;
                            app.smoothArea.Value = app.overlay.settings{si}.smoothArea;
                            app.smoothSteps.Value = app.overlay.settings{si}.smoothSteps;
                            app.assignSwitch.Value = app.overlay.settings{si}.assignSwitch;
                            
                            app.posSwitch.Value = app.overlay.settings{si}.posSwitch;
                            app.negSwitch.Value = app.overlay.settings{si}.negSwitch;
                            app.remZeroSwitch.Value = app.overlay.settings{si}.remZeroSwitch;
                            
                            app.binSwitch.Value = app.overlay.settings{si}.binSwitch;
                            app.opacityBox.Value = app.overlay.settings{si}.opacityBox;
                            app.invertSwitch.Value = app.overlay.settings{si}.invertSwitch;
                            app.clusterThreshBox.Value = app.overlay.settings{si}.clusterThreshBox;
                            if app.clusterThreshBox.Value > max(app.clusterSlider.Limits)
                                app.clusterSlider.Value = max(app.clusterSlider.Limits);
                            else
                                app.clusterSlider.Value = app.clusterThreshBox.Value;
                            end
                            
                            app.pThreshBox.Value = app.overlay.settings{si}.pThresh;
                            
                            app.outlineDropDown.Value = app.overlay.settings{si}.outlineDropDown;
                            app.growShrinkBox.Value = app.overlay.settings{si}.growShrinkBox;
                            if app.growShrinkBox.Value > max(app.growShrinkSlider.Limits)
                                app.growShrinkSlider.Value = max(app.growShrinkSlider.Limits);
                            elseif app.growShrinkBox.Value < min(app.growShrinkSlider.Limits)
                                app.growShrinkSlider.Value = min(app.growShrinkSlider.Limits);
                            else
                                app.growShrinkSlider.Value = app.growShrinkBox.Value;
                            end
                            
                            app.pThreshBox.Value = app.overlay.settings{si}.pThresh;
                            
                            ns = 5;
                            tmp = app.axOver.XLim(1):(app.axOver.XLim(2)-app.axOver.XLim(1))/(ns-1):app.axOver.XLim(2);
                            if length(tmp) < ns
                                tmp = [tmp app.axOver.XLim(2)];
                            end
                            app.axOver.XTick = tmp;
                            app.lOver.Position = [app.overlay.settings{si(fi)}.lowerThreshBox 0; app.overlay.settings{si(fi)}.upperThreshBox 0];
                            app.lowerThreshBox.Value = app.overlay.settings{si(fi)}.lowerThreshBox;
                            app.upperThreshBox.Value = app.overlay.settings{si(fi)}.upperThreshBox;
                            
                            % update steps
                            app.lowerThreshBox.Step = range(tog)/20;
                            app.upperThreshBox.Step = app.lowerThreshBoxSurf.Step;
                            app.limMaxOver.Step = app.lowerThreshBoxSurf.Step;
                            app.limMinOver.Step = app.lowerThreshBoxSurf.Step;
                            
                            if ~isempty(app.overlay.settings{si}.pMap)
                                app.addpButton.Value = 1;
                            else
                                app.addpButton.Value = 0;
                            end
                            
                            app.fdrButton.Value = app.overlay.settings{si}.pFDR;
                            app.bonfButton.Value = app.overlay.settings{si}.pBonf;
                            
                        end
                    end
                    
                    switch def
                        case 'true'
                            app.colormapSelector.Value = 'Select colormap';
                            app.selectSpacing.Value = 'Select color spacing';
                            app.valueSelection.Value = 'Raw';
                            app.posSwitch.Value = 'On';
                            app.negSwitch.Value  = 'On';
                            app.remZeroSwitch.Value = 'On';
                            
                            app.axOver.XLim = [0 100];
                            app.axOver.XTick = 0:100/(11-1):100;
                            
                            app.lOver.Position = [0 0; 0 0];
                            
                            app.smoothButton.Value = 0;
                            app.smoothArea.Value = 0;
                            app.smoothSteps.Value = 0;
                            app.assignSwitch.Value = 'thresholded';
                            
                            app.lowerThreshBox.Value = 0;
                            app.upperThreshBox.Value = 0;
                            
                            leg = legend(app.histOver);
                            delete(leg)
                            plot(0,0,'k','Parent',app.histOver);
                            
                            app.binSwitch.Value = 'Off';
                            app.binBox.Value = 1000;
                            app.opacityBox.Value = 1;
                            
                            app.invertSwitch.Value = 'Off';
                            
                            app.clusterThreshBox.Value = 0;
                            app.clusterSlider.Value = 0;
                            app.pThreshBox.Value = 1;
                            app.outlineDropDown.Value = 'None';
                            app.growShrinkSlider.Value = 0;
                            app.growShrinkBox.Value = 0;
                    end
            end
        end
        
        function [app,hemi] = idHemi(app,f)
            for i = 1:length(f)
                f1 = f{i};
                id = strfind(lower(f1),'lr'); % some files will use the LR string, which we need to remove to check for l.
                f1(id:id+1) = [];
                
                % first id hemis
                fun = @(s)~cellfun('isempty',strfind(lower({f1}),s));
                out = cellfun(fun,app.ln,'UniformOutput',false);
                out2 = cellfun(fun,app.rn,'UniformOutput',false);
                os = sum(vertcat(out{:}));
                os2 = sum(vertcat(out2{:}));
                
                % catch file name errors
                %                 if (out{1}+out{2}+out{3}+out{4}) >= 1 & (out2{1}+out2{2}+out2{3}) >= 1
                %                     s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded references more than one hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                %                     hemi{i,1} = lower(s);
                %                 elseif (out{1}+out{2}+out{3}) > 0 & (out2{1}+out2{2}+out2{3}) > 0
                %                     s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded does not reference a hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                %                     hemi{i,1} = lower(s);
                %                 elseif (out{1}+out{2}+out{3}) >= 1 & (out2{1}+out2{2}+out2{3}) >= 0
                %                     hemi{i,1} = 'left';
                %                 elseif (out{1}+out{2}+out{3}) >= 0 & (out2{1}+out2{2}+out2{3}) >= 1
                %                     hemi{i,1} = 'right';
                %                 else
                %                     hemi{i,1} = [];
                %                 end
                if os >= 1 & os2 >= 1
                    s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded references more than one hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                    hemi{i,1} = lower(s);
                elseif os > 0 & os2 > 0
                    s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded does not reference a hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                    hemi{i,1} = lower(s);
                elseif os >= 1 & os2 >= 0
                    hemi{i,1} = 'left';
                elseif os >= 0 & os2 >= 1
                    hemi{i,1} = 'right';
                else
                    hemi{i,1} = [];
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pths.sep = filesep;
            app.pths.gPth = which(mfilename);
            [app.pths.gPth, ~] = fileparts(app.pths.gPth);
            
            app.pths.brnPth = [app.pths.gPth app.pths.sep 'brains']; % contains lh and rh fsaveraged brains
            app.pths.scrptPth = [app.pths.gPth app.pths.sep 'scripts']; % contains all the scripts
            addpath(genpath(app.pths.scrptPth))
            addpath(genpath([app.pths.gPth app.pths.sep 'buttons']));
            app.pths.clrmpPth = [app.pths.gPth app.pths.sep 'colormaps']; % contains all the colormaps
            app.pths.atlasPth = [app.pths.gPth app.pths.sep 'atlases'];

            app.dlights = dlmread([app.pths.scrptPth app.pths.sep 'defaultLights.txt']);
            
            format longG
            
            colormapDir = dir([app.pths.clrmpPth app.pths.sep '*.mat']);
            colormaps = {colormapDir.name};
            
            for colori = 1:length(colormaps)
                colormaps{colori} = colormaps{colori}(1:end-4);
            end
            
            app.userColor = cell(size(app.colormapSelectorSurf.Items));
            app.userColor(cellfun('isempty',app.userColor)) = {1};
            if ~isempty(colormaps)
                n = length(app.colormapSelectorSurf.Items);
                app.colormapSelectorSurf.Items = horzcat(app.colormapSelectorSurf.Items,colormaps);
                app.colormapSelector.Items = app.colormapSelectorSurf.Items;
                for i = n+1:length(app.colormapSelectorSurf.Items)
                    tmp = load([app.pths.clrmpPth app.pths.sep app.colormapSelectorSurf.Items{i}]);
                    app.userColor{i} = tmp.customColor;
                end
            end
            
            % Create the 1D axes
            app.axSurf = axes( ...
                app.RangeofvaluestoexcludePanel,'Color','none','YColor','none','XLim',[0,100],'YTick',[], ...
                'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','pixels','Position',[20,70,300,0] ...
                );
            app.axOver = axes( ...
                app.RangeofvaluestoexcludePanel_2,'Color','none','YColor','none','XLim',[0,100],'YTick',[], ...
                'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','pixels','Position',[20,70,300,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axSurf);
            disableDefaultInteractivity(app.axOver);

            app.axSurf.Toolbar.Visible = 'off';
            app.axOver.Toolbar.Visible = 'off';
            
            % Add the line ROI
            app.lSurf = images.roi.Line(app.axSurf,'Position',[0.9,0;1.1,0],'Color',[0.9 0.4 0.3]);%,'LineWidth',5,'MarkerSize',10);
            app.lOver = images.roi.Line(app.axOver,'Position',[0.9,0;1.1,0],'Color',[0.9 0.4 0.3]);%,'LineWidth',5,'MarkerSize',10);
            
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lSurf,'ROIMoved',@(varargin)lMoving(app,app.lSurf)); % MovingROI will provide continuous feedback
            addlistener(app.lSurf,'MovingROI',@(varargin)lMoving2(app,app.lSurf));
            
            addlistener(app.lOver,'ROIMoved',@(varargin)lMoving_2(app,app.lOver));
            addlistener(app.lOver,'MovingROI',@(varargin)lMoving2_2(app,app.lOver));
            app.lOver.Deletable = 0;
            
            app.sulcButton.BackgroundColor = [1 1 1];
            app.gyrButton.BackgroundColor = [1 1 1];
            
            [c,ia,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
            app.overlay.names{1} = app.overlayListBox.Items{ia};
            app.overlay.paths{1} = '';
            app.overlay.bm{1} = [];
            app.overlay.bmB{1} = [];
            app.overlay.settings{1} = [];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.brainSurferUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {490, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: LHRHMenu
        function loadfsaveragelhrh(app, event)
            [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);
            [vert2,face2] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, vert2, face2,'lights',app.dlights);
            
            app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
            app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
            app.sg.left = -1*(app.sg.left);
            app.sg.right = -1*(app.sg.right);
            
            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left, 'rh', app.sgB.right);
            tog = updateGUI(app,'underlay','true','false');
            
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
            importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
        end

        % Menu selected function: LHMenu
        function loadfsaveragelh(app, event)
            [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1,'lights',app.dlights);
            
            app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
            app.sg.left = -1*(app.sg.left);
            app.sgB.left = app.sg.left;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay,'lh', app.sgB.left);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
            importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
        end

        % Menu selected function: RHMenu
        function loadfsaveragerh(app, event)
            [vert2,face2] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert2, face2,'lights',app.dlights);
            
            app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
            app.sg.right = -1*(app.sg.right);
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay,'rh',app.sgB.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
            importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
        end

        % Value changed function: upperThreshBoxSurf
        function changeUpperThreshBoxSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.upperThreshBoxSurf.Value < app.lowerThreshBoxSurf.Value
                    t = app.upperThreshBoxSurf.Value;
                    app.upperThreshBoxSurf.Value = app.lowerThreshBoxSurf.Value;
                    app.lowerThreshBoxSurf.Value = t;
                end
                app.lSurf.Position = [app.lowerThreshBoxSurf.Value 0; app.upperThreshBoxSurf.Value 0];
                
                delete(app.upperThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.upperThreshBoxSurf.Value = 0;
            end
        end

        % Value changed function: lowerThreshBoxSurf
        function changeLowerThreshBoxSurf(app, event)
            
            if isgraphics(app.brainFig,'figure')
                if app.lowerThreshBoxSurf.Value > app.upperThreshBoxSurf.Value
                    t = app.upperThreshBoxSurf.Value;
                    app.upperThreshBoxSurf.Value = app.lowerThreshBoxSurf.Value;
                    app.lowerThreshBoxSurf.Value = t;
                end
                app.lSurf.Position = [app.lowerThreshBoxSurf.Value 0; app.upperThreshBoxSurf.Value 0];
                
                delete(app.lowerThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.lowerThreshBoxSurf.Value = 0;
            end
            
        end

        % Value changed function: limMaxSurf
        function limMaxSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(app.limMaxLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.limMaxLineSurf = plot(ones(size(l))*app.limMaxSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.limMaxSurf.Value = 0;
            end
        end

        % Value changed function: limMinSurf
        function limMinSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(app.limMinLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.limMinLineSurf = plot(ones(size(l))*app.limMinSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.limMinSurf.Value = 0;
            end
        end

        % Value changed function: clusterSlider
        function clustSliderChange(app, event)
            %app.clusterThreshBox.Value = app.clusterSlider.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                app.clusterThreshBox.Value = app.clusterSlider.Value;
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{ib}.clusterThreshBox = app.clusterThreshBox.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.clusterSlider.Value = app.clusterThreshBox.Value;
            end
        end

        % Value changed function: upperThreshBox
        function posBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                t2 = app.overlay.settings{ib}.lowerThreshBox;
                if app.upperThreshBox.Value < app.lowerThreshBox.Value
                    app.overlay.settings{ib}.lowerThreshBox = app.upperThreshBox.Value;
                    app.overlay.settings{ib}.upperThreshBox = t2;
                else
                    app.overlay.settings{ib}.upperThreshBox = app.upperThreshBox.Value;
                end
                
                app.lOver.Position = [app.overlay.settings{ib}.lowerThreshBox 0; app.overlay.settings{ib}.upperThreshBox 0];
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.upperThreshBox.Value = max(app.lOver.Position(:,1));
            end
        end

        % Value changed function: lowerThreshBox
        function negBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                t2 = app.overlay.settings{ib}.upperThreshBox;
                if app.lowerThreshBox.Value > app.upperThreshBox.Value
                    app.overlay.settings{ib}.upperThreshBox = app.lowerThreshBox.Value;
                    app.overlay.settings{ib}.lowerThreshBox = t2;
                else
                    app.overlay.settings{ib}.lowerThreshBox = app.lowerThreshBox.Value;
                end
                
                app.lOver.Position = [app.overlay.settings{ib}.lowerThreshBox 0; app.overlay.settings{ib}.upperThreshBox 0];
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.lowerThreshBox.Value = min(app.lOver.Position(:,1));
            end
        end

        % Value changed function: clusterThreshBox
        function clustChange(app, event)
            %app.clusterSlider.Value = app.clusterThreshBox.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.clusterThreshBox.Value > max(app.clusterSlider.Limits)
                    app.clusterSlider.Value = max(app.clusterSlider.Limits);
                else
                    app.clusterSlider.Value = app.clusterThreshBox.Value;
                end
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{ib}.clusterThreshBox = app.clusterThreshBox.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.clusterThreshBox.Value = app.clusterSlider.Value;
            end
        end

        % Menu selected function: LHMenu_7
        function addfsaveragelh(app, event)
            if isgraphics(app.brainFig,'figure')
                [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);
                
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
                
                app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
                app.sg.left = -1*(app.sg.left);
                app.sgB.left = app.sg.left;
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_7
        function addfsaveragerh(app, event)
            if isgraphics(app.brainFig,'figure')
                [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
                
                app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
                app.sg.right = -1*(app.sg.right);
                app.sgB.right = app.sg.right;
                
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHRHMenu_4
        function loadinflatedlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            
            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            
            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
            
            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHRHMenu_15
        function loadmidthicknesslhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            
            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            
            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
            
            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHMenu_15
        function loadmidthicknesslh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
            
            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: RHMenu_15
        function loadmidthicknessrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
            
            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHRHMenu_3
        function loadveryinflatedlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);
            
%             if strcmpi(newFig,'true')
%                app.atlas = struct('bm',struct('left','','right',''),'overlay',struct('left','','right',''),'cBar',struct('left','','right',''),'cBarText',struct('left','','right',''),'cBarAx',struct('left','','right',''),'regions',struct('left','','right',''),'clusters',struct('left','','right',''),'borders',struct('left','','right',''),'name',struct('left','','right',''),'path',struct('left','','right',''));
%             end
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            
            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            
            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHRHMenu_2
        function loadflatlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            
            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            
            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_12
        function loadflatlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            %app.underlay.left = app.underlay.right;
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_12
        function loadflatrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_13
        function loadveryinflatedlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_13
        function loadveryinflatedrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_14
        function loadinflatedlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            l = find(contains(c,'CORTEX_LEFT'));
            
            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_14
        function loadinflatedrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);
            
            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end
            
            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            
            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
            
            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};
            
            r = find(contains(c,'CORTEX_RIGHT'));
            
            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;
            
            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
            
            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_8
        function addflatlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                l = find(contains(c,'CORTEX_LEFT'));
                
                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
                app.sgB.left = app.sg.left;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_8
        function addflatrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_RIGHT'));
                
                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_9
        function addveryinflatedlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_LEFT'));
                
                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_9
        function addveryinflatedrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_RIGHT'));
                
                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_10
        function addinflatedlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_LEFT'));
                
                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_10
        function addinflatedrh(app, event)
            if isgraphics(app.brainFig,'figure')
                
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_RIGHT'));
                
                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_11
        function addmidthicknesslh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_LEFT'));
                
                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_11
        function addmidthicknessrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);
                
                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end
                
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);
                
                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);
                
                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};
                
                r = find(contains(c,'CORTEX_RIGHT'));
                
                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;
                
                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: AddfilesMenu_2
        function addSurface(app, event)
            % Ask for files
            [f,p,~] = uigetfile('*');
            
            % Check if any file was selected
            if p ~= 0
                [app,hemi] = idHemi(app,{f});
                
                if sum(cellfun(@isempty,hemi)) ~= length(f)
                    % now load
                    [~,n,e] = fileparts([p app.pths.sep f]);
                    
                    l = 'no';
                    switch e
                        case {'.gii'; '.gii.gz'}
                            g = gifti([p app.pths.sep f]);
                            vert = g.vertices;
                            face = g.faces;
                            l = 'yes';
                    end
                    
                    if strcmp(l,'no')
                        try
                            [vert,face] = read_surf([p app.pths.sep f]);
                        catch
                            uialert(app.brainSurferUIFigure,['File is not gifti and did not appear to be freesurfer compatible (' f ')'],'Unable to load File');
                            vert = [];
                            face = [];
                        end
                    end
                    if ~isempty(vert)
                        % remove any overlays that might exist...
                        if ~isempty(app.currOver)
                            for i = 1:length(app.currOver)
                                try
                                    delete(app.currOver{i})
                                catch
                                end
                            end
                            app.currOver = [];
                            app.overlayListBox.Value = app.overlayListBox.Items{1};
                        end
                        
                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert, face, 'add', hemi{1}, app.brainFig, app.underlay,'lights',app.dlights);
                        
                        sz = size(app.underlay.(hemi{1}).Vertices,1);
                        if sz == 163842
                            if strcmpi(hemi{1},'left')
                                f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                            elseif strcmpi(hemi{1},'right')
                                f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                            end
                            lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                        elseif sz == 32492
                            if strcmpi(hemi{1},'left')
                                f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                            elseif strcmpi(hemi{1},'right')
                                f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                            end
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                        importAtlas(app,f2,lbl);
                        
                        if strcmp(hemi{1},'left')
                            app.sg.left = [];
                            app.sgB.left = [];
                        elseif strcmp(hemi{1},'right')
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                    end
                else
                    uialert(app.brainSurferUIFigure,['Could not identify hemisphere of file and something about the filename caused the GUI for manual hemisphere selection not to trigger'],'Unable to load File');
                end
            end
        end

        % Menu selected function: LoadfilesMenu
        function loadSurface(app, event)
            % Ask for files
            [f,p,~] = uigetfile('*','MultiSelect','on'); % '*inflated*'; '*pial*'; '*white*' ; '*sphere*'; '*orig*'
            
            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end
                
                % Check for basic case where there are > 2 files
                if length(f) > 2
                    uialert(app.brainSurferUIFigure,['You cannot load more than two surface files at once'],'Too many files');
                end
                
                [app,hemi] = idHemi(app,f);
                if sum(cellfun(@isempty,hemi)) ~= length(f)
                    % For each file, identify hemis and load
                    for i = 1:length(f)
                        f1 = f{i};
                        id = strfind(lower(f1),'lr'); % some files will use the LR string, which we need to remove to check for l.
                        f1(id:id+1) = [];
                        
                        % now load
                        [~,n,e] = fileparts([p app.pths.sep f{i}]);
                        
                        l = 'no';
                        switch e
                            case {'.gii'; '.gii.gz'}
                                g = gifti([p app.pths.sep f{i}]);
                                vert{i} = g.vertices;
                                face{i} = g.faces;
                                l = 'yes';
                        end
                        if strcmp(l,'no')
                            try
                                [vert{i},face{i}] = read_surf([p app.pths.sep f{i}]);
                            catch
                                uialert(app.brainSurferUIFigure,['File is not gifti and did not appear to be freesurfer compatible (' f{i} ')'],'Unable to load File');
                                vert{i} = '';
                                face{i} = '';
                            end
                        end
                    end
                    
                    % now patch
                    id = find(cellfun('isempty',vert));
                    vert(id) = [];
                    face(id) = [];
                    hemi(id) = [];
                    f(id) = [];
                    
                    % remove any overlays that might exist...
                    if ~isempty(app.currOver)
                        for i = 1:length(app.currOver)
                            try
                                delete(app.currOver{i})
                            catch
                            end
                        end
                        app.currOver = [];
                        app.overlayListBox.Value = app.overlayListBox.Items{1};
                    end
                    
                    if length(f) == 2
                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert{1}, face{1}, vert{2}, face{2},'lights',app.dlights,'surfC',app.surfC);
                        if ~isempty(app.sgB.left)
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                        if ~isempty(app.sgB.right)
                            app.sg.right = [];
                            app.sgB.right = [];
                        end
                    elseif length(f) == 1
                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert{1}, face{1},'lights',app.dlights);
                        if strcmp(hemi,'left')
                            app.sg.left = [];
                            app.sgB.left = [];
                        elseif strcmp(hemi,'right')
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                    end
                    
                    if strcmp(hemi{1},'left')
                        app.sg.left = [];
                        app.sgB.left = [];
                    elseif strcmp(hemi{1},'right')
                        app.sg.left = [];
                        app.sgB.left = [];
                    end
                    
                else
                    uialert(app.brainSurferUIFigure,['Could not identify hemisphere of file(s) and something about the filename caused the GUI for manual hemisphere selection not to trigger'],'Unable to load File');
                end
                
                if length(f) == 1
                    if ~isempty(app.underlay.left.Vertices) & isempty(app.underlay.right.Vertices)
                        sz = size(app.underlay.left.Vertices,1);
                        if sz == 163842
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                            lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                        elseif sz == 32492
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                    elseif isempty(app.underlay.left.Vertices) & ~isempty(app.underlay.right.Vertices)
                        sz = size(app.underlay.right.Vertices,1);
                        if sz == 163842
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                            lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                            
                        elseif sz == 32492
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                    end
                elseif length(f) == 2
                    sz = size(app.underlay.left.Vertices,1);
                    if sz == 163842
                        f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                        lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                        
                    elseif sz == 32492
                        f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                        lbl = [];
                    else
                        f2 = [];
                        lbl = [];
                    end
                end
                importAtlas(app,f2,lbl);
            end
        end

        % Menu selected function: LoadsulcigyriMenu
        function loadsg(app, event)
            if isgraphics(app.brainFig,'figure')
                
                % Set up basic defaults in case this is the first user
                % action related to sulci/gyri...
                if strcmp(app.selectSpacingSurf.Value,'Select color spacing')
                    app.selectSpacingSurf.Value = 'even';
                end
                if strcmp(app.colormapSelectorSurf.Value,'Select colormap')
                    app.colormapSelectorSurf.Value = 'gray';
                end
                
                % Ask for a file
                [f,p,~] = uigetfile('*','MultiSelect','on');
                
                % Check if any file was selected
                if p ~= 0
                    if ~iscell(f) % if one file, it will not be in cell array
                        f = {f};
                    end
                    
                    if length(f) > 2
                        uialert(app.brainSurferUIFigure,['You cannot load more than two files at once because there are a maximum of two hemispheres'],'Too many files');
                    end
                    
                    % if two different brains ask which hemisphere to
                    % associated with file...
                    sc = 0;
                    if (isfield(app.underlay,'left') & isvalid(app.underlay.left) & ~isempty(app.underlay.left)) & (isfield(app.underlay,'right') & isvalid(app.underlay.right) & ~isempty(app.underlay.right))
                        if size(app.underlay.left.Vertices,1) ~= size(app.underlay.right.Vertices,1)
                            for i = 1:length(f)
                                tmp = uiconfirm(app.brainSurferUIFigure,['Looks like you have two surfaces with different shapes. Which hemisphere do you want to associate with this sulci/gyri file? ' f(i)],'Select hemisphere',...
                                    'Options',{'left','right','cancel'},...
                                    'DefaultOption',1,'CancelOption',3);
                                if ~strcmp(tmp,'cancel')
                                    hemi{i} = tmp;
                                    sc = 1;
                                else
                                    error('User cancelled action')
                                end
                            end
                        else
                            [app,hemi] = idHemi(app,f);
                        end
                    else
                        [app,hemi] = idHemi(app,f);
                    end
                    
                    % For each file, identify hemis and load
                    for i = 1:length(f)
                        f1 = f{i};
                        [~,~,e] = fileparts([p f{i}]);
                        
                        % if nifti, it can either be nifti format or cifti and
                        % cifti will not reference a hemisphere
                        if (strcmp(e,'.nii') | strcmp(e,'.nii.gz'))
                            try
                                s = cifti_read([p f{i}]);
                                c = cell2mat(s.diminfo{1}.models);
                                c = {c.struct};
                                if isfield(app.underlay,'left') & isvalid(app.underlay.left)
                                    if sc == 0 || (sc == 1 & strcmp(hemi{i},'left'))
                                        l = find(contains(c,'CORTEX_LEFT'));
                                        if isempty(l)
                                            uialert(app.brainSurferUIFigure,['File did not contain reference to left hemisphere sucli/gyri (' f{i} ')'],'Problem with file');
                                        end
                                        app.sg.left = zeros([size(app.underlay.left.Vertices,1),1]);
                                        app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
                                        app.sgB.left = app.sg.left;
                                    end
                                end
                                if isfield(app.underlay,'right') & isvalid(app.underlay.right)
                                    if sc == 0 || (sc == 1 & strcmp(hemi{i},'right'))
                                        r = find(contains(c,'CORTEX_RIGHT'));
                                        if isempty(r)
                                            uialert(app.brainSurferUIFigure,['File did not contain reference to right hemisphere sucli/gyri (' f{i} ')'],'Problem with file');
                                        end
                                        app.sg.right = zeros([size(app.underlay.right.Vertices,1),1]);
                                        app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                                        app.sgB.right = app.sg.right;
                                    end
                                end
                                
                                % tog goes here
                                updateGUI(app,'underlay','false');
                                [app] = sgPatch(app,'false');
                            catch
                                try
                                    s = load_nifti([p f{i}]);
                                    if ndims(s.vol) > 2
                                        uialert(app.brainSurferUIFigure,['Data for this file (' f{i} ') does not appear to be in surface space'],'Problem with file');
                                        error(['Data for this file (' f{i} ') does not appear to be in surface space']);
                                    end
                                    
                                    if strcmp(hemi{i},'left')
                                        app.sg.left = s.vol;
                                        app.sgB.left = app.sg.left;
                                    end
                                    if strcmp(hemi{i},'right')
                                        app.sg.right = s.vol;
                                        app.sgB.right = app.sg.right;
                                    end
                                    updateGUI(app,'underlay','false','false');
                                    [app] = sgPatch(app,'false');
                                catch
                                    uialert(app.brainSurferUIFigure,'Could not load your nifti file','Invalid File');
                                end
                            end
                        elseif strcmp(e,'.curv')
                            s = read_curv([p f{i}]);
                            % first id hemis
                            if strcmp(hemi{i},'left')
                                app.sg.left = s;
                                app.sgB.left = app.sg.left;
                            end
                            if strcmp(hemi{i},'right')
                                app.sg.right = s;
                                app.sgB.right = app.sg.right;
                            end
                            updateGUI(app,'underlay','false','false');
                            [app] = sgPatch(app,'false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: lightsButton
        function lightsButtonEffect(app, event)
            if isgraphics(app.brainFig,'figure')
                l = findobj(app.brainFig,'Type','Light');
                if isempty(l)
                    for i = 1:size(app.dlights,1)
                        camlight(app.dlights(i,1),app.dlights(i,2));
                    end
                else
                    delete(l)
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: reloadButton2
        function reloadUnderlay(app, event)
           if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
               if ~isempty(app.sgB.left)
                   app.sg.left = app.sgB.left;
                   [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
                   app.colormapSG.left = app.colormapSG_tmp.left;
                   app.ticksSG.left = app.ticksSG_tmp.left;
               end
               if ~isempty(app.sgB.right)
                   app.sg.right = app.sgB.right;
                   [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
                   app.colormapSG.right = app.colormapSG_tmp.right;
                   app.ticksSG.right = app.ticksSG_tmp.right;
               end
               tog = updateGUI(app,'underlay','true','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
           end
        end

        % Value changed function: valueSelectionSurf
        function changeValueSurf(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                if ~isempty(app.sgB.left) & ~isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left,'rh', app.sgB.right,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                elseif ~isempty(app.sgB.left) & isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay,'lh',app.sgB.left,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                    app.colormapSG.left = app.colormapSG_tmp.left;
                    app.ticksSG.left = app.ticksSG_tmp.left;
                    app.sg.left = app.sg_tmp;
                elseif ~isempty(app.sg.right) & isempty(app.sgB.left)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay,'rh',app.sgB.right,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                    app.colormapSG.right = app.colormapSG_tmp.right;
                    app.ticksSG.right = app.ticksSG_tmp.right;
                    app.sg.right = app.sg_tmp;
                end
                tog = updateGUI(app,'underlay','false','false');
                m = mean([min(tog) max(tog)]);
                app.lSurf.Position = [m 0; m 0];
                app.binSwitchSurf.Value = 'Off';
                app.sgThresh.Value = 0;
                app.lowerThreshBoxSurf.Value = m;
                app.upperThreshBoxSurf.Value = m;
                app.remZeroSwitchSurf.Value = 'On';
                app.negSwitchSurf.Value = 'On';
                app.posSwitchSurf.Value = 'On';
                
                hold(app.histSurf,'on')
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off')
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.valueSelectionSurf.Value = 'Raw';
            end
        end

        % Value changed function: posSwitchSurf
        function changePosSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.posSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: negSwitchSurf
        function changeNegSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.negSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: remZeroSwitchSurf
        function changeZeroSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.remZeroSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: binSwitchSurf
        function changeBinSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
                %[app] = sgPatch_and_smooth(app);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.binSwitchSurf.Value = 'Off';
            end
        end

        % Callback function
        function changeBinBoxSurf(app, event)
            
        end

        % Value changed function: colormapSelectorSurf
        function colormapSurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & ~strcmp(app.colormapSelectorSurf.Value,'Select colormap')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.colormapSelectorSurf.Value = 'Select colormap';
            end
        end

        % Value changed function: selectSpacingSurf
        function colorSpaceSurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & ~strcmp(app.selectSpacingSurf.Value,'Select color spacing')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.selectSpacingSurf.Value = 'Select color spacing';
            end
        end

        % Value changed function: binBoxSurf
        function binBoxSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.binBoxSurf.Value = 1000;
            end
        end

        % Value changed function: opacityBoxSurf
        function opacitySurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                if ~isempty(app.sgB.left)
                    app.underlay.left.FaceVertexAlphaData = ones([size(app.underlay.left.Vertices,1),1])*app.opacityBoxSurf.Value;
                    app.underlay.left.AlphaDataMapping = 'scaled';
                    app.underlay.left.FaceAlpha = 'interp';
                    app.underlay.left.AlphaDataMapping = 'none';
                end
                if  ~isempty(app.sgB.right)
                    app.underlay.right.FaceVertexAlphaData = ones([size(app.underlay.right.Vertices,1),1])*app.opacityBoxSurf.Value;
                    app.underlay.right.AlphaDataMapping = 'scaled';
                    app.underlay.right.FaceAlpha = 'interp';
                    app.underlay.right.AlphaDataMapping = 'none';
                end
            end
        end

        % Value changed function: invertSwitchSurf
        function invertColorSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.invertSwitchSurf.Value = 'Off';
            end
        end

        % Menu selected function: ChangedefaultsurfacecolorMenu
        function changeSurfC(app, event)
            surfCtmp = uisetcolor;
            app.surfC = surfCtmp;
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            end
        end

        % Value changed function: sgThresh
        function changeSgThresh(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    [app] = sgPatch(app,'false');
                else
                    app.sgThresh.Value = 0;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.sgThresh.Value = 0;
            end
        end

        % Button pushed function: sulcButton
        function changeSulcButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    ctmp = uisetcolor;
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.left.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.left.FaceVertexCData(id,3) = ctmp(3);
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.right.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.right.FaceVertexCData(id,3) = ctmp(3);
                    end
                    app.sulcButton.BackgroundColor = ctmp;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: gyrButton
        function changeGyriButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    ctmp = uisetcolor;
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.left.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.left.FaceVertexCData(id,3) = ctmp(3);
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.right.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.right.FaceVertexCData(id,3) = ctmp(3);
                    end
                    app.gyrButton.BackgroundColor = ctmp;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Callback function
        function changeSgColormap(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.opacityBoxSG.Value = 1;
            end
        end

        % Menu selected function: 
        % MakedefaultsulcalandgyralcolorsMenu_2
        function defaultSGColors(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = 0.4;
                        app.underlay.left.FaceVertexCData(id,2) = 0.4;
                        app.underlay.left.FaceVertexCData(id,3) = 0.4;
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = 0.4;
                        app.underlay.right.FaceVertexCData(id,2) = 0.4;
                        app.underlay.right.FaceVertexCData(id,3) = 0.4;
                    end
                end
                if strcmp(app.binSwitchSurf.Value,'On')
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = 0.8;
                        app.underlay.left.FaceVertexCData(id,2) = 0.8;
                        app.underlay.left.FaceVertexCData(id,3) = 0.8;
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = 0.8;
                        app.underlay.right.FaceVertexCData(id,2) = 0.8;
                        app.underlay.right.FaceVertexCData(id,3) = 0.8;
                    end
                end
                app.gyrButton.BackgroundColor  = [0.4 0.4 0.4];
                app.sulcButton.BackgroundColor = [0.8 0.8 0.8];
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: InvertsulcalandgyralcolorsMenu_2
        function invertSGColors(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    s = app.sulcButton.BackgroundColor;
                    g = app.gyrButton.BackgroundColor;
                    
                    if ~isempty(app.sgB.left)
                        id1 = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        id2 = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        
                        app.underlay.left.FaceVertexCData(id1,1) = g(1);
                        app.underlay.left.FaceVertexCData(id1,2) = g(1);
                        app.underlay.left.FaceVertexCData(id1,3) = g(1);
                        
                        app.underlay.left.FaceVertexCData(id2,1) = s(1);
                        app.underlay.left.FaceVertexCData(id2,2) = s(1);
                        app.underlay.left.FaceVertexCData(id2,3) = s(1);
                    end
                    if ~isempty(app.sgB.right)
                        id1 = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        id2 = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        
                        app.underlay.right.FaceVertexCData(id1,1) = g(1);
                        app.underlay.right.FaceVertexCData(id1,2) = g(1);
                        app.underlay.right.FaceVertexCData(id1,3) = g(1);
                        
                        app.underlay.right.FaceVertexCData(id2,1) = s(1);
                        app.underlay.right.FaceVertexCData(id2,2) = s(1);
                        app.underlay.right.FaceVertexCData(id2,3) = s(1);
                    end
                    app.gyrButton.BackgroundColor = s;
                    app.sulcButton.BackgroundColor = g;
                end
            end
            
        end

        % Menu selected function: DarkModeMenu_3
        function darkMode(app, event)
            props = properties(app);
            for i = 1:length(props)
                thisprop = props{i};
                if ~strcmp(thisprop,'NooverlayPanel')
                    %%%Add logic here if you want to work with select properties
                    if isprop(app.(thisprop),'BackgroundColor') == 1
                        app.(thisprop).BackgroundColor = [0.27 0.27 0.27];
                    end
                    if isprop(app.(thisprop),'ForegroundColor') == 1
                        if ~contains(lower(thisprop),'menu')
                            app.(thisprop).ForegroundColor = [0.94,0.94,0.94];
                        end
                    end
                    if isprop(app.(thisprop),'FontColor') == 1
                        %if ~strfind(thisprop,'menu')
                        app.(thisprop).FontColor = [0.94,0.94,0.94];
                        %end
                    end
                else
                    app.(thisprop).BackgroundColor = [0.4 0.4 0.4];
                    app.(thisprop).ForegroundColor = [0.94,0.94,0.94];
                end
            end
            app.axSurf.XAxis.Color = [1 1 1];
            app.axOver.XAxis.Color = [1 1 1];
            app.histSurf.XColor = [1 1 1];
            app.histSurf.YColor = [1 1 1];
            app.histSurf.Color = [0.4 0.4 0.4];
            set(app.brainFig,'color','k');
            %app.histSurf.ForegroundColor = [0.4 0.4 0.4];
            %app.histOver.BackgroundColor = [0.4 0.4 0.4];
        end

        % Menu selected function: LightModeMenu_3
        function lightMode(app, event)
            props = properties(app);
            for i = 1:length(props)
                thisprop = props{i};
                if ~strcmp(thisprop,'NooverlayPanel') || ~strcmp(thisprop,'lowerThreshBoxSurf') || ~strcmp(thisprop,'upperThreshBoxSurf') ||  ~strcmp(thisprop,'sgThresh') || ~strcmp(thisprop,'valueSelectionSurf') || ~strcmp(thisprop,'sulcButton') || ~strcmp(thisprop,'gyrButton') || ~strcmp(thisprop,'limMinSurf') || ~strcmp(thisprop,'limMaxSurf') || ~strcmp(thisprop,'binBoxSurf')|| ~strcmp(thisprop,'opacityBoxSurf')|| ~strcmp(thisprop,'smoothAreaUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')
                    %%%Add logic here if you want to work with select properties
                    if isprop(app.(thisprop),'BackgroundColor') == 1
                        app.(thisprop).BackgroundColor = [0.94,0.94,0.94];
                    end
                    if isprop(app.(thisprop),'ForegroundColor') == 1
                        if ~contains(lower(thisprop),'menu')
                            app.(thisprop).ForegroundColor = [0 0 0];
                        end
                    end
                    if isprop(app.(thisprop),'FontColor') == 1
                        %if ~strfind(thisprop,'menu')
                        app.(thisprop).FontColor = [0 0 0];
                        %end
                    end
                else
                    app.(thisprop).BackgroundColor = [1 1 1];
                    app.(thisprop).ForegroundColor = [0,0,0];
                end
            end
            app.axSurf.XAxis.Color = [0 0 0];
            app.axOver.XAxis.Color = [0 0 0];
            app.histSurf.XColor = [0 0 0];
            app.histSurf.YColor = [0 0 0];
            app.histSurf.Color = [0.94,0.94,0.94];
            set(app.brainFig,'color','w');
        end

        % Menu selected function: ChangebackgroundofbrainfigureMenu
        function changeBackBrain(app, event)
            tmpC = uisetcolor;
            set(app.brainFig,'color',tmpC);
        end

        % Value changed function: opacityBoxSG
        function changeOpacitySG(app, event)
            %value = app.opacityBoxSG.Value;
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.opacityBoxSG.Value = 1;
            end
        end

        % Value changed function: leftHemiToggle
        function changeDisplayLH(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.leftHemiToggle.Value == 1
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                    end
                elseif app.leftHemiToggle.Value == 0
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: rightHemiToggle
        function changeDisplayRH(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.rightHemiToggle.Value == 1
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                    end
                elseif app.rightHemiToggle.Value == 0
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: smoothButtonSurf
        function smoothButtonSurfPress(app, event)
            if isgraphics(app.brainFig,'figure')
                a = app.smoothAreaUnder.Value;
                s = app.smoothStepsUnder.Value;
                sb = app.smoothButtonSurf.Value;
                if app.smoothButtonSurf.Value == 0
                    app.sg.left = app.sgB.left;
                    app.sg.right = app.sgB.right;
                    
                    %tog = updateGUI(app,'underlay','true','false');
                end
                
                [app] = sgPatch(app,'true');
                tog = updateGUI(app,'underlay','true','true');
                
                app.smoothAreaUnder.Value = a;
                app.smoothStepsUnder.Value = s;
                app.smoothButtonSurf.Value = sb;
            else
                %app.smoothButtonSurf.Value = 0;
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end

        end

        % Menu selected function: LoadoverlaysMenu
        function loadOverlay(app, event)
            
            % Ask for files
            [f,p,~] = uigetfile('*','MultiSelect','on');
            oP = p;
            
            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end
                
                % load files now...
                for i = 1:length(f)
                    [~,n,e] = fileparts([p app.pths.sep f{i}]);
                    disp(['Trying to load file: ' f{i}])
                    
                    % now load
                    ci = 0;
                    try % try to load a regular nifti file
                        disp('Attempting to load as conventional nifti file...')
                        ld = load_nifti([p n e]);
                        sz = size(ld.vol);
                        d = ld.vol;
                        d2 = [];
                    catch
                        disp('NIFTI LOAD FAILED!')
                        try % if that did not work, load a gifti file
                            disp('Attempting to load as gifti file...')
                            ld = gifti([p n e]);
                            if isfield(ld,'cdata')
                                sz = size(ld.cdata);
                                d = ld.cdata;
                                d2 = [];
                            else
                                uialert(app.brainSurferUIFigure,['Could not find cdata structure (' f{i} ')'],'Unable to load File');
                                sz = [];
                                d = [];
                                d2 = [];
                            end
                        catch
                            disp('GIFTI LOAD FAILED!')
                            try % if gifti did not work, try cifti
                                disp('Attempting to load as cifti file...')
                                ld = cifti_read([p n e]);
                                
                                c = cell2mat(ld.diminfo{1}.models);
                                c = {c.struct};
                                
                                l = find(contains(c,'CORTEX_LEFT'));
                                r = find(contains(c,'CORTEX_RIGHT'));
                                
                                d = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                d2 = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                                
                                d(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                d2(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                
                                sz = size(d);
                                ci = 1;
                            catch
                                disp('CIFTI LOAD FAILED!')
                                disp('Uh-oh...your file could not be loaded as nifti, gifti, or cifti')
                            end
                        end
                    end
                    
                    % check if data size is 3D...if it is, ask if user
                    % wants to import volume to surface
                    if sz(2) > 1
                        im = uiconfirm(app.brainSurferUIFigure,['Looks like this particular file was in volume space (' n e '). How do you want to convert it to surface space (fsaverage)?'],'Conversion options','Options',{'Thresholded map','Contains one or more ROIs','Unthresholded map','Cancel'},'DefaultOption',3);
                        switch im
                            case 'Unthresholded map'
                                tmpOut = convertMNI2FS([p n e],[]);
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                                d = ld.vol;
                                d2 = ld2.vol;
                                
                                hemi = 'left';
                                hemi2 = 'right';
                            case 'Thresholded map'
                                smoothing = inputdlg({'Smoothing area', 'Smoothing steps'});
                                if isempty(smoothing{1})
                                    smoothing{1} = '0';
                                end
                                if isempty(smoothing{2})
                                    smoothing{2} = '0';
                                end
                                
                                tmpOut = convertMNI2FS([p n e],str2double(smoothing{1}), str2double(smoothing{2}),p);
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                                d = ld.vol;
                                d2 = ld2.vol;
                                
                                hemi = 'left';
                                hemi2 = 'right';
                            case 'Contains one or more ROIs'
                                weight = inputdlg('Enter a weight vector for ROIs or leave blank for default no weighting option (1s given preference over 0s ; assuming 1s and 0s are in order of ROI values in map)','Define ROI weights');
                                if strcmp(weight{1},'[]') || isempty(weight)
                                    weight = [];
                                else
                                    weight = str2double(weight);
                                end
                                
                                try
                                    tmpOut = convertROIForFS([p n e], weight{1});
                                catch
                                    tmpOut = convertROIForFS([p n e], []);
                                end
                                
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                                d = ld.vol;
                                d2 = ld2.vol;
                                
                                hemi = 'left';
                                hemi2 = 'right';
                        end
                    else
                        if ci == 1
                            hemi = 'left';
                            hemi2 = 'right';
                        else
                            [app,hemi] = idHemi(app,{[p f{i}]});
                            hemi = hemi{1};
                            hemi2 = [];
                        end
                    end
                    
                    if ~isempty(hemi2)
                        if ~ci
                            [p, n, e] = fileparts(tmpOut{1});
                            nm = [n e];
                        elseif ci
                            nm = [n e ' (LEFT HEMI)'];
                        end
                        %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                        if app.nmL < length(nm)
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                            tmpNm = nm(1:app.nmL);
                        else
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm);
                            tmpNm = nm;
                        end
                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);
                        
                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));
                            
                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d);
                        app.overlay.names = horzcat(app.overlay.names,[n e]);
                        app.overlay.paths = horzcat(app.overlay.paths,p);
                        app.overlay.settings{end}.hemi = hemi;
                        app.overlay.settings{end}.limMinOver = min(d);
                        app.overlay.settings{end}.limMaxOver = max(d);
                        
                        if ~ci
                            [p, n, e] = fileparts(tmpOut{2});
                            nm = [n e];
                        elseif ci
                            nm = [n e ' (RIGHT HEMI)'];
                        end
                        
                        %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                        if app.nmL < length(nm)
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                            tmpNm = nm(1:app.nmL);
                        else
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm);
                            tmpNm = nm;
                        end
                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);
                        
                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));
                            
                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d2);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d2);
                        app.overlay.names = horzcat(app.overlay.names,[n e]);
                        app.overlay.paths = horzcat(app.overlay.paths,p);
                        app.overlay.settings{end}.hemi = hemi2;
                        app.overlay.settings{end}.limMinOver = min(d2);
                        app.overlay.settings{end}.limMaxOver = max(d2);
                    else
                        if app.nmL < length(f{i})
                            tmpNm = f{i}(1:app.nmL);
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,f{i}(1:app.nmL));
                        else
                            tmpNm = f{i};
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,f{i});
                        end
                        
                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);
                        
                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));
                            
                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d);
                        app.overlay.names = horzcat(app.overlay.names,f{i});
                        app.overlay.paths = horzcat(app.overlay.paths,oP);
                        app.overlay.settings{end}.hemi = hemi;
                        app.overlay.settings{end}.limMinOver = min(d);
                        app.overlay.settings{end}.limMaxOver = max(d);
                    end
                end
            end
        end

        % Value changed function: overlayListBox
        function overlaySelected(app, event)
            if isgraphics(app.brainFig,'figure')
                
                % remove no overlay only if there are multiple
                % selections....
                if length(app.overlayListBox.Value) > 1
                    [~,ia,~] = intersect(app.overlayListBox.Value,app.overlayListBox.Items(1));
                    if ~isempty(ia)
                        app.overlayListBox.Value(ia) = [];
                    end
                end
                
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                
                % check for overlays that do not match the shape of
                % underlays
                toR = [];
                toRN = [];
                
                %if length(app.overlayListBox.Value) > 1
                if si ~= 1
                    for i = 1:length(si)
                        if length(app.overlay.bm{si(i)}) ~= size(app.underlay.(app.overlay.settings{si(i)}.hemi).Vertices,1)
                            toR = vertcat(toR,si(i));
                            toRN = vertcat(toRN,s(i));
                        end
                    end
                end
                %end
                
                if ~isempty(toR)
                    uialert(app.brainSurferUIFigure,[sprintf('The following overlays have a number of vertices that do not match the underlay you are trying to plot them on so they will not be patched: \n') strjoin(toRN,'\n')],'Incompatible overlay');
                    app.overlayListBox.Value = app.overlayListBox.Items(setdiff(si,toR));
                    [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                    if isempty(s)
                        app.overlayListBox.Value = app.overlayListBox.Items{1};
                    end
                end
                
                if isfield(app.underlay,'left')
                    if strcmpi(app.underlay.left.Visible,'off')
                        app.underlay.left.Visible = 'on';
                        app.leftHemiToggle.Value = 0;
                    end
                end
                if isfield(app.underlay,'right')
                    if strcmpi(app.underlay.right.Visible,'off')
                        app.underlay.right.Visible = 'on';
                        app.rightHemiToggle.Value = 0;
                    end
                end
                
                [app] = overPatch(app,'false','false','true');
                
            else
                app.overlayListBox.Value = 'No overlay';
                uialert(app.brainSurferUIFigure,'You cannot patch an overlay unless a surface is loaded','Action unavailable');
            end
            %app.overlayListBox.Value;
            
        end

        % Value changed function: pThreshBox
        function pThreshBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.pThresh = app.pThreshBox.Value;
                if ~isempty(app.overlay.settings{si}.pMap)
                    app = overPatch(app,'false','false','false');
                else
                    uialert(app.brainSurferUIFigure,'Looks like this map does not have p-values loaded in','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.pThreshBox.Value = 1;
            end
        end

        % Value changed function: valueSelection
        function valueSelectionChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.valueSelection = app.valueSelection.Value;
                app.overlay.settings{si}.lowerThreshBox = 0;
                app.overlay.settings{si}.upperThreshBox = 0;
                app.overlay.bm{si} = app.overlay.bmB{si};
                % change threshold axis
                app = overPatch(app,'false','true','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.valueSelection.Value = 'Raw';
            end
        end

        % Value changed function: posSwitch
        function posSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.posSwitch = app.posSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.posSwitch.Value = 'On';
            end
        end

        % Value changed function: negSwitch
        function negSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.negSwitch = app.negSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.negSwitch.Value = 'On';
            end
        end

        % Value changed function: remZeroSwitch
        function remZeroSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.remZeroSwitch = app.remZeroSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.remZeroSwitch.Value = 'Off';
            end
        end

        % Value changed function: binSwitch
        function binSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.binSwitch = app.binSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.binSwitch.Value = 'Off';
            end
        end

        % Value changed function: addpButton
        function addpButtonChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                %app.addpButton.BackgroundColor = [0 0 0];
                %app.addpButton.ForegroundColor = [0.94 0.94 0.94];
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.addpButton.Value == 1
                    
                    % load input map...
                    [f,p,~] = uigetfile('*','MultiSelect','off');
                    [~,n,e] = fileparts([p app.pths.sep f]);
                    try % try to load a regular nifti file
                        disp('Attempting to load as conventional nifti file...')
                        ld = load_nifti([p n e]);
                        sz = size(ld.vol);
                        app.overlay.settings{si}.pMap = ld.vol;
                    catch
                        disp('NIFTI LOAD FAILED!')
                        try % if that did not work, load a gifti file
                            disp('Attempting to load as gifti file...')
                            ld = gifti([p n e]);
                            if isfield(ld,'cdata')
                                sz = size(ld.cdata);
                                app.overlay.settings{si}.pMap = ld.cdata;
                            else
                                uialert(app.brainSurferUIFigure,['Could not find cdata structure (' f ')'],'Unable to load File');
                                sz = [];
                                app.overlay.settings{si}.pMap = [];
                            end
                        catch
                            disp('GIFTI LOAD FAILED!')
                            try % if gifti did not work, try cifti
                                disp('Attempting to load as cifti file...')
                                ld = cifti_read([p n e]);
                                
                                c = cell2mat(ld.diminfo{1}.models);
                                c = {c.struct};
                                
                                l = find(contains(c,'CORTEX_LEFT'));
                                r = find(contains(c,'CORTEX_RIGHT'));
                                
                                d = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                d2 = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                                
                                d(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                d2(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                
                                sz = size(d);
                                
                                if strcmpi(app.overlay.hemi{si},'left')
                                    app.overlay.settings{si}.pMap = d;
                                elseif strcmpi(app.overlay.hemi{si},'right')
                                    app.overlay.settings{si}.pMap = d2;
                                end
                            catch
                                disp('CIFTI LOAD FAILED!')
                                disp('Uh-oh...your file could not be loaded as nifti, gifti, or cifti')
                                %app.addpButton.ForegroundColor = [0 0 0];
                                %app.addpButton.BackgroundColor = [0.94 0.94 0.94];
                            end
                        end
                    end
                    
                    
                    % check if data size is 3D...if it is, ask if user
                    % wants to import volume to surface
                    if sz(2) > 1
                        im = uiconfirm(app.brainSurferUIFigure,['Looks like this particular file was in volume space (' n e '). How do you want to convert it to surface space (fsaverage)?'],'Conversion options','Options',{'Thresholded map','Contains one or more ROIs','Unthresholded map','Cancel'},'DefaultOption',3);
                        switch im
                            case 'Unthresholded map'
                                tmpOut = convertMNI2FS([p n e],[]);
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                            case 'Thresholded map'
                                smoothing = inputdlg({'Smoothing area', 'Smoothing steps'});
                                if isempty(smoothing{1})
                                    smoothing{1} = '0';
                                end
                                if isempty(smoothing{2})
                                    smoothing{2} = '0';
                                end
                                
                                tmpOut = convertMNI2FS([p n e],str2double(smoothing{1}), str2double(smoothing{2}),p);
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                                app.overlay.settings{si}.pMap = ld.vol;
                                
                            case 'Contains one or more ROIs'
                                weight = inputdlg('Enter a weight vector for ROIs or leave blank for default no weighting option (1s given preference over 0s ; assuming 1s and 0s are in order of ROI values in map)','Define ROI weights');
                                if strcmp(weight{1},'[]') || isempty(weight)
                                    weight = [];
                                else
                                    weight = str2double(weight);
                                end
                                
                                try
                                    tmpOut = convertROIForFS([p n e], weight{1});
                                catch
                                    tmpOut = convertROIForFS([p n e], []);
                                end
                                
                                ld = load_nifti(tmpOut{1});
                                ld2 = load_nifti(tmpOut{2});
                                
                                app.overlay.settings{si}.pMap = ld.vol;
                        end
                        if strcmpi(app.overlay.hemi{si},'left')
                            app.overlay.settings{si}.pMap = ld.vol;
                        elseif strcmpi(app.overlay.hemi{si},'right')
                            app.overlay.settings{si}.pMap = ld2.vol;
                        end
                    end
                    app.overlay.settings{si}.pMapB = app.overlay.settings{si}.pMap;
                    app.overlay.settings{si}.pThresh = 1;
                    app.pThreshBox.Value = 1;
                else
                    app.overlay.settings{si}.pMap = app.overlay.settings{si}.pMapB;
                    app = overPatch(app,'false','false','false');
                end
                %app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                %app.addpButton.BackgroundColor = [0.94 0.94 0.94];
                %app.addpButton.ForegroundColor = [0 0 0];
            end
        end

        % Button pushed function: reloadButton, reloadOver
        function reloadOverlay(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.bm{si} = app.overlay.bmB{si};
                tmp = app.overlay.settings{si}.hemi;
                app.overlay.settings{si} = app.defaultSetOver;
                app.overlay.settings{si}.hemi = tmp;
                app.overlay.settings{si}.limMinOver = min(app.overlay.bm{si});
                app.overlay.settings{si}.limMaxOver = max(app.overlay.bm{si});
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
            end
        end

        % Value changed function: limMinOver
        function limMinChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.limMaxOver.Value < app.limMinOver.Value
                    tmp1 = app.limMaxOver.Value;
                    app.limMaxOver.Value = app.limMinOver.Value;
                    app.limMinOver.Value = tmp1;
                end
                
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.limMinOver = app.limMinOver.Value;
                app.overlay.settings{si}.limMaxOver = app.limMaxOver.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.limMinOver.Value = 0;
            end
        end

        % Value changed function: limMaxOver
        function limMaxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.limMaxOver.Value < app.limMinOver.Value
                    tmp1 = app.limMaxOver.Value;
                    app.limMaxOver.Value = app.limMinOver.Value;
                    app.limMinOver.Value = tmp1;
                end
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.limMaxOver = app.limMaxOver.Value;
                app.overlay.settings{si}.limMinOver = app.limMinOver.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.limMaxOver.Value = 0;
            end
        end

        % Value changed function: colormapSelector
        function colormapSelectorChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.colormapSelector = app.colormapSelector.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.colormapSelector.Value = 'Select colormap';
            end
        end

        % Value changed function: selectSpacing
        function selectSpacingChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.selectSpacing = app.selectSpacing.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.selectSpacing.Value = 'Select color spacing';
            end
        end

        % Value changed function: binBox
        function binBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.binBox = app.binBox.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.binBox.Value = 1000;
            end
        end

        % Value changed function: opacityBox
        function opacityBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.opacityBox = app.opacityBox.Value;
                
                % make if statement here--if you have transparency
                % modulation on, then make a temporary variable for
                % FaceVertexAlphaData equal whatever it is right now...if
                % not, make it equal 1...then proceed with the change in
                % alpha data (i.e. insert this into the for loop)
                
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        app.currOver{i}.FaceVertexAlphaData = ones(size(app.currOver{i}.FaceVertexAlphaData))*app.overlay.settings{si}.opacityBox;
                    end
                end

                %app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.opacityBox.Value = 1;
            end
        end

        % Value changed function: invertSwitch
        function invertSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.invertSwitch = app.invertSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.invertSwitch.Value = 'Off';
            end
        end

        % Value changed function: outlineDropDown
        function outlineDropDownChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.outlineDropDown = app.outlineDropDown.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.outlineDropDown.Value = 'None';
            end
        end

        % Value changed function: growShrinkSlider
        function growShrinkSliderChange(app, event)
            app.growShrinkSlider.Value = round(app.growShrinkSlider.Value);
            app.growShrinkBox.Value = app.growShrinkSlider.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.growShrinkBox = app.growShrinkBox.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.growShrinkSlider.Value = 0;
                app.growShrinkBox.Value = 0;
            end
        end

        % Value changed function: growShrinkBox
        function growShrinkBoxChange(app, event)
            app.growShrinkBox.Value = round(app.growShrinkBox.Value);
            
            if app.growShrinkBox.Value > max(app.growShrinkSlider.Limits)
                app.growShrinkSlider.Value = max(app.growShrinkSlider.Limits);
            elseif app.growShrinkBox.Value < min(app.growShrinkSlider.Limits)
                app.growShrinkSlider.Value = min(app.growShrinkSlider.Limits);
            else
                app.growShrinkSlider.Value = app.growShrinkBox.Value;
            end
            
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.growShrinkBox = app.growShrinkBox.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.growShrinkSlider.Value = 0;
                app.growShrinkBox.Value = 0;
            end
        end

        % Menu selected function: ShowMenu_4
        function showColorbar(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                % get selections
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                for fi = 1:length(si)
                    app.cBar{fi}.Visible = 'on';
                    app.cBarAx{fi}.Visible = 'on';
                    app.cBarText{fi}.Visible = 'on';
                end
            end
        end

        % Menu selected function: HideMenu_4
        function hideColorbar(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                % get selections
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                for fi = 1:length(si)
                    app.cBar{fi}.Visible = 'off';
                    app.cBarAx{fi}.Visible = 'off';
                    app.cBarText{fi}.Visible = 'off';
                end
            end
        end

        % Menu selected function: HorizontalMenu
        function makeColorbarHoriz(app, event)
            if strcmpi(app.cBarDir,'vert')
                app.cBarDir = 'horiz';
                if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    %tol = 1:length(app.brainFig.Children)-1;
                    %for i = 1:length(tol)
                    len = length(app.cBarAx);
                    for i = 1:len
                        if length(app.brainFig.Children) > 1
                            pos = get(app.brainFig.Children(end),'Position');
                        else
                            pos = get(app.brainFig.Children,'Position');
                        end
                        
                        stripe = (0.075)/1.7;
                        space = .1;
                        offs = 0.07;
                        p2 = (pos(2) - offs);
                        rect = [pos(1) (p2 + app.cBarSpacing*(len-1)) pos(3) stripe*pos(4)];
                        rect(2) = rect(2) - app.cBarSpacing*(i-1);
                        cb = permute(app.cBar{i}.CData,[2 1 3]);
                        tlab = 'XTickLabels';
                        tr = 'ytick';
                        ticks.labels = app.cBar{i}.Parent.YTickLabel;
                        transp.map = app.cBar{i}.AlphaData';
                        if len > 1
                            tmpNm = app.cBarText{i}.String;
                        end
                        delete(app.cBarAx{i})
                        delete(app.cBar{i})
                        
                        app.cBarAx{i} = axes('Position', rect);
                        app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                        
                        set(app.cBarAx{i},[tr 'labelmode'],'manual')
                        set(app.cBarAx{i},[tr 'label'],[])
                        set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                        app.cBar{i}.Parent.TickLength = [0 0];
                        app.cBar{i}.Parent.(tlab) = ticks.labels;
                        app.cBar{i}.Parent.YTickLabel = [];
                        
                        if len > 1
                            delete(app.cBarText{i});
                            %app.cBarText{i} = text(app.cBarAx{i},app.cBarAx{i}.Position(1)+0.2,app.cBarAx{i}.Position(2)+0.3,tmpNm);
                            app.cBarText{i} = text(app.cBarAx{i},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                        end
                    end
                end
            end
        end

        % Menu selected function: VerticalMenu
        function makeColorbarVert(app, event)
            if strcmpi(app.cBarDir,'horiz')
                app.cBarDir = 'vert';
                if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    len = length(app.cBarAx);
                    for i = 1:len
                        if length(app.brainFig.Children) > 1
                            pos = get(app.brainFig.Children(end),'Position');
                        else
                            pos = get(app.brainFig.Children,'Position');
                        end
                        
                        stripe = 0.04;
                        edge = 0.01;
                        space = 0.03;
                        p1 = (pos(1)+pos(3)+space);
                        rect = [(p1 - app.cBarSpacing*(len-1)) pos(2) stripe*pos(3) pos(4)];
                        rect(1) = rect(1) + app.cBarSpacing*(i-1);
                        cb = permute(app.cBar{i}.CData,[2 1 3]);
                        tlab = 'YTickLabels';
                        tr = 'xtick';
                        
                        ticks.labels = app.cBar{i}.Parent.XTickLabel;
                        transp.map = app.cBar{i}.AlphaData';
                        if len > 1
                            tmpNm = app.cBarText{i}.String;
                        end
                        delete(app.cBarAx{i})
                        delete(app.cBar{i})
                        
                        app.cBarAx{i} = axes('Position', rect);
                        app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                        
                        set(app.cBarAx{i},[tr 'labelmode'],'manual')
                        set(app.cBarAx{i},[tr 'label'],[])
                        set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                        app.cBar{i}.Parent.TickLength = [0 0];
                        app.cBar{i}.Parent.(tlab) = ticks.labels;
                        
                        if len > 1
                            delete(app.cBarText{i});
%                             app.cBarText{i} = text(app.cBarAx{i},app.cBarAx{i}.Position(1)-0.3,app.cBarAx{i}.Position(2)-0.01,tmpNm);
%                             set(app.cBarText{i},'Rotation',90);
                            app.cBarText{i} = text(app.cBarAx{i},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                        end
                    end
                end
            end
        end

        % Menu selected function: ChangespacingbetweencolorbarsMenu
        function changeSpacingBetweenColorbars(app, event)
            tmp = inputdlg('What should the spacing between multiple colorbars be?','Spacing value',[1],{'0.065'});
            if ~isempty(tmp)
                app.cBarSpacing = str2double(tmp{1});
            end
            if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                len = length(app.cBarAx);
                for i = 1:len
                    if length(app.brainFig.Children) > 1
                        pos = get(app.brainFig.Children(end),'Position');
                    else
                        pos = get(app.brainFig.Children,'Position');
                    end
                    if  strcmpi(app.cBarDir,'vert')
                        stripe = 0.04;
                        edge = 0.01;
                        space = 0.03;
                        p1 = (pos(1)+pos(3)+space);
                        rect = [(p1 - app.cBarSpacing*(len-1)) pos(2) stripe*pos(3) pos(4)];
                        rect(1) = rect(1) + app.cBarSpacing*(i-1);
                        
                        tlab = 'YTickLabels';
                        tr = 'xtick';
                        
                        ticks.labels = app.cBar{i}.Parent.XTickLabel;
                    elseif strcmpi(app.cBarDir,'horiz')
                        stripe = (0.075)/1.7;
                        space = .1;
                        offs = 0.07;
                        p2 = (pos(2) - offs);
                        rect = [pos(1) (p2 + app.cBarSpacing*(len-1)) pos(3) stripe*pos(4)];
                        rect(2) = rect(2) - app.cBarSpacing*(i-1);
                        
                        tlab = 'XTickLabels';
                        tr = 'ytick';
                        
                        ticks.labels = app.cBar{i}.Parent.YTickLabel;
                    end
                    
                    cb = app.cBar{i}.CData;
                    transp.map = app.cBar{i}.AlphaData;
                    
                    if len > 1
                        tmpNm = app.cBarText{i}.String;
                    end
                    delete(app.cBarAx{i})
                    delete(app.cBar{i})
                    
                    app.cBarAx{i} = axes('Position', rect);
                    app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);
                    
                    set(app.cBarAx{i},[tr 'labelmode'],'manual')
                    set(app.cBarAx{i},[tr 'label'],[])
                    set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                    app.cBar{i}.Parent.TickLength = [0 0];
                    app.cBar{i}.Parent.(tlab) = ticks.labels;
                    
                    if len > 1
                        delete(app.cBarText{i});
                        if strcmpi(app.cBarDir,'horiz')
                            app.cBarText{i} = text(app.cBarAx{i},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                        else
                            app.cBarText{i} = text(app.cBarAx{i},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                        end
                    end
                end
            end
        end

        % Menu selected function: HideMenu_5
        function hideMapTitles(app, event)
            for i = 1:length(app.cBarText)
                app.cBarText{i}.Visible = 'off';
            end
        end

        % Menu selected function: ShowMenu_5
        function showMapTitles(app, event)
            for i = 1: length(app.cBarText)
                app.cBarText{i}.Visible = 'on';
            end
        end

        % Menu selected function: LeftlateralMenu, 
        % ViewleftlateralMenu
        function viewLateralLeft(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[-90 0])
            end
        end

        % Menu selected function: RightlateralMenu, 
        % ViewrightlateralMenu
        function viewLateralRight(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[90 0])
            end
        end

        % Menu selected function: InferiorMenu_3, ViewinferiorMenu
        function viewInferior(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                %view(tmp,[90 -90])
                view(tmp,[180 -90])
            end
        end

        % Menu selected function: SuperiorMenu_3, ViewSuperiorMenu
        function viewSuperior(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[-90 90])
            end
        end

        % Menu selected function: LeftMenu_2
        function deleteLH(app, event)
            if isgraphics(app.brainFig,'figure')
               if isfield(app.underlay,'left')
                   toR = [];
                   for i = 2:length(app.overlay.settings)
                       if strcmpi(app.overlay.settings{i}.hemi,'left')
                           toR = [toR; i];
                       end
                   end
                   app.overlay.names(toR) = [];
                   app.overlay.paths(toR) = [];
                   app.overlay.bm(toR) = [];
                   app.overlay.bmB(toR) = [];
                   app.overlay.settings(toR) = [];
                  
                   delete(app.underlay.left)
                   app.underlay = rmfield(app.underlay,'left');
                   
                   if ~isempty(intersect(app.overlayListBox.Items(toR),app.overlayListBox.Value))
                       app.overlayListBox.Value = app.overlayListBox.Items(1);
                       for i = 1:length(app.currOver)
                           delete(app.currOver{i})
                       end
                   end
                   app.overlayListBox.Items(toR) = [];
                   [app] = overPatch(app,'false','false','true');
                   
                   %app.sg = rmfield(app.sg,'left');
                   %app.sgB = rmfield(app.sgB,'left');
                   app.sg.left = [];
                   app.sgB.left = [];
                   tog = updateGUI(app,'underlay','true','false');
                   
                   if ~isempty(app.atlas.overlay.left)
                       delete(app.atlas.overlay.left)
                       app.atlas.overlay.left = [];
                   end
                   
                   if ~isempty(app.atlas.cBar)
                       delete(app.atlas.cBar)
                       app.atlas.cBar = [];
                   end
                   
                   if ~isempty(app.atlas.cBarText)
                       delete(app.atlas.cBarText)
                       app.atlas.cBarText = [];
                   end
                   
                   if ~isempty(app.atlas.cBarAx)
                       delete(app.atlas.cBarAx)
                       app.atlas.cBarAx = [];
                   end
                   
                   app.atlas.defaultColors.left = [];
                   app.atlas.bm.left = [];
                   app.atlas.regions.left = [];
                   app.atlas.clusters.left = [];
                   app.atlas.borders.left = [];
                   app.atlas.name.left = [];
                   app.atlas.path.left = [];
                   
                   if isempty(app.atlas.overlay.right)
                       app.colormapSelectAtlas.Value = 'Select colormap';
                       app.LoadedatlasLabel.Text = 'Loaded atlas: ';
                       app.AreaonlasttooltipLabel.Text = 'Area on last tooltip: ';
                       app.selectSpacingAtlas = 'Select color spacing';
                       app.borderToggleAtlas.Value = 'Off';
                       app.upperLimAtlas.Value = 0;
                       app.lowerLimAtlas.Value = 0;
                       app.binAtlas.Value = 1;
                       app.opacityAtlas.Value = 0;
                       app.shrinkGrowAtlas.Value = 0;
                       app.invertToggleAtlas.Value = 'Off';
                   end
               end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a left hemisphere surface','Action unavailable');
            end
        end

        % Menu selected function: RightMenu_2
        function deleteRH(app, event)
            if isgraphics(app.brainFig,'figure')
                if isfield(app.underlay,'right')
                    toR = [];
                    for i = 2:length(app.overlay.settings)
                        if strcmpi(app.overlay.settings{i}.hemi,'right')
                            toR = [toR; i];
                        end
                    end
                    app.overlay.names(toR) = [];
                    app.overlay.paths(toR) = [];
                    app.overlay.bm(toR) = [];
                    app.overlay.bmB(toR) = [];
                    app.overlay.settings(toR) = [];
                    
                    delete(app.underlay.right)
                    app.underlay = rmfield(app.underlay,'right');
                    
                    if ~isempty(intersect(app.overlayListBox.Items(toR),app.overlayListBox.Value))
                        app.overlayListBox.Value = app.overlayListBox.Items(1);
                        for i = 1:length(app.currOver)
                            delete(app.currOver{i})
                        end
                    end
                    app.overlayListBox.Items(toR) = [];
                    [app] = overPatch(app,'false','false','true');
                    
%                     app.sg = rmfield(app.sg,'right');
%                     app.sgB = rmfield(app.sgB,'right');
                    app.sg.right = [];
                    app.sgB.right = [];
                    tog = updateGUI(app,'underlay','true','false');
                    
                    if ~isempty(app.atlas.overlay.left)
                        delete(app.atlas.overlay.right)
                        app.atlas.overlay.right = [];
                    end
                    right
                    if ~isempty(app.atlas.cBar)
                        delete(app.atlas.cBar)
                        app.atlas.cBar = [];
                    end
                    
                    if ~isempty(app.atlas.cBarText)
                        delete(app.atlas.cBarText)
                        app.atlas.cBarText = [];
                    end
                    
                    if ~isempty(app.atlas.cBarAx)
                        delete(app.atlas.cBarAx)
                        app.atlas.cBarAx = [];
                    end
                    
                    app.atlas.defaultColors.right = [];
                    app.atlas.bm.right = [];
                    app.atlas.regions.right = [];
                    app.atlas.clusters.right = [];
                    app.atlas.borders.right = [];
                    app.atlas.name.right = [];
                    app.atlas.path.right = [];
                    
                    if isempty(app.atlas.overlay.left)
                        app.colormapSelectAtlas.Value = 'Select colormap';
                        app.LoadedatlasLabel.Text = 'Loaded atlas: ';
                        app.AreaonlasttooltipLabel.Text = 'Area on last tooltip: ';
                        app.selectSpacingAtlas = 'Select color spacing';
                        app.borderToggleAtlas.Value = 'Off';
                        app.upperLimAtlas.Value = 0;
                        app.lowerLimAtlas.Value = 0;
                        app.binAtlas.Value = 1;
                        app.opacityAtlas.Value = 0;
                        app.shrinkGrowAtlas.Value = 0;
                        app.invertToggleAtlas.Value = 'Off';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a left hemisphere surface','Action unavailable');
            end
        end

        % Menu selected function: DeleteselectoverlayMenu, 
        % SpecificoverlayMenu_2
        function deleteSomeOverlay(app, event)
            % add all other buttons that can summon the GUI here as well
            % and set them to off
            if length(app.overlayListBox.Items) ~= 1
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to delete');
                
                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in overlays','Action unavailable');
            end
        end

        % Button pushed function: deleteButton
        function deleteOverlay(app, event)
            %[~,ia,~] = intersect(app.overlayListBox.Items,app.overlayListBox.Value);
            if ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                ia = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    ia = [ia; sit];
                end
                
                id = find(ia == 1);
                ia(id) = [];
                app.overlayListBox.Items(ia) = [];
                app.overlay.names(ia) = [];
                app.overlay.paths(ia) = [];
                app.overlay.bm(ia) = [];
                app.overlay.bmB(ia) = [];
                app.overlay.settings(ia) = [];
                [app] = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load or select an overlay','Action unavailable');
            end
        end

        % Menu selected function: SavethresholdedoverlaysMenu
        function saveOverlay(app, event)
            app.overlaySelectionApp = oSelect(app,' to save');
        end

        % Button pushed function: LHButton
        function changeDisplayLHButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmpi(app.underlay.left.Visible,'on')
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                    end
                elseif strcmpi(app.underlay.left.Visible,'off')
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: RHButton
        function changeDisplayRHButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if  strcmpi(app.underlay.right.Visible,'on')
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                    end
                elseif strcmpi(app.underlay.right.Visible,'off')
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: upButton
        function upButtonPress(app, event)
            old = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                old = [old; sit];
                s = [s; st];
            end
            
            old = sort(old,'ascend');
            new = old-1;
            id = find(new == 1);
            new(id) = [];
            old(id) = [];
            
            for i = 1:length(new)
                app.overlayListBox.Items = [app.overlayListBox.Items(1:new(i)-1) app.overlayListBox.Items(old(i)) app.overlayListBox.Items(new(i)) app.overlayListBox.Items(old(i)+1:end)];
                app.overlay.names = [app.overlay.names(1:new(i)-1) app.overlay.names(old(i)) app.overlay.names(new(i)) app.overlay.names(old(i)+1:end)];
                app.overlay.paths = [app.overlay.paths(1:new(i)-1) app.overlay.paths(old(i)) app.overlay.paths(new(i)) app.overlay.paths(old(i)+1:end)];
                app.overlay.bm = [app.overlay.bm(1:new(i)-1) app.overlay.bm(old(i)) app.overlay.bm(new(i)) app.overlay.bm(old(i)+1:end)];
                app.overlay.bmB = [app.overlay.bmB(1:new(i)-1) app.overlay.bmB(old(i)) app.overlay.bmB(new(i)) app.overlay.bmB(old(i)+1:end)];
                app.overlay.settings = [app.overlay.settings(1:new(i)-1) app.overlay.settings(old(i)) app.overlay.settings(new(i)) app.overlay.settings(old(i)+1:end)];
            end
        end

        % Button pushed function: downButton
        function downButtonPress(app, event)
            old = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                old = [old; sit];
                s = [s; st];
            end
            
            old = sort(old,'descend');
            new = old+1;
            id = find(new == length(app.overlayListBox.Items)+1);
            new(id) = [];
            old(id) = [];
            
            for i = 1:length(new)
                app.overlayListBox.Items = [app.overlayListBox.Items(1:old(i)-1) app.overlayListBox.Items(new(i)) app.overlayListBox.Items(old(i)) app.overlayListBox.Items(new(i)+1:end)];
                app.overlay.names = [app.overlay.names(1:old(i)-1) app.overlay.names(new(i)) app.overlay.names(old(i)) app.overlay.names(new(i)+1:end)];
                app.overlay.paths = [app.overlay.paths(1:old(i)-1) app.overlay.paths(new(i)) app.overlay.paths(old(i)) app.overlay.paths(new(i)+1:end)];
                app.overlay.bm = [app.overlay.bm(1:old(i)-1) app.overlay.bm(new(i)) app.overlay.bm(old(i)) app.overlay.bm(new(i)+1:end)];
                app.overlay.bmB = [app.overlay.bmB(1:old(i)-1) app.overlay.bmB(new(i)) app.overlay.bmB(old(i)) app.overlay.bmB(new(i)+1:end)];
                app.overlay.settings = [app.overlay.settings(1:old(i)-1) app.overlay.settings(new(i)) app.overlay.settings(old(i)) app.overlay.settings(new(i)+1:end)];
            end
        end

        % Button pushed function: dupeButton
        function dupeButtonPush(app, event)
            if length(app.overlayListBox.Items) ~= 1
                si = [];
                s = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    si = [si; sit];
                    s = [s; st];
                end
                si = sort(si,'ascend');
                new = si+1;
                id = find(new == 1);
                new(id) = [];
                si(id) = [];
                
                it = 0;
                for i = 1:length(new)
                    tmpNm = [app.overlayListBox.Items{si(i)+it} ' COPY'];
                    ex = 0;
                    while ex == 0
                        [~,~,si_2] = intersect(tmpNm,app.overlayListBox.Items);
                        if ~isempty(si_2)
                            tmpNm = [app.overlayListBox.Items{si(i)+it} ' COPY'];
                        else
                            ex = 1;
                        end
                    end
                    
                    %app.overlayListBox.Items = [app.overlayListBox.Items(1:si(i)+it) app.overlayListBox.Items(si(i)+it) app.overlayListBox.Items(si(i)+1+it:end)];
                    app.overlayListBox.Items = [app.overlayListBox.Items(1:si(i)+it-1) tmpNm app.overlayListBox.Items(si(i)+it) app.overlayListBox.Items(si(i)+1+it:end)];
                    app.overlay.names = [app.overlay.names(1:si(i)+it) app.overlay.names(si(i)+it) app.overlay.names(si(i)+1+it:end)];
                    app.overlay.paths = [app.overlay.paths(1:si(i)+it) app.overlay.paths(si(i)+it) app.overlay.paths(si(i)+1+it:end)];
                    app.overlay.bm = [app.overlay.bm(1:si(i)+it) app.overlay.bm(si(i)+it) app.overlay.bm(si(i)+1+it:end)];
                    app.overlay.bmB = [app.overlay.bmB(1:si(i)+it) app.overlay.bmB(si(i)+it) app.overlay.bmB(si(i)+1+it:end)];
                    app.overlay.settings = [app.overlay.settings(1:si(i)+it) app.overlay.settings(si(i)+it) app.overlay.settings(si(i)+1+it:end)];
                    it=it+1;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in some overlays','Action unavailable');
            end
        end

        % Button pushed function: screenshotButton
        function screenshotButtonPush(app, event)
            if isgraphics(app.brainFig,'figure')
                si = [];
                s = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    si = [si; sit];
                    s = [s; st];
                end
                id = find(si == 1);
                si(id) = [];
                
                if isempty(si)
                    nm = 'Untitled';
                else
                    tmp = app.overlay.names(si);
                    tmp2 = app.overlay.paths(si);
                    for i = 1:length(tmp)
                        [p n e] = fileparts([tmp2{i} tmp{i}]);
                        if i == 1
                            pth = p;
                        end
                        if i ~= length(tmp)
                            tmp3{i} = [n '_AND_'];
                        else
                            tmp3{i} = n;
                        end
                    end
                    nm = strcat(tmp3{:});
                end
                [oFile, oPath] = uiputfile({'*.png'},'Screenshot of current overlay',[pth app.pths.sep nm]);
                saveas(app.brainFig,[oPath oFile]);
                if strcmpi(app.ci,'true')
                    cropImages({[oPath oFile]},'false',1)
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: DeletealloverlaysMenu
        function deleteAllOverlay(app, event)
            if length(app.overlayListBox.Items) > 1
                app.overlayListBox.Items(2:end) = [];
                app.overlay.names(2:end) = [];
                app.overlay.paths(2:end) = [];
                app.overlay.bm(2:end) = [];
                app.overlay.bmB(2:end) = [];
                app.overlay.settings(2:end) = [];
                [app] = overPatch(app,'false','false','true');
            end
        end

        % Menu selected function: CombinedsavedCIFTIoverlaysMenu
        function combineCIFTI(app, event)
            [f,p,~] = uigetfile('*.nii','Select exactly two CIFTI files (a left and a right hemisphere)','MultiSelect','on');
            
            if length(f) == 2
                for i = 1:length(f)
                    try
                        ld = cifti_read([p f{i}]);
                    catch
                        uialert(app.brainSurferUIFigure,'Could not load file--maybe it is not CIFTI?');
                        %error('This is not a CIFTI file')
                    end
                    
                    c = cell2mat(ld.diminfo{1}.models);
                    c = {c.struct};
                    
                    l = find(contains(c,'CORTEX_LEFT'));
                    r = find(contains(c,'CORTEX_RIGHT'));
                    
                    s1 = sum(ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1));
                    s2 = sum(ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1));
                    
                    if i == 1 & ((s1 == 0 & s2 ~= 0) | (s2 == 0 & s1 ~= 0))
                        template = ld;
                        if s1 == 0 & s2 ~= 0
                            rep = 'lh';
                        else
                            rep = 'rh';
                        end
                    elseif i == 2 & ((s1 == 0 & s2 ~= 0) | (s2 == 0 & s1 ~= 0))
                        if strcmp(rep,'lh') & (s1 ~= 0 & s2 == 0)
                            template.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                        elseif strcmp(rep,'rh') & (s1 == 0 & s2 ~= 0)
                            template.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                        else
                            uialert(app.brainSurferUIFigure,['Wrong file? Expecting to replace other hemisphere in file: ' f{i}],'Invalid file selections');
                        end
                    else
                        uialert(app.brainFig,['Both hemispheres have data in file: ' f{i}],'Invalid file selections');
                    end
                end
                
                [~,f,e] = fileparts([p f{i}]);
                f = strrep(f,'rightHemi','');
                f = strrep(f,'leftHemi','');
                [oFile, oPath] = uiputfile({e},['Save combined CIFTI file: ' f(1:end-1)],[p f(1:end-1) e]);
                cifti_write(template,[oPath oFile]);
            else
                uialert(app.brainSurferUIFigure,'You either less than or more than 2 files!','Invalid file selections');
            end
        end

        % Value changed function: smoothButton
        function smoothButtonPress(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                [~,ia,~] = intersect(app.overlayListBox.Items,app.overlayListBox.Value);
                if ia ~= 1
                    a = app.smoothArea.Value;
                    s = app.smoothSteps.Value;
                    sw = app.smoothButton.Value;
                    as = app.assignSwitch.Value;

                    for i = 1:length(ia)
                        app.overlay.settings{ia(i)}.smoothArea = a;
                        app.overlay.settings{ia(i)}.smoothSteps = s;
                        app.overlay.settings{ia(i)}.smoothButton = sw;
                        app.overlay.settings{ia(i)}.assignSwitch = as;
                        if app.smoothButton.Value == 0
                            app.overlay.bm(ia(i)) = app.overlay.bmB(ia(i));
                        end
                    end
                    
                    app = overPatch(app,'true','false','true');
                    
                    app.smoothArea.Value = a;
                    app.smoothSteps.Value = s;
                    app.smoothButton.Value = sw;
                    
                    if  app.smoothButton.Value == 1
                        app.valueSelection.Value = 'Raw';
                        app.posSwitch.Value = 'On';
                        app.negSwitch.Value = 'On';
                        app.remZeroSwitch.Value = 'Off';
                        app.limMinOver.Value = min(app.overlay.bm{ia(i)});
                        app.limMaxOver.Value = max(app.overlay.bm{ia(i)});
                        mid = mean([app.limMinOver.Value app.limMaxOver.Value]);
                        app.lOver.Position = [mid 0; mid 0]; % but make this midpoint instead...
                        app.lowerThreshBox.Value = mid;
                        app.upperThreshBox.Value = mid;
                        app.clusterSlider.Value = 0;
                        app.clusterThreshBox.Value = 0;
                        
                        app.overlay.settings{ia(i)}.valueSelection = 'Raw';
                        app.overlay.settings{ia(i)}.posSwitch = 'On';
                        app.overlay.settings{ia(i)}.negSwitch = 'On';
                        app.overlay.settings{ia(i)}.remZeroSwitch = 'Off';
                        app.overlay.settings{ia(i)}.limMinOver = app.limMinOver.Value;
                        app.overlay.settings{ia(i)}.limMaxOver = app.limMaxOver.Value;
                        
                        app.overlay.settings{ia(i)}.lowerThreshBox = app.lowerThreshBox.Value;
                        app.overlay.settings{ia(i)}.upperThreshBox = app.upperThreshBox.Value;
                        app.overlay.settings{ia(i)}.clusterSlider = 0;
                        app.overlay.settings{ia(i)}.clusterThreshBox = 0;
                    end
                else
                    app.smoothButtonSurf.Value = 0;
                    uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface and select one overlay','Action unavailable');
                end
            end
        end

        % Menu selected function: ChangeFontSizeMenu
        function changeListBxSz(app, event)
            fntSz = inputdlg('Enter a font size for the list box','Font change',1,{'10'});
            if ~isempty(fntSz)
                app.overlayListBox.FontSize = str2double(fntSz{1});
            end
        end

        % Menu selected function: SpecificlocationMenu
        function viewSpecific(app, event)
            if isgraphics(app.brainFig,'figure')
                v = inputdlg('Change view of brain to specific coordinates','View change',1,{'-90 0'});
                if ~isempty(v)
                    v = str2num(v{1});
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,v)
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: TrimoverlaynamelengthsMenu
        function changeOverlayLngth(app, event)
            tmp = inputdlg('Enter maximum string length','String change',1,{'115'});
            if ~isempty(tmp)
                app.nmL = tmp;
                for i = 1:length(app.overlayListBox.Items)
                    if length(app.overlay.names{i}) > str2double(app.nmL)
                        app.overlayListBox.Items{i} = app.overlay.names{i}(1:str2double(app.nmL));
                    else
                        app.overlayListBox.Items{i} = app.overlay.names{i};
                    end
                end
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofalloverlaysMenu
        function screenshotButtonAllPush(app, event)
            if isgraphics(app.brainFig,'figure')
                for si = 2:length(app.overlayListBox.Items)
                    app.overlayListBox.Value = app.overlayListBox.Items(si);
                    [app] = overPatch(app,'false','false','true');
                    nm = app.overlay.names{si};
                    pth = app.overlay.paths{si};
                    [~, n, e] = fileparts(nm);
                    if strcmp(e,'.gz') & strcmp(n(end-3:end),'.nii')
                        n = n(1:end-4);
                        e = '.png';
                    else
                        e = '.png';
                    end
                    
                    ex = 1;
                    appen = '_copy_';
                    appnd = 0;
                    while ex == 1
                        if ~exist([pth n e],'file')
                            ex=0;
                        else
                            n = [n appen];
                            appnd = 1;
                        end
                    end
                    if appnd == 1
                        n = n(1:end-1);
                    end
                    
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    
                    view(tmp,[-90 0]);
                    
                    if isfield(app.underlay,'left')
                        tmpO = [pth n '_leftLateralView' e];
                    else
                        tmpO = [pth n '_rightMedialView' e];
                    end
                    saveas(app.brainFig,tmpO);
                    if strcmpi(app.ci,'true')
                        cropImages({tmpO},'false',1)
                    end
                    
                    view(tmp,[90 0]);
                    if isfield(app.underlay,'right')
                        tmpO = [pth n '_rightLateralView' e];
                    else
                        tmpO = [pth n '_leftMedialView' e];
                    end
                    saveas(app.brainFig,tmpO);
                    if strcmpi(app.ci,'true')
                        cropImages({tmpO},'false',1)
                    end
                    
                    view(tmp,[90 -90]);
                    saveas(app.brainFig,[pth n '_inferiorView' e]);
                    if strcmpi(app.ci,'true')
                        cropImages({[pth n '_inferiorView' e]},'false',1)
                    end
                    
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                        view(tmp,[-90 0]);
                        saveas(app.brainFig,[pth n '_rightMedialView' e]);
                        if strcmpi(app.ci,'true')
                            cropImages({[pth n '_rightMedialView' e]},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                    
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                        
                        view(tmp,[90 0]);
                        saveas(app.brainFig,[pth n '_leftMedialView' e]);
                        if strcmpi(app.ci,'true')
                            cropImages({[pth n '_leftMedialView' e]},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
                app.overlayListBox.Value = app.overlayListBox.Items(1);
                [app] = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofsomeoverlaysMenu
        function screenshotButtonSomePush(app, event)
            if isgraphics(app.brainFig,'figure')
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to screenshot');
                
                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofselectedoverlaysMenu
        function screenshotButtonSelectedPush(app, event)
            if isgraphics(app.brainFig,'figure')
                si = [];
                s = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    si = [si; sit];
                    s = [s; st];
                end
                id = find(si == 1);
                si(id) = [];
                
                if isempty(si)
                    nm = 'Untitled';
                else
                    tmp = app.overlay.names(si);
                    tmp2 = app.overlay.paths(si);
                    for i = 1:length(tmp)
                        [p n e] = fileparts([tmp2{i} tmp{i}]);
                        if i == 1
                            pth = p;
                        end
                        if i ~= length(tmp)
                            tmp3{i} = [n '_AND_'];
                        else
                            tmp3{i} = n;
                        end
                    end
                    nm = strcat(tmp3{:});
                end
                [oFile, oPath] = uiputfile({'*.png'},'Screenshot of current overlay',[pth app.pths.sep nm]);
                
                if ~isempty(oPath)
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    
                    view(tmp,[-90 0]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_leftLateralView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_leftLateralView.png']},'false',1)
                    end
                    
                    view(tmp,[90 0]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_rightLateralView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_rightLateralView.png']},'false',1)
                    end
                    
                    view(tmp,[90 -90]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_inferiorView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_inferiorView.png']},'false',1)
                    end
                    
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                        view(tmp,[-90 0]);
                        saveas(app.brainFig,[oPath oFile(1:end-4) '_rightMedialView.png']);
                        if strcmpi(app.ci,'true')
                            cropImages({[oPath oFile(1:end-4) '_rightMedialView.png']},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                    
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                        
                        view(tmp,[90 0]);
                        saveas(app.brainFig,[oPath oFile(1:end-4) '_leftMedialView.png']);
                        if strcmpi(app.ci,'true')
                            cropImages({[oPath oFile(1:end-4) '_leftMedialView.png']},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: copyButton
        function copyButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmp(app.overlayListBox.Value,app.overlayListBox.Items{1})
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to copy settings into');
                
                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Button pushed function: maskButton
        function maskButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmp(app.overlayListBox.Value,app.overlayListBox.Items{1})
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to mask');
                
                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Context menu opening function: ContextMenu15
        function reloadSomeOverlay(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to reload');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Value changed function: fdrButton
        function fdrCorrButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1}) & app.bonfButton.Value ~= 1
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.fdrButton.Value == 1
                    if ~isempty(app.overlay.settings{si}.pMap)
                        [pVecCorr, ~] = mafdr(app.overlay.settings{si}.pMap);
                        app.overlay.settings{si}.pMap = pVecCorr;
                        app.overlay.settings{si}.pFDR = 1;
                    end
                else
                    app.overlay.settings{si}.pMap =  app.overlay.settings{si}.pMapB;
                    app.overlay.settings{si}.pFDR = 0;
                end
                if app.pThreshBox.Value ~= 1
                    app = overPatch(app,'false','false','false');
                end
            else
                app.fdrButton.Value = 0;
                uialert(app.brainSurferUIFigure,'You need to select a single overlay and not have already performed Bonf. correction.','Action unavailable');
            end
        end

        % Value changed function: bonfButton
        function bonfCorrButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1}) & app.fdrButton.Value ~= 1
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.bonfButton.Value == 1
                    if ~isempty(app.overlay.settings{si}.pMap)
                        [pVecCorr,~] = bonf_holm(app.overlay.settings{si}.pMap,0.05);
                        app.overlay.settings{si}.pMap = pVecCorr;
                        app.overlay.settings{si}.pBonf = 1;
                    end
                else
                    app.overlay.settings{si}.pMap =  app.overlay.settings{si}.pMapB;
                    app.overlay.settings{si}.pBonf = 0;
                end
                if app.pThreshBox.Value ~= 1
                    app = overPatch(app,'false','false','false');
                end
            else
                app.bonfButton.Value = 0;
                uialert(app.brainSurferUIFigure,'You need to select a single overlay and not have already performed FDR correction.','Action unavailable');
            end
        end

        % Menu selected function: RandomizecolorsMenu
        function randomizeColorsButton(app, event)
            if isgraphics(app.brainFig,'figure')  & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.overlay.left)
                    c1 = unique(app.atlas.overlay.left.FaceVertexCData,'Rows');
                    c2 = c1(randperm(size(c1,1)),:);
                    
                    [~, id] = ismember(app.atlas.overlay.left.FaceVertexCData,c1,'rows');
                    app.atlas.overlay.left.FaceVertexCData = c2(id,:);
                    
                    cbar = squeeze(app.atlas.cBar.CData);
                    %[~, ia,ib] = intersect(cbar,c1,'rows');
                    [~, id1] = ismember(c1,cbar,'rows');
                    rcbar = cbar;
                    rcbar(id1,:) = c2;
                end
                if ~isempty(app.atlas.overlay.right)
                    c1 = unique(app.atlas.overlay.right.FaceVertexCData,'Rows');
                    
                    if (~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)) & (isequal(unique(app.atlas.regions.left),unique(app.atlas.regions.right)) & isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right)))
                    %if (~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)) & isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right))
                        [~, id] = ismember(app.atlas.overlay.right.FaceVertexCData,c1,'rows');
                        app.atlas.overlay.right.FaceVertexCData = c2(id,:);
                        rcbar2 = rcbar;
                    else
                        c2 = c1(randperm(size(c1,1)),:);
                        [~, id] = ismember(app.atlas.overlay.right.FaceVertexCData,c1,'rows');
                        app.atlas.overlay.right.FaceVertexCData = c2(id,:);
                        
                        cbar = squeeze(app.atlas.cBar.CData);
                        %[~, ia,ib] = intersect(cbar,c1,'rows');
                        [~, id2] = ismember(c1,cbar,'rows');
                        rcbar2 = cbar;
                        rcbar2(id2,:) = c2;
                    end
                end
                
                if ~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    if ~isequal(unique(app.atlas.regions.left),unique(app.atlas.regions.right)) | ~isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right))
                        cbarf = cbar;
                        cbarf(id1,:) = rcbar(id1,:);
                        cbarf(id2,:) = rcbar2(id2,:);
                    else
                        cbarf = rcbar;
                    end
                elseif ~isempty(app.atlas.defaultColors.left) & isempty(app.atlas.defaultColors.right)
                    cbarf = rcbar;
                    
                elseif isempty(app.atlas.defaultColors.left) & ~isempty(app.atlas.defaultColors.right)
                    cbarf = rcbar2;
                end
                
                tmp = zeros([1,size(cbarf)]);
                tmp(1,:,:) = cbarf;
                
                app.atlas.cBar.CData = tmp;
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: 
        % UsecolorsfromannotationCIFTIheaderforatlasMenu, 
        % UsedefaultcolorsfromatlasifavailableMenu
        function useDefaultColorsAtlas(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if isempty(app.atlas.defaultColors.left) & isempty(app.atlas.defaultColors.right)
                    uialert(app.brainSurferUIFigure,'It looks like your atlas does not have any default colors we could use','Action unavailable');
                else
                    if ~isempty(app.atlas.defaultCbar.left) & ~isempty(app.atlas.defaultCbar.right)
                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                            cbar = [app.atlas.defaultCbar.left; app.atlas.defaultCbar.right];
                        else
                            cbar = [];
                        end
                    end
                    
                    cd = 0;
                    if ~isempty(app.atlas.defaultColors.left)
                        if isempty(cbar)
                            cbar = app.atlas.defaultCbar.left;
                        end
                        if max(app.atlas.defaultColors.left) > 1
                            app.atlas.overlay.left.FaceVertexCData = app.atlas.defaultColors.left./256;
                            cbar = cbar./256;
                            cd = 1;
                        else
                            app.atlas.overlay.left.FaceVertexCData = app.atlas.defaultColors.left;
                        end
                    end
                    if ~isempty(app.atlas.defaultColors.right)
                        if isempty(cbar)
                            cbar = app.atlas.defaultCbar.right;
                        end
                        if max(app.atlas.defaultColors.right) > 1
                            app.atlas.overlay.right.FaceVertexCData = app.atlas.defaultColors.right./256;
                            if cd == 0
                                cbar = cbar./256;
                            end
                        else
                            app.atlas.overlay.right.FaceVertexCData = app.atlas.defaultColors.right;
                        end
                    end
                    % now patch cbar
                    tmp = zeros([1,size(cbar)]);
                    tmp(1,:,:) = cbar;
                    app.atlas.cBar.CData = tmp;
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Value changed function: invertToggleAtlas
        function invertToggleAtlasChange(app, event)
            updateAtlas(app)
        end

        % Value changed function: toggleColorbarAtlas
        function colorbarToggleChange(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if app.toggleColorbarAtlas.Value == 0
                    app.atlas.cBar.Visible = 'off';
                    app.atlas.cBarAx.Visible = 'off';
                    app.atlas.cBarText.Visible = 'off';
                else
                    app.atlas.cBar.AlphaData = ones(size(app.atlas.cBar.AlphaData)).*app.opacityAtlas.Value;
                    app.atlas.cBar.Visible = 'on';
                    app.atlas.cBarAx.Visible = 'on';
                    app.atlas.cBarText.Visible = 'on';
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Value changed function: selectSpacingAtlas
        function spacingAtlasChange(app, event)
            updateAtlas(app)
        end

        % Value changed function: colormapSelectAtlas
        function colormapAtlasChange(app, event)
            updateAtlas(app)
        end

        % Value changed function: upperLimAtlas
        function upperLimAtlasChange(app, event)
            updateAtlas(app)
        end

        % Value changed function: lowerLimAtlas
        function lowerLimAtlasChange(app, event)
updateAtlas(app)
        end

        % Value changed function: binAtlas
        function binAtlasChange(app, event)
updateAtlas(app)
        end

        % Value changed function: opacityAtlas
        function opacityAtlasChange(app, event)
             if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                 if ~isempty(app.atlas.overlay.left)
                     app.atlas.overlay.left.FaceVertexAlphaData = ones(size(app.atlas.overlay.left.FaceVertexAlphaData)) * app.opacityAtlas.Value;
                 end
                 if ~isempty(app.atlas.overlay.right)
                     app.atlas.overlay.right.FaceVertexAlphaData =  ones(size(app.atlas.overlay.right.FaceVertexAlphaData)) * app.opacityAtlas.Value;
                 end
                 app.atlas.cBar.AlphaData = ones(size(app.atlas.cBar.AlphaData))*app.opacityAtlas.Value;
             else
                 uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
             end
            %updateAtlas(app)
        end

        % Value changed function: shrinkGrowAtlas
        function shrinkGrowAtlasChange(app, event)
            if strcmpi(app.borderToggleAtlas.Value,'On')
                updateAtlas(app)
            else
                app.shrinkGrowAtlas.Value = 0;
                uialert(app.brainSurferUIFigure,'You can only use this button if borders are on','Action unavailable');
            end
        end

        % Value changed function: borderToggleAtlas
        function borderToggleAtlasChange(app, event)
            updateAtlas(app)
        end

        % Menu selected function: CreatecolormapMenu
        function createColormapButton(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Callback function
        function loadGUIState(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Callback function
        function saveGUIState(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Menu selected function: ImportoverlayonfsaverageMenu_2
        function importVol2FS(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Menu selected function: Importoverlayoncarets900Menu
        function importVol2s900(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Menu selected function: ImportanatlasMenu
        function importAtlasButton(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update.','Action unavailable');
        end

        % Button pushed function: reloadAtlas
        function reloadAtlasButton(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.left app.atlas.name.left]; f{2} = [app.atlas.path.right app.atlas.name.right];
                elseif ~isempty(app.atlas.name.left) & isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.left app.atlas.name.left];
                elseif isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.right app.atlas.name.right];
                end
                l = app.atlas.regions.left;
                r = app.atlas.regions.right;
                
                importAtlas(app,f,[]);
                
                app.atlas.regions.left = l;
                app.atlas.regions.right = r;
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: loadAtlas
        function loadAtlasButton(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                % Ask for files
                
                [f,p,~] = uigetfile('*','MultiSelect','on');
                
                % Check if any file was selected
                if p ~= 0
                    if ~iscell(f) % if one file, it will not be in cell array
                        f = {f};
                    end
                    for i = 1:length(f)
                        f{i} = [p f{i}];
                    end
                    if length(f) > 2
                        uialert(app.brainSurferUIFigure,'You can only select two files to load as an atlas (one left and one right hemisphere).','Action unavailable');
                    else
            
                    importAtlas(app,f,[]);
                        
                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
                end
            end
        end

        % Button pushed function: loadLabels
        function loadLabelsButton(app, event)
            if isgraphics(app.brainFig,'figure')
                % Ask for files
                [f,p,~] = uigetfile({'*.txt;*.xml' 'Text file or Word document';'*.txt' 'Text file'; '*.xml' 'XML document'});
                
                % Check if any file was selected
                if p > 0
                    [~,n,e] = fileparts([p f]);
                    disp(['Trying to load file: '])
                    
                    if ~isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                        hemi{1} = 'left'; hemi{2} = 'right';
                        %f{1} = [app.atlas.path.left app.atlas.name.left]; f{2} = [app.atlas.path.right app.atlas.name.right];
                    elseif ~isempty(app.atlas.name.left) & isempty(app.atlas.name.right)
                        %f{1} = [app.atlas.path.left app.atlas.name.left];
                        hemi{1} = 'left';
                    elseif isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                        %f{1} = [app.atlas.path.right app.atlas.name.right];
                        hemi{2} = 'right';
                    end
                    
                    for i = 1:length(hemi)
                        % get atlas names
                        switch e
                            case '.xml'
                                if i == 1
                                    xml = xml2struct([p f]);
                                    name = [];
                                    key = [];
                                    for j = 1:length(xml.Children(4).Children)
                                        if ~isempty(xml.Children(4).Children(j).Children)
                                            name = [name; {xml.Children(4).Children(j).Children.Data}];
                                            key = [key; str2double(xml.Children(4).Children(j).Attributes(1).Value)];
                                        end
                                    end
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                [~, is2] = ismember(app.atlas.bm.(hemi{i}),key);
                                
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = name(is2(id));
                                app.atlas.regions.(hemi{i})(id2) =  {'No region information'};;
                            case '.txt'
                                if i == 1
                                    fid = fopen([p f]);
                                    tline = fgetl(fid);
                                    tlines = cell(0,1);
                                    while ischar(tline)
                                        tlines{end+1,1} = tline;
                                        tline = fgetl(fid);
                                    end
                                    fclose(fid);
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = tlines(app.atlas.bm.(hemi{i})(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};;
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: EditlightlocationsMenu
        function editLightLocationsButton(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update. If you would like to edit lights, you can do so manullay by changing them in ./scripts/defaultLights.txt','Action unavailable');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.brainSurferUIFigure = uifigure('Visible', 'off');
            app.brainSurferUIFigure.Name = 'brainSurfer';
            app.brainSurferUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.brainSurferUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadsurfaceMenu = uimenu(app.FileMenu);
            app.LoadsurfaceMenu.Text = 'Load surface';
            app.NewfigureMenu = uimenu(app.LoadsurfaceMenu);
            app.NewfigureMenu.Text = 'New figure';
            app.FsaveragetemplateMenu_2 = uimenu(app.NewfigureMenu);
            app.FsaveragetemplateMenu_2.Text = 'Fsaverage template';
            app.LHRHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.LHRHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragelhrh, true);
            app.LHRHMenu.Text = 'LH + RH';
            app.LHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.LHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragelh, true);
            app.LHMenu.Text = 'LH';
            app.RHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.RHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragerh, true);
            app.RHMenu.Text = 'RH';
            app.Carettemplates900Menu_2 = uimenu(app.NewfigureMenu);
            app.Carettemplates900Menu_2.Text = 'Caret template (s900)';
            app.FlatMenu_3 = uimenu(app.Carettemplates900Menu_2);
            app.FlatMenu_3.Text = 'Flat';
            app.LHRHMenu_2 = uimenu(app.FlatMenu_3);
            app.LHRHMenu_2.MenuSelectedFcn = createCallbackFcn(app, @loadflatlhrh, true);
            app.LHRHMenu_2.Text = 'LH + RH';
            app.LHMenu_12 = uimenu(app.FlatMenu_3);
            app.LHMenu_12.MenuSelectedFcn = createCallbackFcn(app, @loadflatlh, true);
            app.LHMenu_12.Text = 'LH';
            app.RHMenu_12 = uimenu(app.FlatMenu_3);
            app.RHMenu_12.MenuSelectedFcn = createCallbackFcn(app, @loadflatrh, true);
            app.RHMenu_12.Text = 'RH';
            app.VeryinflatedMenu_3 = uimenu(app.Carettemplates900Menu_2);
            app.VeryinflatedMenu_3.Text = 'Very inflated';
            app.LHRHMenu_3 = uimenu(app.VeryinflatedMenu_3);
            app.LHRHMenu_3.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedlhrh, true);
            app.LHRHMenu_3.Text = 'LH + RH';
            app.LHMenu_13 = uimenu(app.VeryinflatedMenu_3);
            app.LHMenu_13.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedlh, true);
            app.LHMenu_13.Text = 'LH';
            app.RHMenu_13 = uimenu(app.VeryinflatedMenu_3);
            app.RHMenu_13.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedrh, true);
            app.RHMenu_13.Text = 'RH';
            app.InflatedMenu_3 = uimenu(app.Carettemplates900Menu_2);
            app.InflatedMenu_3.Text = 'Inflated';
            app.LHRHMenu_4 = uimenu(app.InflatedMenu_3);
            app.LHRHMenu_4.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedlhrh, true);
            app.LHRHMenu_4.Text = 'LH + RH';
            app.LHMenu_14 = uimenu(app.InflatedMenu_3);
            app.LHMenu_14.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedlh, true);
            app.LHMenu_14.Text = 'LH';
            app.RHMenu_14 = uimenu(app.InflatedMenu_3);
            app.RHMenu_14.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedrh, true);
            app.RHMenu_14.Text = 'RH';
            app.MidthicknessMenu_3 = uimenu(app.Carettemplates900Menu_2);
            app.MidthicknessMenu_3.Text = 'Midthickness';
            app.LHRHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.LHRHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknesslhrh, true);
            app.LHRHMenu_15.Text = 'LH + RH';
            app.LHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.LHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknesslh, true);
            app.LHMenu_15.Text = 'LH';
            app.RHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.RHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknessrh, true);
            app.RHMenu_15.Text = 'RH';
            app.LoadfilesMenu = uimenu(app.NewfigureMenu);
            app.LoadfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @loadSurface, true);
            app.LoadfilesMenu.Text = 'Load file(s)...';
            app.ExistingfigureMenu = uimenu(app.LoadsurfaceMenu);
            app.ExistingfigureMenu.Text = 'Existing figure';
            app.FsaveragetemplateMenu_3 = uimenu(app.ExistingfigureMenu);
            app.FsaveragetemplateMenu_3.Text = 'Fsaverage template';
            app.LHMenu_7 = uimenu(app.FsaveragetemplateMenu_3);
            app.LHMenu_7.MenuSelectedFcn = createCallbackFcn(app, @addfsaveragelh, true);
            app.LHMenu_7.Text = 'LH';
            app.RHMenu_7 = uimenu(app.FsaveragetemplateMenu_3);
            app.RHMenu_7.MenuSelectedFcn = createCallbackFcn(app, @addfsaveragerh, true);
            app.RHMenu_7.Text = 'RH';
            app.Carettemplates900Menu_3 = uimenu(app.ExistingfigureMenu);
            app.Carettemplates900Menu_3.Text = 'Caret template (s900)';
            app.FlatMenu_2 = uimenu(app.Carettemplates900Menu_3);
            app.FlatMenu_2.Text = 'Flat';
            app.LHMenu_8 = uimenu(app.FlatMenu_2);
            app.LHMenu_8.MenuSelectedFcn = createCallbackFcn(app, @addflatlh, true);
            app.LHMenu_8.Text = 'LH';
            app.RHMenu_8 = uimenu(app.FlatMenu_2);
            app.RHMenu_8.MenuSelectedFcn = createCallbackFcn(app, @addflatrh, true);
            app.RHMenu_8.Text = 'RH';
            app.VeryinflatedMenu_2 = uimenu(app.Carettemplates900Menu_3);
            app.VeryinflatedMenu_2.Text = 'Very inflated';
            app.LHMenu_9 = uimenu(app.VeryinflatedMenu_2);
            app.LHMenu_9.MenuSelectedFcn = createCallbackFcn(app, @addveryinflatedlh, true);
            app.LHMenu_9.Text = 'LH';
            app.RHMenu_9 = uimenu(app.VeryinflatedMenu_2);
            app.RHMenu_9.MenuSelectedFcn = createCallbackFcn(app, @addveryinflatedrh, true);
            app.RHMenu_9.Text = 'RH';
            app.InflatedMenu_2 = uimenu(app.Carettemplates900Menu_3);
            app.InflatedMenu_2.Text = 'Inflated';
            app.LHMenu_10 = uimenu(app.InflatedMenu_2);
            app.LHMenu_10.MenuSelectedFcn = createCallbackFcn(app, @addinflatedlh, true);
            app.LHMenu_10.Text = 'LH';
            app.RHMenu_10 = uimenu(app.InflatedMenu_2);
            app.RHMenu_10.MenuSelectedFcn = createCallbackFcn(app, @addinflatedrh, true);
            app.RHMenu_10.Text = 'RH';
            app.MidthicknessMenu_2 = uimenu(app.Carettemplates900Menu_3);
            app.MidthicknessMenu_2.Text = 'Midthickness';
            app.LHMenu_11 = uimenu(app.MidthicknessMenu_2);
            app.LHMenu_11.MenuSelectedFcn = createCallbackFcn(app, @addmidthicknesslh, true);
            app.LHMenu_11.Text = 'LH';
            app.RHMenu_11 = uimenu(app.MidthicknessMenu_2);
            app.RHMenu_11.MenuSelectedFcn = createCallbackFcn(app, @addmidthicknessrh, true);
            app.RHMenu_11.Text = 'RH';
            app.AddfilesMenu_2 = uimenu(app.ExistingfigureMenu);
            app.AddfilesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @addSurface, true);
            app.AddfilesMenu_2.Text = 'Add file(s)...';
            app.LoadsulcigyriMenu = uimenu(app.FileMenu);
            app.LoadsulcigyriMenu.MenuSelectedFcn = createCallbackFcn(app, @loadsg, true);
            app.LoadsulcigyriMenu.Text = 'Load sulci/gyri...';
            app.LoadoverlaysMenu = uimenu(app.FileMenu);
            app.LoadoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @loadOverlay, true);
            app.LoadoverlaysMenu.Text = 'Load overlay(s)...';
            app.ImportoverlayfromvolumespaceMenu = uimenu(app.FileMenu);
            app.ImportoverlayfromvolumespaceMenu.Text = 'Import overlay from volume space';
            app.ImportoverlayonfsaverageMenu_2 = uimenu(app.ImportoverlayfromvolumespaceMenu);
            app.ImportoverlayonfsaverageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @importVol2FS, true);
            app.ImportoverlayonfsaverageMenu_2.Text = 'Import overlay on fsaverage...';
            app.Importoverlayoncarets900Menu = uimenu(app.ImportoverlayfromvolumespaceMenu);
            app.Importoverlayoncarets900Menu.MenuSelectedFcn = createCallbackFcn(app, @importVol2s900, true);
            app.Importoverlayoncarets900Menu.Text = 'Import overlay on caret (s900)...';
            app.ImportanatlasMenu = uimenu(app.ImportoverlayfromvolumespaceMenu);
            app.ImportanatlasMenu.MenuSelectedFcn = createCallbackFcn(app, @importAtlasButton, true);
            app.ImportanatlasMenu.Text = 'Import an atlas...';
            app.SavethresholdedoverlaysMenu = uimenu(app.FileMenu);
            app.SavethresholdedoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @saveOverlay, true);
            app.SavethresholdedoverlaysMenu.Text = 'Save thresholded overlay(s)...';
            app.CombinedsavedCIFTIoverlaysMenu = uimenu(app.FileMenu);
            app.CombinedsavedCIFTIoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @combineCIFTI, true);
            app.CombinedsavedCIFTIoverlaysMenu.Text = 'Combined saved CIFTI overlays...';
            app.DeleteMenu = uimenu(app.FileMenu);
            app.DeleteMenu.Text = 'Delete';
            app.HemisphereMenu_2 = uimenu(app.DeleteMenu);
            app.HemisphereMenu_2.Text = 'Hemisphere';
            app.LeftMenu_2 = uimenu(app.HemisphereMenu_2);
            app.LeftMenu_2.MenuSelectedFcn = createCallbackFcn(app, @deleteLH, true);
            app.LeftMenu_2.Text = 'Left';
            app.RightMenu_2 = uimenu(app.HemisphereMenu_2);
            app.RightMenu_2.MenuSelectedFcn = createCallbackFcn(app, @deleteRH, true);
            app.RightMenu_2.Text = 'Right';
            app.SpecificoverlayMenu_2 = uimenu(app.DeleteMenu);
            app.SpecificoverlayMenu_2.MenuSelectedFcn = createCallbackFcn(app, @deleteSomeOverlay, true);
            app.SpecificoverlayMenu_2.Text = 'Specific overlay...';
            app.ViewMenu = uimenu(app.brainSurferUIFigure);
            app.ViewMenu.Text = 'View';
            app.LeftlateralMenu = uimenu(app.ViewMenu);
            app.LeftlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralLeft, true);
            app.LeftlateralMenu.Text = 'Left lateral';
            app.RightlateralMenu = uimenu(app.ViewMenu);
            app.RightlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralRight, true);
            app.RightlateralMenu.Text = 'Right lateral';
            app.InferiorMenu_3 = uimenu(app.ViewMenu);
            app.InferiorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @viewInferior, true);
            app.InferiorMenu_3.Text = 'Inferior';
            app.SuperiorMenu_3 = uimenu(app.ViewMenu);
            app.SuperiorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @viewSuperior, true);
            app.SuperiorMenu_3.Text = 'Superior';
            app.SpecificlocationMenu = uimenu(app.ViewMenu);
            app.SpecificlocationMenu.MenuSelectedFcn = createCallbackFcn(app, @viewSpecific, true);
            app.SpecificlocationMenu.Text = 'Specific location...';
            app.GridLayout = uigridlayout(app.brainSurferUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_8 = uipanel(app.LeftPanel);
            app.AppearanceLabel_2 = uilabel(app.Panel_8);
            app.AppearanceLabel_2.Text = 'Appearance';
            app.TabGroup = uitabgroup(app.Panel_8);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TabGroup_2 = uitabgroup(app.DataTab);
            app.UnderlayTab = uitab(app.TabGroup_2);
            app.UnderlayTab.Title = 'Underlay';
            app.Panel_10 = uipanel(app.UnderlayTab);
            app.ValuesPanel = uipanel(app.Panel_10);
            app.ValuesPanel.Title = 'Values';
            app.valueSelectionSurf = uidropdown(app.ValuesPanel);
            app.valueSelectionSurf.Items = {'Raw', 'Percentile', 'Normalized', 'Scaled (0 to 1)', 'Scaled (-1 to 1)', 'Percentile (absolute)', 'Normalized (absolute)', 'Scaled (absolute)', 'Percentile (separate for pos/neg)', 'Normalized (separate for pos/neg)', 'Scaled (separate for pos/neg)', 'Scaled (absolute and separate for pos/neg)'};
            app.valueSelectionSurf.ValueChangedFcn = createCallbackFcn(app, @changeValueSurf, true);
            app.valueSelectionSurf.Tooltip = {'Change the values of the sulcal/gyral data currently loaded on top of the underlay'};
            app.valueSelectionSurf.Value = 'Raw';
            app.posSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.posSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changePosSwitchSurf, true);
            app.posSwitchSurf.Tooltip = {'Turn positive values in sulcal/gyral data patched on top of underlay on or off'};
            app.posSwitchSurf.Value = 'On';
            app.PositiveLabel = uilabel(app.ValuesPanel);
            app.PositiveLabel.Text = 'Positive';
            app.negSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.negSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeNegSwitchSurf, true);
            app.negSwitchSurf.Tooltip = {'Turn negative values in sulcal/gyral data patched on top of underlay on or off'};
            app.negSwitchSurf.Value = 'On';
            app.NegativeLabel = uilabel(app.ValuesPanel);
            app.NegativeLabel.Text = 'Negative';
            app.ZerosLabel = uilabel(app.ValuesPanel);
            app.ZerosLabel.Text = 'Zeros';
            app.remZeroSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.remZeroSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeZeroSwitchSurf, true);
            app.remZeroSwitchSurf.Tooltip = {'Turn zeros in sulcal/gyral data patched on top of underlay on or off'};
            app.remZeroSwitchSurf.Value = 'On';
            app.reloadButton2 = uibutton(app.ValuesPanel, 'push');
            app.reloadButton2.ButtonPushedFcn = createCallbackFcn(app, @reloadUnderlay, true);
            app.reloadButton2.Icon = 'reloadButton.jpg';
            app.reloadButton2.Tooltip = {'Reload the sulci/gyri loaded in for this underlay'};
            app.reloadButton2.Text = '';
            app.BinarizevaluessulciandgyriPanel = uipanel(app.Panel_10);
            app.BinarizevaluessulciandgyriPanel.Title = 'Binarize values (sulci and gyri)';
            app.binSwitchSurf = uiswitch(app.BinarizevaluessulciandgyriPanel, 'slider');
            app.binSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeBinSwitchSurf, true);
            app.binSwitchSurf.Tooltip = {'This binarizes your sulcal/gyral data based on the threshold to the right'};
            app.sgThresh = uispinner(app.BinarizevaluessulciandgyriPanel);
            app.sgThresh.ValueChangedFcn = createCallbackFcn(app, @changeSgThresh, true);
            app.sgThresh.Tooltip = {'Anything above this value will be gyri and anything below is sulci'};
            app.ThresholdLabel = uilabel(app.BinarizevaluessulciandgyriPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.DefinecolorforsulciandgyriindividuallyLabel = uilabel(app.BinarizevaluessulciandgyriPanel);
            app.DefinecolorforsulciandgyriindividuallyLabel.Text = 'Define color for sulci and gyri individually';
            app.sulcButton = uibutton(app.BinarizevaluessulciandgyriPanel, 'push');
            app.sulcButton.ButtonPushedFcn = createCallbackFcn(app, @changeSulcButton, true);
            app.sulcButton.Tooltip = {'This shows the color of sucli or gyri. Press the button to change this color on the brain. Right click for additional options'};
            app.sulcButton.Text = '';
            app.gyrButton = uibutton(app.BinarizevaluessulciandgyriPanel, 'push');
            app.gyrButton.ButtonPushedFcn = createCallbackFcn(app, @changeGyriButton, true);
            app.gyrButton.Tooltip = {'This shows the color of sucli or gyri. Press the button to change this color on the brain. Right click for additional options'};
            app.gyrButton.Text = '';
            app.SurfacePanel = uipanel(app.Panel_10);
            app.SurfacePanel.Title = 'Surface';
            app.opacityBoxSurf = uispinner(app.SurfacePanel);
            app.opacityBoxSurf.Step = 0.05;
            app.opacityBoxSurf.Limits = [1e-05 1];
            app.opacityBoxSurf.ValueChangedFcn = createCallbackFcn(app, @opacitySurfChange, true);
            app.opacityBoxSurf.Tooltip = {'Change the opacitiy of the entire surface'};
            app.opacityBoxSurf.Value = 1;
            app.opacityLabelSurf_2 = uilabel(app.SurfacePanel);
            app.opacityLabelSurf_2.Text = 'Opacity';
            app.leftHemiToggle = uibutton(app.SurfacePanel, 'state');
            app.leftHemiToggle.ValueChangedFcn = createCallbackFcn(app, @changeDisplayLH, true);
            app.leftHemiToggle.Tooltip = {'Hide the left hemisphere surface if there is one loaded'};
            app.leftHemiToggle.Text = 'Left';
            app.rightHemiToggle = uibutton(app.SurfacePanel, 'state');
            app.rightHemiToggle.ValueChangedFcn = createCallbackFcn(app, @changeDisplayRH, true);
            app.rightHemiToggle.Tooltip = {'Hide the right hemisphere surface if there is one loaded'};
            app.rightHemiToggle.Text = 'Right';
            app.opacityLabelSurf_3 = uilabel(app.SurfacePanel);
            app.opacityLabelSurf_3.Text = 'Hide';
            app.RangeofvaluestoexcludePanel = uipanel(app.UnderlayTab);
            app.RangeofvaluestoexcludePanel.Title = 'Range of values to exclude';
            app.lowerThreshBoxSurf = uispinner(app.RangeofvaluestoexcludePanel);
            app.lowerThreshBoxSurf.ValueChangedFcn = createCallbackFcn(app, @changeLowerThreshBoxSurf, true);
            app.lowerThreshBoxSurf.Tooltip = {'Threshold for sulci/gyri data patched on top of the underlay. Any data that falls in between these two thresholds will not be patched.'};
            app.upperThreshBoxSurf = uispinner(app.RangeofvaluestoexcludePanel);
            app.upperThreshBoxSurf.ValueChangedFcn = createCallbackFcn(app, @changeUpperThreshBoxSurf, true);
            app.upperThreshBoxSurf.Tooltip = {'Threshold for sulci/gyri data patched on top of the underlay. Any data that falls in between these two thresholds will not be patched.'};
            app.LowerlimitLabel = uilabel(app.RangeofvaluestoexcludePanel);
            app.LowerlimitLabel.Text = 'Lower limit';
            app.UpperlimitLabel = uilabel(app.RangeofvaluestoexcludePanel);
            app.UpperlimitLabel.Text = 'Upper limit';
            app.OverlayTab = uitab(app.TabGroup_2);
            app.OverlayTab.Title = 'Overlay';
            app.Panel_7 = uipanel(app.OverlayTab);
            app.ValuesPanel_2 = uipanel(app.Panel_7);
            app.ValuesPanel_2.Title = 'Values';
            app.valueSelection = uidropdown(app.ValuesPanel_2);
            app.valueSelection.Items = {'Raw', 'Percentile', 'Normalized', 'Scaled (0 to 1)', 'Scaled (-1 to 1)', 'Percentile (absolute)', 'Normalized (absolute)', 'Scaled (absolute)', 'Percentile (separate for pos/neg)', 'Normalized (separate for pos/neg)', 'Scaled (separate for pos/neg)', 'Scaled (absolute and separate for pos/neg)'};
            app.valueSelection.ValueChangedFcn = createCallbackFcn(app, @valueSelectionChange, true);
            app.valueSelection.Tooltip = {'Change the values of the currently selected overlay'};
            app.valueSelection.Value = 'Raw';
            app.posSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.posSwitch.ValueChangedFcn = createCallbackFcn(app, @posSwitchChange, true);
            app.posSwitch.Tooltip = {'Turn positive values in overlay on or off'};
            app.posSwitch.Value = 'On';
            app.PositiveLabel_2 = uilabel(app.ValuesPanel_2);
            app.PositiveLabel_2.Text = 'Positive';
            app.negSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.negSwitch.ValueChangedFcn = createCallbackFcn(app, @negSwitchChange, true);
            app.negSwitch.Tooltip = {'Turn negative values in overlay on or off'};
            app.negSwitch.Value = 'On';
            app.NegativeLabel_2 = uilabel(app.ValuesPanel_2);
            app.NegativeLabel_2.Text = 'Negative';
            app.ZerosLabel_2 = uilabel(app.ValuesPanel_2);
            app.ZerosLabel_2.Text = 'Zeros';
            app.remZeroSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.remZeroSwitch.ValueChangedFcn = createCallbackFcn(app, @remZeroSwitchChange, true);
            app.remZeroSwitch.Tooltip = {'Turn zeros in overlay on or off'};
            app.reloadOver = uibutton(app.ValuesPanel_2, 'push');
            app.reloadOver.ButtonPushedFcn = createCallbackFcn(app, @reloadOverlay, true);
            app.reloadOver.Icon = 'reloadButton.jpg';
            app.reloadOver.Tooltip = {'Reload the currently selected overlay'};
            app.reloadOver.Text = '';
            app.pvaluesPanel_2 = uipanel(app.Panel_7);
            app.pvaluesPanel_2.Title = 'p-values';
            app.addpButton = uibutton(app.pvaluesPanel_2, 'state');
            app.addpButton.ValueChangedFcn = createCallbackFcn(app, @addpButtonChange, true);
            app.addpButton.Tooltip = {'Load p-values that can be thresholded on top of the other threshold options'};
            app.addpButton.Text = 'Load p-values';
            app.fdrButton = uibutton(app.pvaluesPanel_2, 'state');
            app.fdrButton.ValueChangedFcn = createCallbackFcn(app, @fdrCorrButtonPush, true);
            app.fdrButton.Tooltip = {'FDR correct the loaded p-values'};
            app.fdrButton.Text = {'FDR corr.'; ''};
            app.bonfButton = uibutton(app.pvaluesPanel_2, 'state');
            app.bonfButton.ValueChangedFcn = createCallbackFcn(app, @bonfCorrButtonPush, true);
            app.bonfButton.Tooltip = {'Bonferroni correct the loaded p-values'};
            app.bonfButton.Text = {'Bonf. corr.'; ''};
            app.pthresholdLabel_2 = uilabel(app.pvaluesPanel_2);
            app.pthresholdLabel_2.Text = 'p-threshold:';
            app.pThreshBox = uispinner(app.pvaluesPanel_2);
            app.pThreshBox.Step = 0.005;
            app.pThreshBox.Limits = [0 1];
            app.pThreshBox.ValueChangedFcn = createCallbackFcn(app, @pThreshBoxChange, true);
            app.pThreshBox.Tooltip = {'Anything above this p-threshold will not be patched on the brain'};
            app.pThreshBox.Value = 1;
            app.RangeofvaluestoexcludePanel_2 = uipanel(app.Panel_7);
            app.RangeofvaluestoexcludePanel_2.Title = 'Range of values to exclude';
            app.LowerlimitLabel_2 = uilabel(app.RangeofvaluestoexcludePanel_2);
            app.LowerlimitLabel_2.Text = 'Lower limit';
            app.UpperlimitLabel_2 = uilabel(app.RangeofvaluestoexcludePanel_2);
            app.UpperlimitLabel_2.Text = 'Upper limit';
            app.lowerThreshBox = uispinner(app.RangeofvaluestoexcludePanel_2);
            app.lowerThreshBox.ValueChangedFcn = createCallbackFcn(app, @negBoxChange, true);
            app.lowerThreshBox.Tooltip = {'Threshold for patched overlay. Any data that falls in between these two thresholds will not be patched.'};
            app.upperThreshBox = uispinner(app.RangeofvaluestoexcludePanel_2);
            app.upperThreshBox.ValueChangedFcn = createCallbackFcn(app, @posBoxChange, true);
            app.upperThreshBox.Tooltip = {'Threshold for patched overlay. Any data that falls in between these two thresholds will not be patched.'};
            app.ClusterthresholdPanel = uipanel(app.Panel_7);
            app.ClusterthresholdPanel.Title = 'Cluster threshold';
            app.clusterThreshBox = uispinner(app.ClusterthresholdPanel);
            app.clusterThreshBox.Limits = [0 Inf];
            app.clusterThreshBox.ValueChangedFcn = createCallbackFcn(app, @clustChange, true);
            app.clusterThreshBox.Tooltip = {'Any cluster above this size will be removed'};
            app.SizethreshLabel = uilabel(app.ClusterthresholdPanel);
            app.SizethreshLabel.Text = 'Size thresh:';
            app.clusterSlider = uislider(app.ClusterthresholdPanel);
            app.clusterSlider.Limits = [0 1000];
            app.clusterSlider.ValueChangedFcn = createCallbackFcn(app, @clustSliderChange, true);
            app.clusterSlider.Tooltip = {'Any cluster above this size will be removed'};
            app.ClustcorrButton = uibutton(app.ClusterthresholdPanel, 'push');
            app.ClustcorrButton.Tooltip = {'Determine an appropriate cluster cutoff threshold'};
            app.ClustcorrButton.Text = 'Clust. corr.';
            app.LimitsTab = uitab(app.TabGroup);
            app.LimitsTab.Title = 'Limits';
            app.TabGroup_4 = uitabgroup(app.LimitsTab);
            app.UnderlayTab_2 = uitab(app.TabGroup_4);
            app.UnderlayTab_2.Title = 'Underlay';
            app.histSurf = uiaxes(app.UnderlayTab_2);
            title(app.histSurf, '')
            xlabel(app.histSurf, {'value'; ''})
            ylabel(app.histSurf, 'density')
            app.histSurf.YTick = [];
            app.IntensityhistogramLabel = uilabel(app.UnderlayTab_2);
            app.IntensityhistogramLabel.Text = 'Intensity histogram';
            app.UpperlimitofcolormapLabel = uilabel(app.UnderlayTab_2);
            app.UpperlimitofcolormapLabel.Text = 'Upper limit of colormap:';
            app.LowerlimitofcolormapLabel = uilabel(app.UnderlayTab_2);
            app.LowerlimitofcolormapLabel.Text = 'Lower limit of colormap:';
            app.limMaxSurf = uispinner(app.UnderlayTab_2);
            app.limMaxSurf.ValueChangedFcn = createCallbackFcn(app, @limMaxSurfChange, true);
            app.limMaxSurf.Tooltip = {'This will be the top value of your underlay colorbar'};
            app.limMinSurf = uispinner(app.UnderlayTab_2);
            app.limMinSurf.ValueChangedFcn = createCallbackFcn(app, @limMinSurfChange, true);
            app.limMinSurf.Tooltip = {'This will be the bottom value of your underlay colorbar'};
            app.OverlayTab_3 = uitab(app.TabGroup_4);
            app.OverlayTab_3.Title = 'Overlay';
            app.IntensityhistogramLabel_2 = uilabel(app.OverlayTab_3);
            app.IntensityhistogramLabel_2.Text = 'Intensity histogram';
            app.UpperlimitofcolormapLabel_2 = uilabel(app.OverlayTab_3);
            app.UpperlimitofcolormapLabel_2.Text = 'Upper limit of colormap:';
            app.LowerlimitofcolormapLabel_2 = uilabel(app.OverlayTab_3);
            app.LowerlimitofcolormapLabel_2.Text = 'Lower limit of colormap:';
            app.limMinOver = uispinner(app.OverlayTab_3);
            app.limMinOver.ValueChangedFcn = createCallbackFcn(app, @limMinChange, true);
            app.limMinOver.Tooltip = {'This will be the top value of your overlay colorbar'};
            app.limMaxOver = uispinner(app.OverlayTab_3);
            app.limMaxOver.ValueChangedFcn = createCallbackFcn(app, @limMaxChange, true);
            app.limMaxOver.Tooltip = {'This will be the bottom value of your overlay colorbar'};
            app.histOver = uiaxes(app.OverlayTab_3);
            title(app.histOver, '')
            xlabel(app.histOver, {'value'; ''})
            ylabel(app.histOver, 'density')
            app.histOver.YTick = [];
            app.ColormapTab = uitab(app.TabGroup);
            app.ColormapTab.Title = 'Colormap';
            app.TabGroup_3 = uitabgroup(app.ColormapTab);
            app.UnderlayTab_3 = uitab(app.TabGroup_3);
            app.UnderlayTab_3.Title = 'Underlay';
            app.Panel = uipanel(app.UnderlayTab_3);
            app.colormapSelectorSurf = uidropdown(app.Panel);
            app.colormapSelectorSurf.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelectorSurf.ValueChangedFcn = createCallbackFcn(app, @colormapSurfChange, true);
            app.colormapSelectorSurf.Tooltip = {'Change the colormap of the currently loaded underlay'};
            app.colormapSelectorSurf.Value = 'Select colormap';
            app.binsLabelSurf = uilabel(app.Panel);
            app.binsLabelSurf.Text = 'Bins';
            app.opacityLabelSurf = uilabel(app.Panel);
            app.opacityLabelSurf.Text = 'Opacity';
            app.selectSpacingSurf = uidropdown(app.Panel);
            app.selectSpacingSurf.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacingSurf.ValueChangedFcn = createCallbackFcn(app, @colorSpaceSurfChange, true);
            app.selectSpacingSurf.Tooltip = {'Change whether the colorbar spreads evenly between your limits, or whether you want the center to map onto a specific value (for underlay)'};
            app.selectSpacingSurf.Value = 'Select color spacing';
            app.binBoxSurf = uispinner(app.Panel);
            app.binBoxSurf.Step = 25;
            app.binBoxSurf.Limits = [1 Inf];
            app.binBoxSurf.ValueChangedFcn = createCallbackFcn(app, @binBoxSurfChange, true);
            app.binBoxSurf.Tooltip = {'Number of colors in the underlay colormap'};
            app.binBoxSurf.Value = 1000;
            app.InvertcolorsLabel = uilabel(app.Panel);
            app.InvertcolorsLabel.Text = 'Invert colors';
            app.invertSwitchSurf = uiswitch(app.Panel, 'slider');
            app.invertSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @invertColorSurfChange, true);
            app.invertSwitchSurf.Tooltip = {'Invert colors in the colormap'};
            app.opacityBoxSG = uispinner(app.Panel);
            app.opacityBoxSG.Step = 0.05;
            app.opacityBoxSG.Limits = [1e-05 1];
            app.opacityBoxSG.ValueChangedFcn = createCallbackFcn(app, @changeOpacitySG, true);
            app.opacityBoxSG.Tooltip = {'Change the opacity of the sulci/gyri patched on top of the underlay. NOTE: THIS WILL TAKE SOME TIME TO COMPLETE.'};
            app.opacityBoxSG.Value = 1;
            app.OverlayTab_2 = uitab(app.TabGroup_3);
            app.OverlayTab_2.Title = 'Overlay';
            app.Panel_3 = uipanel(app.OverlayTab_2);
            app.colormapSelector = uidropdown(app.Panel_3);
            app.colormapSelector.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelector.ValueChangedFcn = createCallbackFcn(app, @colormapSelectorChange, true);
            app.colormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.colormapSelector.Value = 'Select colormap';
            app.BinsLabel_2 = uilabel(app.Panel_3);
            app.BinsLabel_2.Text = 'Bins';
            app.OpacityLabel_2 = uilabel(app.Panel_3);
            app.OpacityLabel_2.Text = 'Opacity';
            app.selectSpacing = uidropdown(app.Panel_3);
            app.selectSpacing.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacing.ValueChangedFcn = createCallbackFcn(app, @selectSpacingChange, true);
            app.selectSpacing.Tooltip = {'Change whether the colorbar spreads evenly between your limits, or whether you want the center to map onto a specific value (for overlay)'};
            app.selectSpacing.Value = 'Select color spacing';
            app.binBox = uispinner(app.Panel_3);
            app.binBox.Step = 25;
            app.binBox.Limits = [1 Inf];
            app.binBox.ValueChangedFcn = createCallbackFcn(app, @binBoxChange, true);
            app.binBox.Tooltip = {'Number of colors in the overlay colormap'};
            app.binBox.Value = 1000;
            app.opacityBox = uispinner(app.Panel_3);
            app.opacityBox.Step = 0.05;
            app.opacityBox.Limits = [1e-05 1];
            app.opacityBox.ValueChangedFcn = createCallbackFcn(app, @opacityBoxChange, true);
            app.opacityBox.Tooltip = {'Change the opacity of the overlay. This one is efficient.'};
            app.opacityBox.Value = 1;
            app.InvertcolorsLabel_2 = uilabel(app.Panel_3);
            app.InvertcolorsLabel_2.Text = 'Invert colors';
            app.invertSwitch = uiswitch(app.Panel_3, 'slider');
            app.invertSwitch.ValueChangedFcn = createCallbackFcn(app, @invertSwitchChange, true);
            app.invertSwitch.Tooltip = {'Invert colors in the colormap'};
            app.OutlineDropDownLabel = uilabel(app.Panel_3);
            app.OutlineDropDownLabel.Text = 'Outline';
            app.outlineDropDown = uidropdown(app.Panel_3);
            app.outlineDropDown.Items = {'None', 'Map', 'ROI'};
            app.outlineDropDown.ValueChangedFcn = createCallbackFcn(app, @outlineDropDownChange, true);
            app.outlineDropDown.Tooltip = {'Make an outline of the selected overlay based on the kind of data that is being outlined'};
            app.outlineDropDown.Value = 'None';
            app.GrowShrinkSlider_2Label = uilabel(app.Panel_3);
            app.GrowShrinkSlider_2Label.Text = 'Grow/Shrink';
            app.growShrinkSlider = uislider(app.Panel_3);
            app.growShrinkSlider.Limits = [-25 25];
            app.growShrinkSlider.ValueChangedFcn = createCallbackFcn(app, @growShrinkSliderChange, true);
            app.growShrinkSlider.Tooltip = {'Grow or shrink the map by a certain number of neighboring vertices'};
            app.growShrinkBox = uispinner(app.Panel_3);
            app.growShrinkBox.Limits = [-50 50];
            app.growShrinkBox.ValueChangedFcn = createCallbackFcn(app, @growShrinkBoxChange, true);
            app.growShrinkBox.Tooltip = {'Grow or shrink the map by a certain number of neighboring vertices'};
            app.BinarizeDropDownLabel = uilabel(app.Panel_3);
            app.BinarizeDropDownLabel.Text = 'Binarize';
            app.binSwitch = uidropdown(app.Panel_3);
            app.binSwitch.Items = {'Off', 'Map', 'Clusters'};
            app.binSwitch.ValueChangedFcn = createCallbackFcn(app, @binSwitchChange, true);
            app.binSwitch.Tooltip = {'Binarize the selected overlay based on the kind of data that is being binarized'};
            app.binSwitch.Value = 'Off';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.TabGroup_5 = uitabgroup(app.SmoothingTab);
            app.SmoothTab = uitab(app.TabGroup_5);
            app.SmoothTab.Title = 'Smooth';
            app.Panel_2 = uipanel(app.SmoothTab);
            app.AreaLabel_2 = uilabel(app.Panel_2);
            app.AreaLabel_2.Text = 'Area';
            app.StepsLabel_2 = uilabel(app.Panel_2);
            app.StepsLabel_2.Text = 'Steps';
            app.assignSwitchUnder = uiswitch(app.Panel_2, 'slider');
            app.assignSwitchUnder.Items = {'thresholded', 'all'};
            app.assignSwitchUnder.Tooltip = {'Do you want to iterate over all vertices in the map or just thresholded ones'};
            app.assignSwitchUnder.Value = 'thresholded';
            app.smoothAreaUnder = uispinner(app.Panel_2);
            app.smoothAreaUnder.Tooltip = {'Number of vertices over which to smooth  (i.e., how many neighboring vertices should be smoothed for each vertex in the map)'};
            app.smoothStepsUnder = uispinner(app.Panel_2);
            app.smoothStepsUnder.Tooltip = {'Number of times to repeat the smoothing process'};
            app.smoothButtonSurf = uibutton(app.Panel_2, 'state');
            app.smoothButtonSurf.ValueChangedFcn = createCallbackFcn(app, @smoothButtonSurfPress, true);
            app.smoothButtonSurf.Tooltip = {'Smooth or revert'};
            app.smoothButtonSurf.Text = 'Smooth';
            app.VerticesbeingsmoothedLabel_3 = uilabel(app.Panel_2);
            app.VerticesbeingsmoothedLabel_3.Text = 'Vertices being smoothed:';
            app.SmoothsulcigyriLabel = uilabel(app.SmoothTab);
            app.SmoothsulcigyriLabel.Text = 'Smooth sulci/gyri';
            app.Panel_5 = uipanel(app.SmoothTab);
            app.AreaLabel_3 = uilabel(app.Panel_5);
            app.AreaLabel_3.Text = 'Area';
            app.StepsLabel_3 = uilabel(app.Panel_5);
            app.StepsLabel_3.Text = 'Steps';
            app.smoothArea = uispinner(app.Panel_5);
            app.smoothArea.Tooltip = {'Number of vertices over which to smooth  (i.e., how many neighboring vertices should be smoothed for each vertex in the map)'};
            app.smoothSteps = uispinner(app.Panel_5);
            app.smoothSteps.Tooltip = {'Number of times to repeat the smoothing process'};
            app.smoothButton = uibutton(app.Panel_5, 'state');
            app.smoothButton.ValueChangedFcn = createCallbackFcn(app, @smoothButtonPress, true);
            app.smoothButton.Tooltip = {'Smooth or revert'};
            app.smoothButton.Text = 'Smooth';
            app.assignSwitch = uiswitch(app.Panel_5, 'slider');
            app.assignSwitch.Items = {'thresholded', 'all'};
            app.assignSwitch.Tooltip = {'Do you want to iterate over all vertices in the map or just thresholded ones'};
            app.assignSwitch.Value = 'thresholded';
            app.VerticesbeingsmoothedLabel_2 = uilabel(app.Panel_5);
            app.VerticesbeingsmoothedLabel_2.Text = 'Vertices being smoothed:';
            app.SmoothoverlayLabel_2 = uilabel(app.SmoothTab);
            app.SmoothoverlayLabel_2.Text = 'Smooth overlay';
            app.ClusterTab = uitab(app.TabGroup_5);
            app.ClusterTab.Title = 'Cluster';
            app.addpButton_4 = uibutton(app.ClusterTab, 'push');
            app.addpButton_4.Text = 'Launch cluster editor';
            app.addpButton_5 = uibutton(app.ClusterTab, 'push');
            app.addpButton_5.Text = 'Cluster correction';
            app.addpButton_6 = uibutton(app.ClusterTab, 'push');
            app.addpButton_6.Text = 'Convert clusters to ROIs';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_9 = uipanel(app.RightPanel);
            app.reloadButton = uibutton(app.Panel_9, 'push');
            app.reloadButton.ButtonPushedFcn = createCallbackFcn(app, @reloadOverlay, true);
            app.reloadButton.Icon = 'reloadButton.jpg';
            app.reloadButton.Tooltip = {'Reload the currently selected overlay. Right click for additional options'};
            app.reloadButton.Text = '';
            app.copyButton = uibutton(app.Panel_9, 'push');
            app.copyButton.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPush, true);
            app.copyButton.Icon = 'copy.png';
            app.copyButton.Tooltip = {'Copy currently selected overlay settings into other overlay(s)'};
            app.copyButton.Text = '';
            app.deleteButton = uibutton(app.Panel_9, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteOverlay, true);
            app.deleteButton.Icon = 'deleteButton.jpg';
            app.deleteButton.Tooltip = {'Delete the currently selected overlay(s).  Right click for additional options'};
            app.deleteButton.Text = '';
            app.OverlayselectionLabel = uilabel(app.Panel_9);
            app.OverlayselectionLabel.Text = 'Overlay selection';
            app.upButton = uibutton(app.Panel_9, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPress, true);
            app.upButton.Icon = 'upButton.jpg';
            app.upButton.Tooltip = {'Move currently selected overlay(s) up'};
            app.upButton.Text = '';
            app.downButton = uibutton(app.Panel_9, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPress, true);
            app.downButton.Icon = 'downButton.jpg';
            app.downButton.Tooltip = {'Move currently selected overlay(s) down'};
            app.downButton.Text = '';
            app.lightsButton = uibutton(app.Panel_9, 'push');
            app.lightsButton.ButtonPushedFcn = createCallbackFcn(app, @lightsButtonEffect, true);
            app.lightsButton.Icon = 'lightingButton.jpg';
            app.lightsButton.Tooltip = {'Toggle the lighting on surface'};
            app.lightsButton.Text = '';
            app.screenshotButton = uibutton(app.Panel_9, 'push');
            app.screenshotButton.ButtonPushedFcn = createCallbackFcn(app, @screenshotButtonPush, true);
            app.screenshotButton.Icon = 'screenshotButton.jpg';
            app.screenshotButton.Tooltip = {'Take screenshot of current overlay with current viewing angle. Right click for additional options'};
            app.screenshotButton.Text = {''; ''};
            app.dupeButton = uibutton(app.Panel_9, 'push');
            app.dupeButton.ButtonPushedFcn = createCallbackFcn(app, @dupeButtonPush, true);
            app.dupeButton.Icon = 'duplicateButton.jpg';
            app.dupeButton.Tooltip = {'Duplicate the currently selected overlay(s)'};
            app.dupeButton.Text = '';
            app.maskButton = uibutton(app.Panel_9, 'push');
            app.maskButton.ButtonPushedFcn = createCallbackFcn(app, @maskButtonPush, true);
            app.maskButton.Icon = 'maskButton.jpg';
            app.maskButton.Tooltip = {'Use the currently selected overlay as a  mask for some other overlay(s)'};
            app.maskButton.Text = '';
            app.LHButton = uibutton(app.Panel_9, 'push');
            app.LHButton.ButtonPushedFcn = createCallbackFcn(app, @changeDisplayLHButton, true);
            app.LHButton.Icon = 'leftHemi2.jpg';
            app.LHButton.Tooltip = {'Toggle visibility of left hemisphere. Right click for additional options'; ''};
            app.LHButton.Text = '';
            app.RHButton = uibutton(app.Panel_9, 'push');
            app.RHButton.ButtonPushedFcn = createCallbackFcn(app, @changeDisplayRHButton, true);
            app.RHButton.Icon = 'rightHemi2.jpg';
            app.RHButton.Tooltip = {'Toggle visibility of right hemisphere. Right click for additional options'};
            app.RHButton.Text = '';
            app.overlayListBox = uilistbox(app.Panel_9);
            app.overlayListBox.Items = {'No overlay'};
            app.overlayListBox.Multiselect = 'on';
            app.overlayListBox.ValueChangedFcn = createCallbackFcn(app, @overlaySelected, true);
            app.overlayListBox.Tooltip = {'Select an overlay to patch. Right click for additional options'};
            app.overlayListBox.Value = {'No overlay'};
            app.Panel_11 = uipanel(app.Panel_9);
            app.colormapSelectAtlas = uidropdown(app.Panel_11);
            app.colormapSelectAtlas.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelectAtlas.ValueChangedFcn = createCallbackFcn(app, @colormapAtlasChange, true);
            app.colormapSelectAtlas.Tooltip = {'Change the colormap of the atlas. Right click for additional options'};
            app.colormapSelectAtlas.Value = 'Select colormap';
            app.loadAtlas = uibutton(app.Panel_11, 'push');
            app.loadAtlas.ButtonPushedFcn = createCallbackFcn(app, @loadAtlasButton, true);
            app.loadAtlas.Tooltip = {'Load a new atlas '};
            app.loadAtlas.Text = 'Load atlas';
            app.LoadedatlasLabel = uilabel(app.Panel_11);
            app.LoadedatlasLabel.Text = 'Loaded atlas: ';
            app.loadLabels = uibutton(app.Panel_11, 'push');
            app.loadLabels.ButtonPushedFcn = createCallbackFcn(app, @loadLabelsButton, true);
            app.loadLabels.Tooltip = {'Load (a) new label(s)'};
            app.loadLabels.Text = 'Load labels';
            app.AreaonlasttooltipLabel = uilabel(app.Panel_11);
            app.AreaonlasttooltipLabel.Text = 'Area on last tooltip: ';
            app.selectSpacingAtlas = uidropdown(app.Panel_11);
            app.selectSpacingAtlas.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacingAtlas.ValueChangedFcn = createCallbackFcn(app, @spacingAtlasChange, true);
            app.selectSpacingAtlas.Tooltip = {'Change whether the colorbar for the atlas spreads evenly between your limits, or whether you want the center to map onto a specific value (for overlay)'};
            app.selectSpacingAtlas.Value = 'Select color spacing';
            app.BordersLabel = uilabel(app.Panel_11);
            app.BordersLabel.Text = 'Borders';
            app.borderToggleAtlas = uiswitch(app.Panel_11, 'slider');
            app.borderToggleAtlas.ValueChangedFcn = createCallbackFcn(app, @borderToggleAtlasChange, true);
            app.borderToggleAtlas.Tooltip = {'Patch only the outline of eah ROI in the atlas by turning this switch on'};
            app.upperLimAtlas = uispinner(app.Panel_11);
            app.upperLimAtlas.ValueChangedFcn = createCallbackFcn(app, @upperLimAtlasChange, true);
            app.upperLimAtlas.Tooltip = {'This will be the top value of your atlas colorbar'};
            app.lowerLimAtlas = uispinner(app.Panel_11);
            app.lowerLimAtlas.ValueChangedFcn = createCallbackFcn(app, @lowerLimAtlasChange, true);
            app.lowerLimAtlas.Tooltip = {'This will be the bottom value of your atlas colorbar'};
            app.binAtlas = uispinner(app.Panel_11);
            app.binAtlas.Limits = [1 Inf];
            app.binAtlas.ValueChangedFcn = createCallbackFcn(app, @binAtlasChange, true);
            app.binAtlas.Tooltip = {'Number of colors in the atlas colormap'};
            app.binAtlas.Value = 1;
            app.BinsLabel_3 = uilabel(app.Panel_11);
            app.BinsLabel_3.Text = 'Bins';
            app.UpperlimitLabel_3 = uilabel(app.Panel_11);
            app.UpperlimitLabel_3.Text = 'Upper limit';
            app.LowerlimitLabel_3 = uilabel(app.Panel_11);
            app.LowerlimitLabel_3.Text = 'Lower limit';
            app.opacityAtlas = uispinner(app.Panel_11);
            app.opacityAtlas.Limits = [0 1];
            app.opacityAtlas.ValueChangedFcn = createCallbackFcn(app, @opacityAtlasChange, true);
            app.opacityAtlas.Tooltip = {'Change the opacity of the atlas. This one is efficient.'};
            app.OpacityLabel_3 = uilabel(app.Panel_11);
            app.OpacityLabel_3.Text = 'Opacity';
            app.ShrinkGrowLabel = uilabel(app.Panel_11);
            app.ShrinkGrowLabel.Text = 'Shrink/Grow';
            app.shrinkGrowAtlas = uispinner(app.Panel_11);
            app.shrinkGrowAtlas.Limits = [-50 50];
            app.shrinkGrowAtlas.ValueChangedFcn = createCallbackFcn(app, @shrinkGrowAtlasChange, true);
            app.shrinkGrowAtlas.Tooltip = {'Grow or shrink the atlas map by a certain number of neighboring vertices'};
            app.toggleColorbarAtlas = uibutton(app.Panel_11, 'state');
            app.toggleColorbarAtlas.ValueChangedFcn = createCallbackFcn(app, @colorbarToggleChange, true);
            app.toggleColorbarAtlas.Tooltip = {'Toggle the visibility of the atlas colorbar'};
            app.toggleColorbarAtlas.Text = 'Colorbar';
            app.InvertcolorsLabel_4 = uilabel(app.Panel_11);
            app.InvertcolorsLabel_4.Text = 'Invert colors';
            app.invertToggleAtlas = uiswitch(app.Panel_11, 'slider');
            app.invertToggleAtlas.ValueChangedFcn = createCallbackFcn(app, @invertToggleAtlasChange, true);
            app.invertToggleAtlas.Tooltip = {'Invert colors in the atlas colormap'};
            app.reloadAtlas = uibutton(app.Panel_11, 'push');
            app.reloadAtlas.ButtonPushedFcn = createCallbackFcn(app, @reloadAtlasButton, true);
            app.reloadAtlas.Icon = 'reloadButton.jpg';
            app.reloadAtlas.Tooltip = {'Reload the atlas'};
            app.reloadAtlas.Text = '';
            app.AtlasLabel = uilabel(app.Panel_9);
            app.AtlasLabel.Text = 'Atlas';
            app.SettingsMenu = uimenu(app.brainSurferUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ColorsMenu = uimenu(app.SettingsMenu);
            app.ColorsMenu.Text = 'Colors';
            app.ColorbarMenu = uimenu(app.ColorsMenu);
            app.ColorbarMenu.Text = 'Colorbar';
            app.VisibilityMenu = uimenu(app.ColorbarMenu);
            app.VisibilityMenu.Text = 'Visibility';
            app.ShowMenu_4 = uimenu(app.VisibilityMenu);
            app.ShowMenu_4.MenuSelectedFcn = createCallbackFcn(app, @showColorbar, true);
            app.ShowMenu_4.Text = 'Show';
            app.HideMenu_4 = uimenu(app.VisibilityMenu);
            app.HideMenu_4.MenuSelectedFcn = createCallbackFcn(app, @hideColorbar, true);
            app.HideMenu_4.Text = 'Hide';
            app.OrientationMenu = uimenu(app.ColorbarMenu);
            app.OrientationMenu.Text = 'Orientation';
            app.VerticalMenu = uimenu(app.OrientationMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @makeColorbarVert, true);
            app.VerticalMenu.Text = 'Vertical';
            app.HorizontalMenu = uimenu(app.OrientationMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @makeColorbarHoriz, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.TitlesonlyformultipleselectionsMenu = uimenu(app.ColorbarMenu);
            app.TitlesonlyformultipleselectionsMenu.Text = 'Titles (only for multiple selections)';
            app.ShowMenu_5 = uimenu(app.TitlesonlyformultipleselectionsMenu);
            app.ShowMenu_5.MenuSelectedFcn = createCallbackFcn(app, @showMapTitles, true);
            app.ShowMenu_5.Text = 'Show';
            app.HideMenu_5 = uimenu(app.TitlesonlyformultipleselectionsMenu);
            app.HideMenu_5.MenuSelectedFcn = createCallbackFcn(app, @hideMapTitles, true);
            app.HideMenu_5.Text = 'Hide';
            app.ChangespacingbetweencolorbarsMenu = uimenu(app.ColorbarMenu);
            app.ChangespacingbetweencolorbarsMenu.MenuSelectedFcn = createCallbackFcn(app, @changeSpacingBetweenColorbars, true);
            app.ChangespacingbetweencolorbarsMenu.Text = 'Change spacing between colorbars...';
            app.CreatecolormapMenu = uimenu(app.ColorsMenu);
            app.CreatecolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @createColormapButton, true);
            app.CreatecolormapMenu.Text = 'Create colormap...';
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu = uimenu(app.ColorsMenu);
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu.MenuSelectedFcn = createCallbackFcn(app, @useDefaultColorsAtlas, true);
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu.Text = 'Use colors from annotation/CIFTI header for atlas';
            app.ChangedefaultsurfacecolorMenu = uimenu(app.ColorsMenu);
            app.ChangedefaultsurfacecolorMenu.MenuSelectedFcn = createCallbackFcn(app, @changeSurfC, true);
            app.ChangedefaultsurfacecolorMenu.Text = 'Change default surface color...';
            app.ChangebackgroundofbrainfigureMenu = uimenu(app.ColorsMenu);
            app.ChangebackgroundofbrainfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @changeBackBrain, true);
            app.ChangebackgroundofbrainfigureMenu.Text = 'Change background of brain figure...';
            app.GUIMenu = uimenu(app.SettingsMenu);
            app.GUIMenu.Text = 'GUI';
            app.DarkModeMenu_3 = uimenu(app.GUIMenu);
            app.DarkModeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @darkMode, true);
            app.DarkModeMenu_3.Text = 'Dark Mode';
            app.LightModeMenu_3 = uimenu(app.GUIMenu);
            app.LightModeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @lightMode, true);
            app.LightModeMenu_3.Text = 'Light Mode';
            app.ContextMenu = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu2 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu2
            app.lightsButton.ContextMenu = app.ContextMenu2;
            app.EditlightlocationsMenu = uimenu(app.ContextMenu2);
            app.EditlightlocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @editLightLocationsButton, true);
            app.EditlightlocationsMenu.Tooltip = {'Edit where each light is placed.'};
            app.EditlightlocationsMenu.Text = 'Edit light locations';
            app.ContextMenu3 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu4 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu4
            app.screenshotButton.ContextMenu = app.ContextMenu4;
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonSelectedPush, true);
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu.Text = 'Take multiple standardized screenshots of selected overlay(s)';
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonSomePush, true);
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.Tooltip = {'Opens listbox to select overlays to screenshot'};
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.Text = 'Take multiple standardized screenshots of some overlay(s)';
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonAllPush, true);
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.Tooltip = {'Automatically take screenshots of all overlays in listbox'};
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.Text = 'Take multiple standardized screenshots of all overlays';
            app.ContextMenu5 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu5
            app.deleteButton.ContextMenu = app.ContextMenu5;
            app.DeleteselectoverlayMenu = uimenu(app.ContextMenu5);
            app.DeleteselectoverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSomeOverlay, true);
            app.DeleteselectoverlayMenu.Tooltip = {'Open new listbox to select overlays to delete'};
            app.DeleteselectoverlayMenu.Text = 'Delete select overlay';
            app.DeletealloverlaysMenu = uimenu(app.ContextMenu5);
            app.DeletealloverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteAllOverlay, true);
            app.DeletealloverlaysMenu.Tooltip = {'Delete very overlay in listbox'};
            app.DeletealloverlaysMenu.Text = 'Delete all overlays';
            app.ContextMenu6 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu9 = uicontextmenu(app.brainSurferUIFigure);

            % Assign app.ContextMenu9
            app.LHButton.ContextMenu = app.ContextMenu9;
            app.RHButton.ContextMenu = app.ContextMenu9;
            app.ViewleftlateralMenu = uimenu(app.ContextMenu9);
            app.ViewleftlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralLeft, true);
            app.ViewleftlateralMenu.Text = 'View left lateral';
            app.ViewrightlateralMenu = uimenu(app.ContextMenu9);
            app.ViewrightlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralRight, true);
            app.ViewrightlateralMenu.Text = 'View right lateral';
            app.ViewinferiorMenu = uimenu(app.ContextMenu9);
            app.ViewinferiorMenu.MenuSelectedFcn = createCallbackFcn(app, @viewInferior, true);
            app.ViewinferiorMenu.Text = 'View inferior';
            app.ViewSuperiorMenu = uimenu(app.ContextMenu9);
            app.ViewSuperiorMenu.MenuSelectedFcn = createCallbackFcn(app, @viewSuperior, true);
            app.ViewSuperiorMenu.Text = 'View Superior';
            app.ContextMenu10 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu11 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu11
            app.sulcButton.ContextMenu = app.ContextMenu11;
            app.gyrButton.ContextMenu = app.ContextMenu11;
            app.MakedefaultsulcalandgyralcolorsMenu_2 = uimenu(app.ContextMenu11);
            app.MakedefaultsulcalandgyralcolorsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @defaultSGColors, true);
            app.MakedefaultsulcalandgyralcolorsMenu_2.Text = 'Make default sulcal and gyral colors';
            app.InvertsulcalandgyralcolorsMenu_2 = uimenu(app.ContextMenu11);
            app.InvertsulcalandgyralcolorsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @invertSGColors, true);
            app.InvertsulcalandgyralcolorsMenu_2.Text = 'Invert sulcal and gyral colors';
            app.ContextMenu12 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu13 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu13
            app.overlayListBox.ContextMenu = app.ContextMenu13;
            app.ChangeFontSizeMenu = uimenu(app.ContextMenu13);
            app.ChangeFontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @changeListBxSz, true);
            app.ChangeFontSizeMenu.Tooltip = {'Change listbox font size'};
            app.ChangeFontSizeMenu.Text = 'Change Font Size';
            app.TrimoverlaynamelengthsMenu = uimenu(app.ContextMenu13);
            app.TrimoverlaynamelengthsMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOverlayLngth, true);
            app.TrimoverlaynamelengthsMenu.Tooltip = {'Trim name lengths in listbox'};
            app.TrimoverlaynamelengthsMenu.Text = 'Trim overlay name lengths';
            app.ContextMenu15 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu15.ContextMenuOpeningFcn = createCallbackFcn(app, @reloadSomeOverlay, true);
            % Assign app.ContextMenu15
            app.reloadButton.ContextMenu = app.ContextMenu15;
            app.reloadAtlas.ContextMenu = app.ContextMenu15;
            app.ReloadsomeoverlaysMenu = uimenu(app.ContextMenu15);
            app.ReloadsomeoverlaysMenu.Tooltip = {'Open a new listbox to select overlays to reload'};
            app.ReloadsomeoverlaysMenu.Text = 'Reload some overlay(s)';
            app.ContextMenu16 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu16
            app.colormapSelectAtlas.ContextMenu = app.ContextMenu16;
            app.selectSpacingAtlas.ContextMenu = app.ContextMenu16;
            app.RandomizecolorsMenu = uimenu(app.ContextMenu16);
            app.RandomizecolorsMenu.MenuSelectedFcn = createCallbackFcn(app, @randomizeColorsButton, true);
            app.RandomizecolorsMenu.Tooltip = {'Randomize the colors in the atlas'};
            app.RandomizecolorsMenu.Text = 'Randomize colors';
            app.UsedefaultcolorsfromatlasifavailableMenu = uimenu(app.ContextMenu16);
            app.UsedefaultcolorsfromatlasifavailableMenu.MenuSelectedFcn = createCallbackFcn(app, @useDefaultColorsAtlas, true);
            app.UsedefaultcolorsfromatlasifavailableMenu.Tooltip = {'Some atlases will come with colors for ROIs. Use these default colors if available.'};
            app.UsedefaultcolorsfromatlasifavailableMenu.Text = 'Use default colors from atlas if available';
            % Show the figure after all components are created
            app.brainSurferUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainSurfer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.brainSurferUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.brainSurferUIFigure)
        end
    end
end